classdef CCFregister < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LoadStateButton             matlab.ui.control.Button
        SaveStateButton             matlab.ui.control.Button
        MessageBoxTextArea          matlab.ui.control.TextArea
        MessageBoxTextAreaLabel     matlab.ui.control.Label
        SavePointsButton            matlab.ui.control.Button
        DeletePointButton           matlab.ui.control.Button
        bsplineWarpButton           matlab.ui.control.Button
        AffineWarpButton            matlab.ui.control.Button
        AddPointButton              matlab.ui.control.Button
        CCFSettingsPanel            matlab.ui.container.Panel
        OverlayAnnoCheckBox         matlab.ui.control.CheckBox
        CCFtt                       matlab.ui.control.TextArea
        SelectCCFVolumeButtonGroup  matlab.ui.container.ButtonGroup
        AverageTemplateButton       matlab.ui.control.RadioButton
        AnnotationBoundaryButton    matlab.ui.control.RadioButton
        AnnotationButton            matlab.ui.control.RadioButton
        ColorCCFbyButtonGroup       matlab.ui.container.ButtonGroup
        SubstructureButton          matlab.ui.control.RadioButton
        StructureButton             matlab.ui.control.RadioButton
        DivisionButton              matlab.ui.control.RadioButton
        CategoryButton              matlab.ui.control.RadioButton
        OrganButton                 matlab.ui.control.RadioButton
        CCFPanel                    matlab.ui.container.Panel
        CropCCFPanel                matlab.ui.container.Panel
        UndoCropButton              matlab.ui.control.Button
        CROPButton                  matlab.ui.control.Button
        ZMaxLamp                    matlab.ui.control.Lamp
        ZMinLamp                    matlab.ui.control.Lamp
        XYLamp                      matlab.ui.control.Lamp
        SetZMaxButton               matlab.ui.control.Button
        SetZMinButton               matlab.ui.control.Button
        SetXYButton                 matlab.ui.control.Button
        CCFZLabel                   matlab.ui.control.Label
        IncCCFMinusButton           matlab.ui.control.Button
        IncCCFPlusButton            matlab.ui.control.Button
        CCFZSlider                  matlab.ui.control.Slider
        CCFaxes                     matlab.ui.control.UIAxes
        pointsTable                 matlab.ui.control.Table
        SamplePanel                 matlab.ui.container.Panel
        FlipSampleZCheckBox         matlab.ui.control.CheckBox
        ChannelsListBox             matlab.ui.control.ListBox
        ChannelsListBoxLabel        matlab.ui.control.Label
        SampleZLabel                matlab.ui.control.Label
        IncSampleMinusButton        matlab.ui.control.Button
        IncSamplePlusButton         matlab.ui.control.Button
        SampleZSlider               matlab.ui.control.Slider
        SampleImageParamsPanel      matlab.ui.container.Panel
        AutoSetLimitsCheckBox       matlab.ui.control.CheckBox
        MaxEditField                matlab.ui.control.NumericEditField
        MaxEditFieldLabel           matlab.ui.control.Label
        MinEditField                matlab.ui.control.NumericEditField
        MinEditFieldLabel           matlab.ui.control.Label
        SampleAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        abcDir % ABC atlas directory
        imgDir % Allen CCF directory
        colorDir % Allen CCF colors directory
        ccfColors % path to Allen CCF colors csv
        ccfTerms % path to Allen CCF parcellation terms csv
        Tcolors % table that stores Allen CCF color data
        Tterms % table that stores Allen CCF parcellation terms data
        colors % current Allen CCF colormap

        anno_orig
        anno_bound_orig
        avg_temp_orig
        anno
        anno_bound
        avg_temp

        ttOrgan
        ttCategory
        ttDivision
        ttStructure
        ttSubstructure
        CCFmarker
        
        CropXYRect
        CropZMin
        CropZMax
        
        sampleDir
        im
        im_info
        samp_im % sample image object
        CCF_im  % CCF image object

        zCCF
        zSamp

        pointsROI           % struct containing ROI objects for each movable point
        selectedPointsRow   % index of selected row in points table

        fiducialPlottingRangeSamp = 5 % maximum number of z-planes away previously marked
                                      % fiducials will be plotted in
                                      % sample image
        fiducialPlottingRangeCCF = 10 % maximum number of z-planes away previously marked
                                      % fiducials will be plotted in CCF

        sampFids % scatter object that corresponds to previously marked fiducials currently displayed in sample image
        ccfFids  % scatter object that corresponds to previously marked fiducials currently displayed  ccf image
        
        appState
        flipSampleZ = false;
        pointsData          % struct containing coordinates of each fiducial, 3 fields
        
    end
    
    methods (Access = private)
        
        function loadCCFvol(app)

            function vol = rotateVol(vol)
                vol = imrotate3(vol,-90,[0 0 1]);
                vol = imrotate3(vol,-90,[0 1 0]);
            end
            
            app.anno = niftiread(fullfile(app.imgDir,'annotation_10.nii'));
            app.anno_bound = niftiread(fullfile(app.imgDir,'annotation_boundary_10.nii'));
            app.avg_temp = niftiread(fullfile(app.imgDir,'average_template_10.nii'));

            app.anno = rotateVol(app.anno);
            app.anno_bound = rotateVol(app.anno_bound);
            app.avg_temp = rotateVol(app.avg_temp);

            app.anno_orig = app.anno;
            app.anno_bound_orig = app.anno_bound;
            app.avg_temp_orig = app.avg_temp;
            
        end

        function setCCFcolormap(app)
            
            switch app.SelectCCFVolumeButtonGroup.SelectedObject.Text

                case 'Annotation'

                    switch app.ColorCCFbyButtonGroup.SelectedObject.Text
                        case 'Organ'
                            app.colors = hex2rgb(app.Tcolors{:,'organ_color'});
                        case 'Category'
                            app.colors = hex2rgb(app.Tcolors{:,'category_color'});
                        case 'Division'
                            app.colors = hex2rgb(app.Tcolors{:,'division_color'});
                        case 'Structure'
                            app.colors = hex2rgb(app.Tcolors{:,'structure_color'});
                        case 'Substructure'
                            app.colors = hex2rgb(app.Tcolors{:,'substructure_color'});
                    end

                case 'Annotation Boundary'

                    app.colors = gray(255);

                case 'Average Template'

                    app.colors = gray(517);

            end

        end

        function initializeCCFvol(app)

            X = app.anno(:,:,app.zCCF);
            AlphaData = zeros(size(X));
            AlphaData(X ~= 0) = 1;

            C = unique(X);
            cmap = app.colors(ismember(app.Tcolors.parcellation_index,C),:);
            d = dictionary(C',1:length(C));
            X = d(X);
            RGB = ind2rgb(X,cmap);

            if app.OverlayAnnoCheckBox.Value == true
                BW = imbinarize(app.anno_bound(:,:,app.zCCF),0);
                RGB = imoverlay(RGB,BW,"black");
            end
            
            app.CCF_im = imshow(RGB,Parent=app.CCFaxes);
            set(app.CCF_im,'ButtonDownFcn',@app.clickCCFCallback)
            app.CCF_im.AlphaData = AlphaData;

            app.updateZLabel(app.CCFZLabel,app.zCCF,app.anno)

        end

        function updateCCFvol(app)

            X = app.anno(:,:,app.zCCF);
            AlphaData = zeros(size(X));
            AlphaData(X ~= 0) = 1;

            switch app.SelectCCFVolumeButtonGroup.SelectedObject.Text

                case 'Annotation'

                    C = unique(X);
                    cmap = app.colors(ismember(app.Tcolors.parcellation_index,C),:);
                    d = dictionary(C',1:length(C));
                    X = d(X);
                    RGB = ind2rgb(X,cmap);

                    if app.OverlayAnnoCheckBox.Value == true
                        BW = imbinarize(app.anno_bound(:,:,app.zCCF),0);
                        RGB = imoverlay(RGB,BW,"black");
                    end
                    
                    app.CCF_im.CData = RGB;

                case 'Annotation Boundary'

                    app.CCF_im.CData = app.anno_bound(:,:,app.zCCF);

                case 'Average Template'

                    app.CCF_im.CData = app.avg_temp(:,:,app.zCCF);

            end

            app.CCF_im.AlphaData = AlphaData;

        end

        function clickCCFCallback(app,~,hit)

            x = uint16(hit.IntersectionPoint(2));
            y = uint16(hit.IntersectionPoint(1));
            hold(app.CCFaxes,"on")
            delete(app.CCFmarker)
            app.CCFmarker = scatter(app.CCFaxes,y,x,40,"r+");
            hold(app.CCFaxes,"off")
            parc_ind = app.anno(x,y,app.zCCF);
            rows = (app.Tterms.parcellation_index == parc_ind);
            row = app.Tterms(rows,:);
            app.ttOrgan = row.organ{1};
            app.ttCategory = row.category{1};
            app.ttDivision = row.division{1};
            app.ttStructure = row.structure{1};
            app.ttSubstructure = row.substructure{1};
            app.updateCCFtt()

        end
        
        function updateCCFtt(app)

            formatSpec = "Organ: %s | Category: %s | Division: %s | Structure: %s | Substructure: %s";
            str = sprintf(formatSpec,app.ttOrgan,app.ttCategory, ...
                app.ttDivision,app.ttStructure,app.ttSubstructure);
            app.CCFtt.Value = str;

        end

        function calibrateZSlider(~, ZSlider, vol)

            numZ = uint16(size(vol,3));
            ZSlider.Limits = [double(1) double(numZ)];
            ZSlider.MajorTicks = [1 numZ];
            ZSlider.MajorTickLabels = {'1' num2str(numZ)};
            ZSlider.MinorTicks = [];
            ZSlider.Value = double(median(1:numZ));

        end
        
        % update z positions of each volume using slider values
        function fetchZ(app)
            app.zCCF = uint16(app.CCFZSlider.Value);
            app.zSamp = uint16(app.SampleZSlider.Value);
        end

        function populateChannels(app)
            sampleDirListing = dir(app.sampleDir);
            fNames = {sampleDirListing.name};
            tifsPat = 'r' + digitsPattern(1) + '_' + alphanumericsPattern + digitsPattern + '.tif';
            TF = contains(fNames,tifsPat);
            tifs = fNames(TF);
            app.ChannelsListBox.Items = tifs;
            firstTifPat = 'r1_neur';
            TF = contains(tifs,firstTifPat);
            if sum(TF) == 1
                app.ChannelsListBox.Value = tifs{TF};
            else
                app.ChannelsListBox.Value = tifs{randi(length(tifs))};
            end 
        end

        function updateSampleImage(app)

            app.samp_im.CData = app.im(:,:,app.zSamp);

        end

        function autoSetLims(app, value)

            switch value

                case true

                    app.MaxEditField.Editable = false;
                    app.MinEditField.Editable = false;

                    P = prctile(app.im(:,:,app.zSamp),[0.1, 99.9],"all");
                    app.MaxEditField.Value = double(P(2));
                    app.MinEditField.Value = double(P(1));
                    app.SampleAxes.CLim = P;
                    
                case false

                    app.MaxEditField.Editable = true;
                    app.MinEditField.Editable = true;
            end

        end

        function loadSampleImage(app, options)

            arguments
                app
                options.init
            end

            [app.im, app.im_info] = read_tiff(fullfile(app.sampleDir,app.ChannelsListBox.Value));

            if app.FlipSampleZCheckBox.Value == true
                app.im = flip(app.im,3);
            end
            
            switch options.init 

                case true

                    app.calibrateZSlider(app.SampleZSlider,app.im)
                    app.updateZLabel(app.SampleZLabel,app.zSamp,app.im)
                    app.fetchZ()
                    app.samp_im = imshow(app.im(:,:,app.zSamp), ...
                        Parent=app.SampleAxes, ...
                        InitialMagnification="fit");
            
                case false

                    app.updateSampleImage()

            end

            app.autoSetLims(app.AutoSetLimitsCheckBox.Value)
            
        end

        function updateZLabel(~, label, z, vol)

            formatSpec = "Current plane: %u / %u";
            str = sprintf(formatSpec,z,size(vol,3));
            label.Text = str;

        end

        function plotNearbyFiducials(app, vol)

            switch vol

                case 'Sample'

                    delete(app.sampFids)

                    if ~isempty(app.pointsData.samp)
                        zFid = double(app.pointsData.samp(:,3));
                        zVec = double(repelem(app.zSamp,size(app.pointsData.samp,1)).');
                        toPlot = (abs(zFid - zVec) <= app.fiducialPlottingRangeSamp);
                        ptsToPlot = app.pointsData.samp(toPlot,1:2);
                        app.sampFids = scatter(app.SampleAxes, ptsToPlot(:,1), ptsToPlot(:,2),30,'r','filled');
                        app.sampFids.HitTest = 'off';
                        app.sampFids.SelectionHighlight = 'off';
                        app.pointsROI.sampleROI.bringToFront()
                    end

                case 'CCF'

                    delete(app.ccfFids)

                    if ~isempty(app.pointsData.ccf)
                        zFid = double(app.pointsData.ccf(:,3));
                        zVec = double(repelem(app.zCCF,size(app.pointsData.ccf,1)).');
                        toPlot = (abs(zFid - zVec) <= app.fiducialPlottingRangeCCF);
                        ptsToPlot = app.pointsData.ccf(toPlot,1:2);
                        app.ccfFids = scatter(app.CCFaxes, ptsToPlot(:,1), ptsToPlot(:,2),30,'r','filled');
                        app.ccfFids.HitTest = 'off';
                        app.ccfFids.SelectionHighlight = 'off';
                        app.pointsROI.ccfROI.bringToFront()
                    end

            end
            
        end

        function incVol(app, vol, dir)

            switch vol

                case 'Sample'

                    switch dir
                        case 'plus'
                            if app.zSamp < size(app.im,3)
                                app.SampleZSlider.Value = double(app.zSamp + 1);
                                app.SampleZSliderValueChanged()
                            end
                        case 'minus'
                            if app.zSamp > 1
                                app.SampleZSlider.Value = double(app.zSamp - 1);
                                app.SampleZSliderValueChanged()
                            end
                    end

                case 'CCF'

                    switch dir
                        case 'plus'
                            if app.zCCF < size(app.anno,3)
                                app.CCFZSlider.Value = double(app.zCCF + 1);
                                app.CCFZSliderValueChanged()
                            end
                        case 'minus'
                            if app.zCCF > 1
                                app.CCFZSlider.Value = double(app.zCCF - 1);
                                app.CCFZSliderValueChanged()
                            end
                    end

            end

        end

        function doAfWarp(app)

            fixImSize = size(app.im);
            movImSize = size(app.anno);

            afIm = zeros(max(fixImSize(1),movImSize(1)), ...
                max(fixImSize(2),movImSize(2)), ...
                max(fixImSize(3),movImSize(3)));
            afIm(1:movImSize(1), 1:movImSize(2), 1:movImSize(3)) = app.anno;

            pointsMov = app.pointsData.ccf;
            pointsFix = app.pointsData.samp;

            pointsMov = [pointsMov(:, [2 1 3]) ones(size(pointsMov, 1), 1)];
            pointsFix = [pointsFix(:, [2 1 3]) ones(size(pointsFix, 1), 1)];

            mid = [size(afIm)./2 1];

            pointsMov(:,1) = pointsMov(:,1) - mid(1);
            pointsMov(:,2) = pointsMov(:,2) - mid(2);
            pointsMov(:,3) = pointsMov(:,3) - mid(3);
    
            af = pointsMov\pointsFix;
            
            af(4,1) = af(4,1) - mid(1);
            af(4,2) = af(4,2) - mid(2);
            af(4,3) = af(4,3) - mid(3);
            
            afIm = affine_transform(afIm, inv(af'),1);
            
        end

        function saveState(app)

            app.appState.flipSampleZ = app.flipSampleZ;
            app.appState.pointsData = app.pointsData;
            appStateStruct = app.appState;
            
            newStr = split(app.sampleDir,'\');
            filename = fullfile(app.sampleDir,append(newStr{end},'CCFReg.mat'));
            save(filename,'appStateStruct')

            app.MessageBoxTextArea.Value = 'Saving complete';

        end

        function loadState(app)

            newStr = split(app.sampleDir,'\');
            filename = fullfile(app.sampleDir,append(newStr{end},'CCFReg.mat'));
            load(filename, "appStateStruct")
            
            if app.flipSampleZ ~= appStateStruct.flipSampleZ
                app.im = flip(app.im,3);
                app.updateSampleImage()
                app.flipSampleZ = appStateStruct.flipSampleZ;
                app.FlipSampleZCheckBox.Value = app.flipSampleZ;
            end

            app.pointsData = appStateStruct.pointsData;
            app.pointsTable.Data = horzcat(transpose(1:size(app.pointsData.samp,1)), ...
                    app.pointsData.samp); 
            app.selectedPointsRow = [];
            app.plotNearbyFiducials('Sample')
            app.plotNearbyFiducials('CCF')

            app.MessageBoxTextArea.Value = 'Loading complete';

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % add subdirectories with needed functions
            [filepath,~,~] = fileparts(mfilename('fullpath'));
            cd(filepath)
            addpath(['./' 'utils'])

            movegui(app.UIFigure,'north')
            
            % setting up Allen CCF stuff
            app.abcDir = params('abcDir');
            app.imgDir = fullfile(app.abcDir,'image_volumes', ...
                'Allen-CCF-2020','20230630');
            app.colorDir = fullfile(app.abcDir,'metadata', ...
                'Allen-CCF-2020','20230630','views');

            app.ccfColors = fullfile(app.colorDir, ...
                'parcellation_to_parcellation_term_membership_color.csv');
            app.ccfTerms = fullfile(app.colorDir, ...
                'parcellation_to_parcellation_term_membership_acronym.csv');

            app.Tcolors = readtable(app.ccfColors);
            app.Tterms = readtable(app.ccfTerms);

            axis(app.CCFaxes,"off")
            axis(app.CCFaxes,"image")
            hold(app.CCFaxes,"on")
            axis(app.SampleAxes,"off")
            axis(app.SampleAxes,"image")
            hold(app.SampleAxes,"on")

            app.sampleDir = ('D:\2025-10-18_contra_1\post\core_output\s04');
            app.populateChannels()
            app.loadSampleImage(init=true)
            
            app.loadCCFvol()
            app.setCCFcolormap()
            app.calibrateZSlider(app.CCFZSlider,app.anno)
            app.fetchZ()
            app.initializeCCFvol()
            
            app.pointsROI.sampleROI = drawpoint(app.SampleAxes, Color='g', ...
                Position=[round(size(app.im, 2)./2), round(size(app.im, 1)./2)]);
            app.pointsROI.ccfROI = drawpoint(app.CCFaxes, Color='m', ...
                Position=[round(size(app.anno, 2)./2), round(size(app.anno, 1)./2)]);

            app.pointsData.samp = [];
            app.pointsData.ccf = [];
            app.pointsData.cur = [];
        end

        % Selection changed function: SelectCCFVolumeButtonGroup
        function SelectCCFVolumeButtonGroupSelectionChanged(app, event)
            app.setCCFcolormap()
            switch app.SelectCCFVolumeButtonGroup.SelectedObject.Text
                case 'Annotation'
                    app.CCF_im.Parent.Colormap = app.colors;
                case 'Annotation Boundary'
                    app.CCF_im.Parent.Colormap = app.colors;
                case 'Average Template'
                    app.CCF_im.Parent.Colormap = app.colors;
                    app.CCF_im.Parent.CLim = [0,516];
            end
            app.updateCCFvol()
        end

        % Selection changed function: ColorCCFbyButtonGroup
        function ColorCCFbyButtonGroupSelectionChanged(app, event)
            app.setCCFcolormap()
            app.updateCCFvol()
        end

        % Value changed function: OverlayAnnoCheckBox
        function OverlayAnnoCheckBoxValueChanged(app, event)
            app.updateCCFvol()
        end

        % Value changed function: SampleZSlider
        function SampleZSliderValueChanged(app, event)
            app.zSamp = uint16(app.SampleZSlider.Value);
            app.updateSampleImage()
            app.autoSetLims(app.AutoSetLimitsCheckBox.Value)
            app.plotNearbyFiducials('Sample')
            app.updateZLabel(app.SampleZLabel,app.zSamp,app.im)
        end

        % Value changed function: CCFZSlider
        function CCFZSliderValueChanged(app, event)
            app.zCCF = uint16(app.CCFZSlider.Value);
            app.updateCCFvol()
            app.plotNearbyFiducials('CCF')
            app.updateZLabel(app.CCFZLabel,app.zCCF,app.anno)
        end

        % Value changed function: AutoSetLimitsCheckBox
        function AutoSetLimitsCheckBoxValueChanged(app, event)
            value = app.AutoSetLimitsCheckBox.Value;
            app.autoSetLims(value)
        end

        % Button pushed function: AddPointButton
        function AddPointButtonPushed(app, event)
            pSample = app.pointsROI.sampleROI.Position;
            pCCF = app.pointsROI.ccfROI.Position;

            app.pointsData.samp(end+1, :) = [pSample app.zSamp];
            app.pointsData.ccf(end+1, :) = [pCCF app.zCCF];
            app.pointsData.cur(end+1, :) = [pCCF app.zCCF];

            app.pointsTable.Data = horzcat(transpose(1:size(app.pointsData.samp,1)), ...
                app.pointsData.samp); 

            app.plotNearbyFiducials('Sample')
            app.plotNearbyFiducials('CCF')
        end

        % Button pushed function: DeletePointButton
        function DeletePointButtonPushed(app, event)
            if ~isempty(app.selectedPointsRow)

                indices = setxor(1:size(app.pointsData.samp,1), app.selectedPointsRow);

                app.pointsData.samp = app.pointsData.samp(indices,:);
                app.pointsData.ccf = app.pointsData.ccf(indices,:);
                app.pointsData.cur = app.pointsData.cur(indices,:);

                app.pointsTable.Data = horzcat(transpose(1:size(app.pointsData.samp,1)), ...
                    app.pointsData.samp); 

                app.selectedPointsRow = [];

            end
        end

        % Cell selection callback: pointsTable
        function pointsTableCellSelection(app, event)
            
            indices = event.Indices;
            if isempty(indices)
                app.selectedPointsRow = app.selectedPointsRow;
            else
                app.selectedPointsRow = unique(indices(:,1));
            end

            app.SampleZSlider.Value = app.pointsData.samp(app.selectedPointsRow,3);
            app.CCFZSlider.Value = app.pointsData.ccf(app.selectedPointsRow,3);
            app.SampleZSliderValueChanged()
            app.CCFZSliderValueChanged()

            app.pointsROI.sampleROI.Position = [app.pointsData.samp(app.selectedPointsRow,1), ...
                app.pointsData.samp(app.selectedPointsRow,2)];
            app.pointsROI.ccfROI.Position = [app.pointsData.ccf(app.selectedPointsRow,1), ...
                app.pointsData.ccf(app.selectedPointsRow,2)];

            
        end

        % Button pushed function: IncSamplePlusButton
        function IncSamplePlusButtonPushed(app, event)
            app.incVol('Sample','plus')
        end

        % Button pushed function: IncSampleMinusButton
        function IncSampleMinusButtonPushed(app, event)
            app.incVol('Sample','minus')
        end

        % Button pushed function: IncCCFPlusButton
        function IncCCFPlusButtonPushed(app, event)
            app.incVol('CCF','plus')
        end

        % Button pushed function: IncCCFMinusButton
        function IncCCFMinusButtonPushed(app, event)
            app.incVol('CCF','minus')
        end

        % Button pushed function: AffineWarpButton
        function AffineWarpButtonPushed(app, event)
            
            numFid = size(app.pointsData.samp,1);
            if numFid == 0
                str = 'Cannot perform affine warp without fiducials';
            elseif (numFid > 0 && numFid < 3)
                str = 'At least 3 fiducials needed to perform an affine warp';
            elseif numFid >= 3
                str = 'Performing affine warp...';
            end
            app.MessageBoxTextArea.Value = str;

            app.doAfWarp()
        end

        % Value changed function: ChannelsListBox
        function ChannelsListBoxValueChanged(app, event)
            app.UIFigure.Pointer = 'watch';
            drawnow()
            app.loadSampleImage(init=false)
            app.UIFigure.Pointer = 'arrow';
        end

        % Value changed function: FlipSampleZCheckBox
        function FlipSampleZCheckBoxValueChanged(app, event)
            app.im = flip(app.im,3);
            app.updateSampleImage()
            app.flipSampleZ = app.FlipSampleZCheckBox.Value;
        end

        % Button pushed function: SaveStateButton
        function SaveStateButtonPushed(app, event)
            app.saveState()
        end

        % Button pushed function: LoadStateButton
        function LoadStateButtonPushed(app, event)
            app.loadState()
        end

        % Button pushed function: SetXYButton
        function SetXYButtonPushed(app, event)
            app.XYLamp.Color = 'y';
            str = ['Move or resize the crop rectangle using the mouse. ' ...
                'When you are finished sizing and positioning the crop rectangle, ' ...
                'create the cropped image by either double-clicking the left mouse button or ' ...
                'choosing Crop Image from the right-click context menu.'];
            app.MessageBoxTextArea.Value = str;
            [~,app.CropXYRect] = imcrop(app.CCFaxes);
            app.CropXYRect = round(app.CropXYRect);
            app.XYLamp.Color = 'g';
        end

        % Button pushed function: SetZMinButton
        function SetZMinButtonPushed(app, event)
            app.ZMinLamp.Color = 'g';
            app.CropZMin = app.zCCF;
        end

        % Button pushed function: SetZMaxButton
        function SetZMaxButtonPushed(app, event)
            app.ZMaxLamp.Color = 'g';
            app.CropZMax = app.zCCF;
        end

        % Button pushed function: CROPButton
        function CROPButtonPushed(app, event)
            if all([~isempty(app.CropXYRect),~isempty(app.CropZMin),~isempty(app.CropZMax)])
                
                cuboid = [app.CropXYRect(1), app.CropXYRect(2), app.CropZMin, ...
                    app.CropXYRect(3), app.CropXYRect(4), app.CropZMax - app.CropZMin];
                app.anno = imcrop3(app.anno_orig,cuboid);
                app.anno_bound = imcrop3(app.anno_bound_orig,cuboid);
                app.avg_temp = imcrop3(app.avg_temp_orig,cuboid);
                
                hold(app.CCFaxes,"off")
                app.calibrateZSlider(app.CCFZSlider,app.anno)
                app.fetchZ()
                app.initializeCCFvol()
                hold(app.CCFaxes,"off")

                app.pointsROI.ccfROI = drawpoint(app.CCFaxes, Color='m', ...
                    Position=[round(size(app.anno, 2)./2), round(size(app.anno, 1)./2)]);

                app.XYLamp.Color = 'w';
                app.ZMinLamp.Color = 'w';
                app.ZMaxLamp.Color = 'w';
            else
                str = ['Must set XY crop rectangle, Z crop minimum, and Z crop maximum ' ...
                    'before cropping'];
                app.MessageBoxTextArea.Value = str;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SamplePanel = uipanel(app.UIFigure);
            app.SamplePanel.Title = 'Sample';
            app.SampleAxes = uiaxes(app.SamplePanel);
            app.SampleImageParamsPanel = uipanel(app.SamplePanel);
            app.SampleImageParamsPanel.Title = 'Sample Image Display Parameters';
            app.MinEditFieldLabel = uilabel(app.SampleImageParamsPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.SampleImageParamsPanel, 'numeric');
            app.MaxEditFieldLabel = uilabel(app.SampleImageParamsPanel);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.SampleImageParamsPanel, 'numeric');
            app.AutoSetLimitsCheckBox = uicheckbox(app.SampleImageParamsPanel);
            app.AutoSetLimitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoSetLimitsCheckBoxValueChanged, true);
            app.AutoSetLimitsCheckBox.Text = 'Auto set limits';
            app.AutoSetLimitsCheckBox.Value = true;
            app.SampleZSlider = uislider(app.SamplePanel);
            app.SampleZSlider.ValueChangedFcn = createCallbackFcn(app, @SampleZSliderValueChanged, true);
            app.IncSamplePlusButton = uibutton(app.SamplePanel, 'push');
            app.IncSamplePlusButton.ButtonPushedFcn = createCallbackFcn(app, @IncSamplePlusButtonPushed, true);
            app.IncSamplePlusButton.Icon = fullfile(pathToMLAPP, 'assets', 'right_arrow.png');
            app.IncSamplePlusButton.Text = '';
            app.IncSampleMinusButton = uibutton(app.SamplePanel, 'push');
            app.IncSampleMinusButton.ButtonPushedFcn = createCallbackFcn(app, @IncSampleMinusButtonPushed, true);
            app.IncSampleMinusButton.Icon = fullfile(pathToMLAPP, 'assets', 'left_arrow.png');
            app.IncSampleMinusButton.Text = '';
            app.SampleZLabel = uilabel(app.SamplePanel);
            app.SampleZLabel.Text = 'SampleZLabel';
            app.ChannelsListBoxLabel = uilabel(app.SamplePanel);
            app.ChannelsListBoxLabel.Text = 'Channels';
            app.ChannelsListBox = uilistbox(app.SamplePanel);
            app.ChannelsListBox.Items = {};
            app.ChannelsListBox.ValueChangedFcn = createCallbackFcn(app, @ChannelsListBoxValueChanged, true);
            app.ChannelsListBox.Value = {};
            app.FlipSampleZCheckBox = uicheckbox(app.SamplePanel);
            app.FlipSampleZCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlipSampleZCheckBoxValueChanged, true);
            app.FlipSampleZCheckBox.Text = {'Flip Sample Z'; '(L → R should be A → P)'};
            app.pointsTable = uitable(app.UIFigure);
            app.pointsTable.ColumnName = {''; 'x fixed'; 'y fixed'; 'z fixed'};
            app.pointsTable.RowName = {};
            app.pointsTable.SelectionType = 'row';
            app.pointsTable.CellSelectionCallback = createCallbackFcn(app, @pointsTableCellSelection, true);
            app.pointsTable.Multiselect = 'off';
            app.CCFPanel = uipanel(app.UIFigure);
            app.CCFPanel.Title = 'Allen CCF';
            app.CCFaxes = uiaxes(app.CCFPanel);
            app.CCFZSlider = uislider(app.CCFPanel);
            app.CCFZSlider.ValueChangedFcn = createCallbackFcn(app, @CCFZSliderValueChanged, true);
            app.IncCCFPlusButton = uibutton(app.CCFPanel, 'push');
            app.IncCCFPlusButton.ButtonPushedFcn = createCallbackFcn(app, @IncCCFPlusButtonPushed, true);
            app.IncCCFPlusButton.Icon = fullfile(pathToMLAPP, 'assets', 'right_arrow.png');
            app.IncCCFPlusButton.Text = '';
            app.IncCCFMinusButton = uibutton(app.CCFPanel, 'push');
            app.IncCCFMinusButton.ButtonPushedFcn = createCallbackFcn(app, @IncCCFMinusButtonPushed, true);
            app.IncCCFMinusButton.Icon = fullfile(pathToMLAPP, 'assets', 'left_arrow.png');
            app.IncCCFMinusButton.Text = '';
            app.CCFZLabel = uilabel(app.CCFPanel);
            app.CCFZLabel.Text = 'CCFZLabel';
            app.CropCCFPanel = uipanel(app.CCFPanel);
            app.CropCCFPanel.Title = 'Crop CCF';
            app.SetXYButton = uibutton(app.CropCCFPanel, 'push');
            app.SetXYButton.ButtonPushedFcn = createCallbackFcn(app, @SetXYButtonPushed, true);
            app.SetXYButton.Text = 'Set XY';
            app.SetZMinButton = uibutton(app.CropCCFPanel, 'push');
            app.SetZMinButton.ButtonPushedFcn = createCallbackFcn(app, @SetZMinButtonPushed, true);
            app.SetZMinButton.Text = 'Set Z Min';
            app.SetZMaxButton = uibutton(app.CropCCFPanel, 'push');
            app.SetZMaxButton.ButtonPushedFcn = createCallbackFcn(app, @SetZMaxButtonPushed, true);
            app.SetZMaxButton.Text = 'Set Z Max';
            app.XYLamp = uilamp(app.CropCCFPanel);
            app.ZMinLamp = uilamp(app.CropCCFPanel);
            app.ZMaxLamp = uilamp(app.CropCCFPanel);
            app.CROPButton = uibutton(app.CropCCFPanel, 'push');
            app.CROPButton.ButtonPushedFcn = createCallbackFcn(app, @CROPButtonPushed, true);
            app.CROPButton.Text = 'CROP';
            app.UndoCropButton = uibutton(app.CropCCFPanel, 'push');
            app.UndoCropButton.Text = 'Undo Crop';
            app.ColorCCFbyButtonGroup = uibuttongroup(app.UIFigure);
            app.ColorCCFbyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorCCFbyButtonGroupSelectionChanged, true);
            app.ColorCCFbyButtonGroup.Title = 'Color CCF by:';
            app.OrganButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.OrganButton.Text = 'Organ';
            app.CategoryButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.CategoryButton.Text = 'Category';
            app.DivisionButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.DivisionButton.Text = 'Division';
            app.StructureButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.StructureButton.Text = 'Structure';
            app.StructureButton.Value = true;
            app.SubstructureButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.SubstructureButton.Text = 'Substructure';
            app.SelectCCFVolumeButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectCCFVolumeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectCCFVolumeButtonGroupSelectionChanged, true);
            app.SelectCCFVolumeButtonGroup.Title = 'Select CCF Volume:';
            app.AnnotationButton = uiradiobutton(app.SelectCCFVolumeButtonGroup);
            app.AnnotationButton.Text = 'Annotation';
            app.AnnotationButton.Value = true;
            app.AnnotationBoundaryButton = uiradiobutton(app.SelectCCFVolumeButtonGroup);
            app.AnnotationBoundaryButton.Text = 'Annotation Boundary';
            app.AverageTemplateButton = uiradiobutton(app.SelectCCFVolumeButtonGroup);
            app.AverageTemplateButton.Text = 'Average Template';
            app.CCFtt = uitextarea(app.UIFigure);
            app.CCFtt.Value = {'Click anywhere in the CCF to view metadata'};
            app.CCFSettingsPanel = uipanel(app.UIFigure);
            app.CCFSettingsPanel.Title = 'CCF Settings';
            app.OverlayAnnoCheckBox = uicheckbox(app.CCFSettingsPanel);
            app.OverlayAnnoCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlayAnnoCheckBoxValueChanged, true);
            app.OverlayAnnoCheckBox.Text = {'Overlay annotation boundaries'; 'on annotation volume?'};
            app.OverlayAnnoCheckBox.Value = true;
            app.AddPointButton = uibutton(app.UIFigure, 'push');
            app.AddPointButton.ButtonPushedFcn = createCallbackFcn(app, @AddPointButtonPushed, true);
            app.AddPointButton.Text = 'Add point';
            app.AffineWarpButton = uibutton(app.UIFigure, 'push');
            app.AffineWarpButton.ButtonPushedFcn = createCallbackFcn(app, @AffineWarpButtonPushed, true);
            app.AffineWarpButton.Text = 'Affine Warp';
            app.bsplineWarpButton = uibutton(app.UIFigure, 'push');
            app.bsplineWarpButton.Text = 'b-spline Warp';
            app.DeletePointButton = uibutton(app.UIFigure, 'push');
            app.DeletePointButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePointButtonPushed, true);
            app.DeletePointButton.Text = 'Delete point';
            app.SavePointsButton = uibutton(app.UIFigure, 'push');
            app.SavePointsButton.Text = 'Save points';
            app.MessageBoxTextAreaLabel = uilabel(app.UIFigure);
            app.MessageBoxTextAreaLabel.Text = 'Message Box';
            app.MessageBoxTextArea = uitextarea(app.UIFigure);
            app.MessageBoxTextArea.Editable = 'off';
            app.SaveStateButton = uibutton(app.UIFigure, 'push');
            app.SaveStateButton.ButtonPushedFcn = createCallbackFcn(app, @SaveStateButtonPushed, true);
            app.SaveStateButton.Text = 'Save State';
            app.LoadStateButton = uibutton(app.UIFigure, 'push');
            app.LoadStateButton.ButtonPushedFcn = createCallbackFcn(app, @LoadStateButtonPushed, true);
            app.LoadStateButton.Text = 'Load State';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CCFregister

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end