classdef HCRprocess < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FunctionsPanel                 matlab.ui.container.Panel
        memMonitorButton               matlab.ui.control.Button
        RedoCheckBox                   matlab.ui.control.CheckBox
        DoAllButton                    matlab.ui.control.Button
        TableFileSwitch                matlab.ui.control.Switch
        RegRoundDropDown               matlab.ui.control.DropDown
        RegistrationRoundLabel         matlab.ui.control.Label
        SettingsPanel                  matlab.ui.container.Panel
        DeleteIntermediatesCheckBox    matlab.ui.control.CheckBox
        PostprocessingPanel            matlab.ui.container.Panel
        CCFRegistrationLabel           matlab.ui.control.Label
        CCFLamp                        matlab.ui.control.Lamp
        CCFSwitch                      matlab.ui.control.ToggleSwitch
        SegSwitch                      matlab.ui.control.ToggleSwitch
        SegLamp                        matlab.ui.control.Lamp
        SegmentationLabel              matlab.ui.control.Label
        LipoSwitch                     matlab.ui.control.ToggleSwitch
        LipoGoneLabel                  matlab.ui.control.Label
        LipoLamp                       matlab.ui.control.Lamp
        CoreProcessingPipelinePanel    matlab.ui.container.Panel
        FinishSwitch                   matlab.ui.control.ToggleSwitch
        FinishingLabel                 matlab.ui.control.Label
        FinishLamp                     matlab.ui.control.Lamp
        Image_4                        matlab.ui.control.Image
        UnmixSwitch                    matlab.ui.control.ToggleSwitch
        RegSwitch                      matlab.ui.control.ToggleSwitch
        Image_2                        matlab.ui.control.Image
        UnmixingLabel                  matlab.ui.control.Label
        StitchSwitch                   matlab.ui.control.ToggleSwitch
        RegistrationLabel              matlab.ui.control.Label
        StitchingLabel                 matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        UnmixLamp                      matlab.ui.control.Lamp
        RegLamp                        matlab.ui.control.Lamp
        StitchLamp                     matlab.ui.control.Lamp
        DirTree                        matlab.ui.container.Tree
        ExperimentDirectoryNode        matlab.ui.container.TreeNode
        ExpDirButton                   matlab.ui.control.Button
        TabGroup                       matlab.ui.container.TabGroup
        MainTab                        matlab.ui.container.Tab
        CoreprocessingpipelineroundspecificLabel  matlab.ui.control.Label
        ManualsettingsroundspecificLabel  matlab.ui.control.Label
        PostprocessingroundindependentLabel  matlab.ui.control.Label
        PostTable                      matlab.ui.control.Table
        SettingsTable                  matlab.ui.control.Table
        FilesTable                     matlab.ui.control.Table
        StitchingTab                   matlab.ui.container.Tab
        StitchingFunctionsPanel        matlab.ui.container.Panel
        StitchAllButton                matlab.ui.control.Button
        StitchButton                   matlab.ui.control.Button
        StitchTable                    matlab.ui.control.Table
        SpectralUnmixingTab            matlab.ui.container.Tab
        UnmixFunctionsPanel            matlab.ui.container.Panel
        UnmixAllButton                 matlab.ui.control.Button
        UnmixButton                    matlab.ui.control.Button
        UnmixParametersFunctionsPanel  matlab.ui.container.Panel
        UnmixParamButton               matlab.ui.control.Button
        UnmixTable                     matlab.ui.control.Table
        UnmixParamTable                matlab.ui.control.Table
        RegTab                         matlab.ui.container.Tab
        RegistrationFunctionsPanel     matlab.ui.container.Panel
        RegAllButton                   matlab.ui.control.Button
        RegButton                      matlab.ui.control.Button
        FiducialsFunctionsPanel        matlab.ui.container.Panel
        FiducialsButton                matlab.ui.control.Button
        RegTable                       matlab.ui.control.Table
        FiducialsTable                 matlab.ui.control.Table
        FinalTab                       matlab.ui.container.Tab
        FinishingFunctionsPanel        matlab.ui.container.Panel
        SubtractbackgroundCheckBox     matlab.ui.control.CheckBox
        ProcessAllButton               matlab.ui.control.Button
        ProcessButton                  matlab.ui.control.Button
        RadiusEditField                matlab.ui.control.EditField
        RollingballradiusEditFieldLabel  matlab.ui.control.Label
        FinishTable                    matlab.ui.control.Table
        Histology                      matlab.ui.container.Tab
        HistologyFunctionsPanel        matlab.ui.container.Panel
        HistoProcessAllButton          matlab.ui.control.Button
        HistoProcessButton             matlab.ui.control.Button
        HistoTable                     matlab.ui.control.Table
        SegmentationTab                matlab.ui.container.Tab
        SegButton                      matlab.ui.control.Button
        SegAllButton                   matlab.ui.control.Button
        SegTable                       matlab.ui.control.Table
        LipofuscinRemovalTab           matlab.ui.container.Tab
    end

    
    properties (Access = public)

        exp_dir                            % experiment directory
        HCR_kw = "HCR";                    % keyword that marks HCR image file
        histo_kw = "histo";                % keyword that marks histo file
        reg_round_token = 'r1'             % token that marks the registration round
        reg_channel_token = ["NT", ...
            "Snap25", "Snap", "neur", ...
            "nt"]                          % token(s) that mark registration channel
        do_all_flag = false;               % is app currently running in 'do all' mode
        do_all_cache = {}                  % cell array of warnings stored by 'do all' mode

        stitch_sel                         % selected row in stitch table
        fiducials_sel                      % selected row in fiducials table
        reg_sel                            % selected row in registration table
        unmix_param_sel                    % selected row in unmix parameters table
        unmix_sel                          % selected row in unmixing table
        process_sel                        % selected row in final processing table
        histo_sel                          % selected row in histology table
        seg_sel                            % selected row in segmentation table

        reg_round                          % registration round

        round_folder                       % currently selected round folder
        T1                                 % table 1 (files table)
        T2                                 % table 2 (settings table)
        T3                                 % table 3 (postprocessing table)
        T4                                 % pipeline table for core operations
        T5                                 % histology table
        T6                                 % pipeline table for post operations
        meta                               % metadata dictionary

        T1_literal                         % files table, but for actual files that can be found
        T2_literal                         % settings table, but for actual settings files that can be found 
        T3_literal                         % postprocessing table, but for actual files that can be found
        T5_literal                         % histology table, but for actual files that can be found

        tables                             % cell array of all tables throughout the app 

        pipeline                           % cell array of strings describing processing steps to do

        num_uniq                           % number of unique images (slices typically)
        num_process_steps                  % total number of possible processing steps
        num_manual_steps                   % total number of possible manual steps
        num_post_steps                     % total number of possible post-processing steps

        uniq_ids_HCR = {}                  % cell array of strings containing all uniq_ids for HCR in the experiment
        uniq_ids_histo = {}                % cell array of strings containing all uniq_ids for histo in the experiment
        round_ids                          % cell array of strings containing all round_ids for HCR in the experiment

        histo_dir = ''                     % histology directory

    end
    
    methods (Access = private)
        
        % populate internal folders, only needs to run once on startup
        function pop_int_folders(app, listing)

            % whether this experiment has been opened before
            first_time_flag = ~isfolder(fullfile(app.exp_dir,listing{1},'internal'));

            if first_time_flag == true

                app.uniq_ids_HCR = app.get_uniq_ids(listing{1}, app.HCR_kw);

                txt_prompt = "It looks like this is your first time opening " + ...
                    "this experiment directory. Please verify that the " + ...
                    "following details are correct. If so, press 'Continue', " + ...
                    "otherwise press 'Cancel' and reorganize the input " + ...
                    "experiment directory appropriately.";

                txt_prompt = txt_prompt + newline + newline + sprintf('%u HCR subdirectories were found:',length(listing));

                for i=1:length(listing)
                    txt_prompt = txt_prompt + newline + listing{i};
                end

                txt_prompt = txt_prompt + newline + newline + sprintf('%u HCR files were found:',length(app.uniq_ids_HCR));
                for i=1:length(app.uniq_ids_HCR)
                    txt_prompt = txt_prompt + newline + app.uniq_ids_HCR{i};
                end

                if ~isempty(app.uniq_ids_histo)
                    txt_prompt = txt_prompt + newline +newline + sprintf("A histology subdirectory was found, it contains %u files:",length(app.uniq_ids_histo));
                    for i=1:length(app.uniq_ids_histo)
                        txt_prompt = txt_prompt + newline + app.uniq_ids_histo{i};
                    end
                else
                    txt_prompt = txt_prompt + newline + newline + "No histology subdirectory was found";
                end
                
                fig = app.UIFigure;
                choice = uiconfirm(fig,txt_prompt, ...
                    'First time opening experiment','Icon','question', ...
                    "Options",["Continue","Cancel"], ...
                    "DefaultOption",1, ...
                    "CancelOption",2);

                switch choice
                    case 'Continue'
                        % do nothing
                    case 'Cancel'
                        app.delete
                end
                
            end


            % iterate through rounds
            for i=1:length(listing)
                roundf = listing{i};
                check_internal = fullfile(app.exp_dir,roundf,'internal');
                if exist(check_internal,'dir') == false
                    app.move_to_raw(roundf)
                end
                check_build_dir(check_internal)
                check_stitch = fullfile(app.exp_dir,roundf,'stitch');
                check_build_dir(check_stitch)
                check_reg = fullfile(app.exp_dir,roundf,'reg');
                check_build_dir(check_reg)
                check_unmix = fullfile(app.exp_dir,roundf,'unmix');
                check_build_dir(check_unmix)
                check_fiducials = fullfile(app.exp_dir,roundf,'internal','fiducials');
                check_build_dir(check_fiducials)
                check_unmix_param = fullfile(app.exp_dir,roundf,'internal','uparams');
                check_build_dir(check_unmix_param)
            end
            
            check_post = fullfile(app.exp_dir,'post');
            check_build_dir(check_post)
            check_internal = fullfile(app.exp_dir,'internal');
            check_build_dir(check_internal)
            check_core_output = fullfile(app.exp_dir,'post','core_output');
            check_build_dir(check_core_output)
            check_LipoGone = fullfile(app.exp_dir,'post','LipoGone');
            check_build_dir(check_LipoGone)
            check_masks = fullfile(app.exp_dir,'post','masks');
            check_build_dir(check_masks)
            check_CCF_coords = fullfile(app.exp_dir,'post','CCF_coords');
            check_build_dir(check_CCF_coords)

            if first_time_flag == true

                app.meta = struct;
                app.meta.uniq_ids_HCR = app.uniq_ids_HCR;
                app.save_meta();

            else
                
                load(fullfile(app.exp_dir,'internal','metadata.mat'))
                app.meta = meta;
                app.uniq_ids_HCR = app.meta.uniq_ids_HCR;

            end
            
            for i=1:length(app.uniq_ids_HCR)
                check_build_dir(fullfile(check_core_output,app.uniq_ids_HCR{i}))
                check_build_dir(fullfile(check_LipoGone,app.uniq_ids_HCR{i}))
                check_build_dir(fullfile(check_masks,app.uniq_ids_HCR{i}))
                check_build_dir(fullfile(check_CCF_coords,app.uniq_ids_HCR{i}))
            end
        
            if ~isempty(app.histo_dir)
                check_internal = fullfile(app.exp_dir,app.histo_dir,'internal');
                % first time opening this folder
                if exist(check_internal,'dir') == false
                    app.move_to_raw(app.histo_dir)
                    check_build_dir(check_internal)
                    nfiles = length(app.uniq_ids_histo);
                    app.T5 = table(zeros(nfiles,1), ...
                        'VariableNames',{'Processed'}, ...
                        'RowNames',app.uniq_ids_histo);
                    check_processed = fullfile(app.exp_dir,app.histo_dir,'processed');
                    check_build_dir(check_processed)
                else
                    app.T5 = read_table_with_row_names(fullfile(app.exp_dir,app.histo_dir,'internal','T5.txt'));
                    check_processed = fullfile(app.exp_dir,app.histo_dir,'processed');
                    check_build_dir(check_processed)
                end
            end

            % try to find and set reg_round if first time experiment
            % directory is being opened, otherwise load the previous
            % reg_round
            if first_time_flag == true

                for i=1:length(app.ExperimentDirectoryNode.Children)
                    if contains(app.ExperimentDirectoryNode.Children(i).Text, app.reg_round_token)
                        app.reg_round = app.ExperimentDirectoryNode.Children(i).Text;
                    end
                end

                if ~isempty(app.reg_round)
                    app.meta.reg_round = app.reg_round;
                else
                    msgbox("No round containing the registration token found. Registration round will have to be set manually later", "Registration warning", "warn")
                    app.meta.reg_round = "";
                end
                app.save_meta();

            else

                load(fullfile(app.exp_dir,'internal','metadata.mat'))
                app.meta = meta;
                app.reg_round = app.meta.reg_round;

            end

        end

        % populate the reg round dropdown
        function init_reg_round(app)

            for i=1:length(app.ExperimentDirectoryNode.Children)
                app.RegRoundDropDown.Items{end+1} = app.ExperimentDirectoryNode.Children(i).Text;
            end

            if ~isempty(app.reg_round)
                app.RegRoundDropDown.Value = app.reg_round;
            else
                app.RegRoundDropDown.Items{end+1} = '';
                app.RegRoundDropDown.Value = '';
            end

        end

        function save_meta(app)
            meta_fn = fullfile(app.exp_dir,'internal','metadata.mat');
            meta = app.meta;
            save(meta_fn,"meta")
        end
        
        % move all the raw files to a raw subdirectory, runs when a round
        % folder is opened for the first time
        function move_to_raw(app, roundf)
            
            mkdir(fullfile(app.exp_dir,roundf,'raw'))

            listing = fetch_dir(fullfile(app.exp_dir,roundf),'file');
            
            if ~isempty(listing)
                for i=1:length(listing)
                    movefile(fullfile(app.exp_dir,roundf,listing{i}), fullfile(app.exp_dir,roundf,'raw'))
                end
            end

            listing = fetch_dir(fullfile(app.exp_dir,roundf),'dir');
            listing(ismember(listing,'raw')) = [];
            
            if ~isempty(listing)
                for i=1:length(listing)
                    movefile(fullfile(app.exp_dir,roundf,listing{i}), fullfile(app.exp_dir,roundf,'raw'))
                end
            end

        end
        
        % run on startup
        function uniq_ids = get_uniq_ids(app, sub_dir, kw)

            % extract cell array of uniq_ids
            listing = fetch_dir(fullfile(app.exp_dir,sub_dir,'raw'), 'file');

            if isempty(listing)
                listing = fetch_dir(fullfile(app.exp_dir,sub_dir), 'file');
            end

            listing = filt_kw(listing, kw, 'keep');

            % iterate through uniq_ids
            for i=1:length(listing)
                split_str = split(listing{i},'__');
                listing{i} = split_str{1};
            end

            uniq_ids = listing;

        end
        
        % run on startup
        function get_round_ids(app)
            
            num_rounds = length(app.ExperimentDirectoryNode.Children);
            app.round_ids = cell(1,num_rounds);

            for i=1:length(app.ExperimentDirectoryNode.Children)
                split_str = split(app.ExperimentDirectoryNode.Children(i).Text,'_');
                app.round_ids{i} = split_str{1};
            end

        end
        
        function update_round(app)

            listing = fetch_dir(fullfile(app.exp_dir,app.round_folder, ...
                'internal'), 'file');

            % inherit pre-existing status tables if they exist
            if any(strcmp(listing,'T1.txt'))
                app.T1 = read_table_with_row_names(fullfile(app.exp_dir,app.round_folder,'internal','T1.txt'));
                app.T2 = read_table_with_row_names(fullfile(app.exp_dir,app.round_folder,'internal','T2.txt'));
                app.T3 = read_table_with_row_names(fullfile(app.exp_dir,'internal','T3.txt'));
                app.T4 = read_table_with_row_names(fullfile(app.exp_dir,'internal','T4.txt'));

            % or else make new ones
            else
                create_status_tables(app)
            end

            app.num_uniq = size(app.T1,1);
            app.num_process_steps = size(app.T1,2);
            app.num_manual_steps = size(app.T2,2);
            app.num_post_steps = size(app.T3,2);

            app.update_tables()
            app.load_pipeline()
            app.write_tables()

        end

        function update_tables(app)

            % set row names of each table to uniq_ids
            row_names = app.T1.Properties.RowNames;
            app.FilesTable.RowName = row_names;
            app.SettingsTable.RowName = row_names;
            app.StitchTable.RowName = row_names;
            app.RegTable.RowName = row_names;
            app.FiducialsTable.RowName = row_names;
            app.UnmixTable.RowName = row_names;
            app.UnmixParamTable.RowName = row_names;
            app.FinishTable.RowName = row_names;
            app.PostTable.RowName = row_names;
            app.SegTable.RowName = row_names;
            
            % sets the Data for each table to an empty cell array
            app.FilesTable.Data = cell(app.num_uniq,app.num_process_steps);
            app.SettingsTable.Data = cell(app.num_uniq,app.num_manual_steps);
            app.StitchTable.Data = cell(app.num_uniq,1);
            app.RegTable.Data = cell(app.num_uniq,1);
            app.FiducialsTable.Data = cell(app.num_uniq,1);
            app.FinishTable.Data = cell(app.num_uniq,1);
            app.UnmixTable.Data = cell(app.num_uniq,1);
            app.UnmixParamTable.Data = cell(app.num_uniq,1);
            app.PostTable.Data = cell(app.num_uniq,app.num_post_steps);
            app.SegTable.Data = cell(app.num_uniq,1);
            
            % histology table is special because it may or may not be there
            if ~isempty(app.histo_dir)
                app.HistoTable.RowName = app.T5.Properties.RowNames;
                num_uniq_histo = length(app.uniq_ids_histo);
                app.HistoTable.Data = cell(num_uniq_histo,1);
            end

            switch app.TableFileSwitch.Value

                case "Status Tables"
                    
                    app.update_table_visual(table2array(app.T1),app.FilesTable)
                    app.update_table_visual(table2array(app.T2),app.SettingsTable)
                    app.update_table_visual(app.T1.Stitched,app.StitchTable)
                    app.update_table_visual(app.T1.Registered,app.RegTable)
                    app.update_table_visual(app.T2.Fiducials,app.FiducialsTable)
                    app.update_table_visual(app.T1.Finished,app.FinishTable)
                    app.update_table_visual(app.T1.Unmixed,app.UnmixTable)
                    app.update_table_visual(app.T2.("Unmix Settings"),app.UnmixParamTable)
                    app.update_table_visual(table2array(app.T3),app.PostTable)
                    app.update_table_visual(app.T3.Segmented,app.SegTable)
                    if ~isempty(app.histo_dir)
                        app.update_table_visual(app.T5.Processed,app.HistoTable)
                    end
                    app.update_tables_pipeline()

                case "Files"

                    app.update_literal_tables()
                    app.update_table_visual(table2array(app.T1_literal),app.FilesTable)
                    app.update_table_visual(table2array(app.T2_literal),app.SettingsTable)
                    app.update_table_visual(app.T1_literal.Stitched,app.StitchTable)
                    app.update_table_visual(app.T1_literal.Registered,app.RegTable)
                    app.update_table_visual(app.T2_literal.Fiducials,app.FiducialsTable)
                    app.update_table_visual(app.T1_literal.Finished,app.FinishTable)
                    app.update_table_visual(app.T1_literal.Unmixed,app.UnmixTable)
                    app.update_table_visual(app.T2_literal.("Unmix Settings"),app.UnmixParamTable)
                    app.update_table_visual(table2array(app.T3_literal),app.PostTable)
                    app.update_table_visual(app.T5_literal.Processed,app.HistoTable)
                    app.update_tables_pipeline()
            end


        end

        function update_table_visual(~, backend_arr, frontend_table)

            g_box = uistyle('BackgroundColor','green', ...
                'Icon','success', ...
                'IconAlignment','left', ...
                'HorizontalAlignment','center');
            r_box = uistyle('BackgroundColor','red', ...
                'Icon',fullfile('./','assets','Cross_red_circle.svg'), ...
                'IconAlignment','left', ...
                'HorizontalAlignment','center');

           
            nrows = size(frontend_table.Data,1);
            ncols = size(frontend_table.Data,2);

            for j=1:nrows
                for k=1:ncols
                    switch backend_arr(j,k)
                        case 0
                            addStyle(frontend_table,r_box,'cell',[j,k])
                        case 1
                            addStyle(frontend_table,g_box,'cell',[j,k])
                        otherwise
                            continue
                    end
                end
            end
               
        end
        
        % go back at the end and blur out the steps that aren't being done
        function update_tables_pipeline(app)

            gray_box = uistyle('BackgroundColor',[0.7 0.7 0.7], ...
                'Icon',fullfile('./','assets','Twemoji2_1f5ff.svg'), ...
                'IconAlignment','left', ...
                'HorizontalAlignment','center');

            if app.T4(app.round_folder,:).stitch == 0
                for i=1:length(app.uniq_ids_HCR)
                    addStyle(app.FilesTable,gray_box,'cell',[i,1])
                    addStyle(app.StitchTable,gray_box,'cell',[i,1])
                end
            end

            if app.T4(app.round_folder,:).unmix == 0
                for i=1:length(app.uniq_ids_HCR)
                    addStyle(app.FilesTable,gray_box,'cell',[i,2])
                    addStyle(app.UnmixTable,gray_box,'cell',[i,1])
                    addStyle(app.UnmixParamTable,gray_box,'cell',[i,1])
                    addStyle(app.SettingsTable,gray_box,'cell',[i,1])
                end
            end

            if app.T4(app.round_folder,:).reg == 0
                for i=1:length(app.uniq_ids_HCR)
                    addStyle(app.FilesTable,gray_box,'cell',[i,3])
                    addStyle(app.RegTable,gray_box,'cell',[i,1])
                    addStyle(app.FiducialsTable,gray_box,'cell',[i,1])
                    addStyle(app.SettingsTable,gray_box,'cell',[i,2])
                end
            end
            
            if app.T4(app.round_folder,:).finish == 0
                for i=1:length(app.uniq_ids_HCR)
                    addStyle(app.FilesTable,gray_box,'cell',[i,4])
                    addStyle(app.FinishTable,gray_box,'cell',[i,1])
                end
            end


        end

        function update_literal_tables(app)
            
            % check for existence of uniq ids as either files or folders
            % in a folder, type = 'file' or 'dir', returns column vector 
            % of length N, where N is the number of uniq_ids, composed of
            % 1s and 0s
            function bin_col = assay_folder(folder, uniq_ids, type)

                bin_col = zeros(length(uniq_ids),1);
                listing = fetch_dir(folder, type);
                for i=1:length(uniq_ids)
                    if any(contains(listing,uniq_ids(i)))
                        bin_col(i) = 1;
                    end
                end

            end

            app.T1_literal = app.T1;
            app.T2_literal = app.T2;
            app.T3_literal = app.T3;
            app.T5_literal = app.T5;

            app.T1_literal.Stitched = assay_folder(fullfile(app.exp_dir,app.round_folder,'stitch'),app.uniq_ids_HCR,'file');
            app.T1_literal.Registered = assay_folder(fullfile(app.exp_dir,app.round_folder,'reg'),app.uniq_ids_HCR,'file');
            app.T1_literal.Unmixed = assay_folder(fullfile(app.exp_dir,app.round_folder,'unmix'),app.uniq_ids_HCR,'file');
            app.T2_literal.Fiducials = assay_folder(fullfile(app.exp_dir,app.round_folder,'internal','fiducials'),app.uniq_ids_HCR,'file');
            app.T2_literal.('Unmix Settings') = assay_folder(fullfile(app.exp_dir,app.round_folder,'internal','uparams'),app.uniq_ids_HCR,'file');
            app.T5_literal.Processed = assay_folder(fullfile(app.exp_dir,app.histo_dir,'processed'),app.uniq_ids_histo,'file');
            
            bin_col = zeros(length(app.uniq_ids_HCR),1);
            split_str = split(app.round_folder,'_');
            round_id = split_str{1};

            % iterate through slices, checking for the current round
            for j=1:length(app.uniq_ids_HCR)
                listing = fetch_dir(fullfile(app.exp_dir,'post','core_output',app.uniq_ids_HCR{j}),'file');
                if any(contains(listing,round_id))
                        bin_col(j) = 1;
                end
            end

            app.T1_literal.Finished = bin_col;

        end

        function write_tables(app)

            writetable(app.T1, fullfile(app.exp_dir,app.round_folder,'internal','T1.txt'), ...
                    'WriteRowNames',true);
            writetable(app.T2, fullfile(app.exp_dir,app.round_folder,'internal','T2.txt'), ...
                    'WriteRowNames',true);
            writetable(app.T3, fullfile(app.exp_dir,'internal','T3.txt'), ...
                    'WriteRowNames',true);
            writetable(app.T4, fullfile(app.exp_dir,'internal','T4.txt'), ...
                    'WriteRowNames',true);
            if ~isempty(app.histo_dir)
                writetable(app.T5, fullfile(app.exp_dir,app.histo_dir,'internal','T5.txt'), ...
                    'WriteRowNames',true);
            end

        end
        
        % build the full file name of an image given its unique identifier
        % and subfolder
        function img_f = build_img_f(app, img, subfolder, roundf, mode)
            
            arguments
                app
                img
                subfolder
                roundf = app.round_folder
                mode = 'HCR'
            end

            listing = fetch_dir(fullfile(app.exp_dir,roundf,subfolder),'file');
            
            switch mode
                case 'HCR'
                    listing = filt_kw(listing, app.HCR_kw, 'keep');
                case 'histo'
                    listing = filt_kw(listing, app.histo_kw, 'keep');
            end

            if sum(contains(listing, img)) == 0
                img_f = [];
                return
            end
            full_img = listing{contains(listing, img)};
            img_f = fullfile(app.exp_dir,roundf,subfolder,full_img);
           
        end
        
        % create new status tables
        function create_status_tables(app)

            listing = fetch_dir(fullfile(app.exp_dir,app.round_folder,'raw'), 'file');
            listing = filt_kw(listing, app.HCR_kw, 'keep');
            
            % make sure there are raw files in this round folder
            if length(listing) < 1
                app.T1 = [];
                app.T2 = [];
                update_tables(app)
                msgbox("No raw files or status tables found in this round folder")
                return
            end

            nfiles = length(listing);
            for i=1:nfiles
                split_listing = split(listing{i},'__');
                listing{i} =  split_listing{1};
            end
            
            app.T1 = table(zeros(nfiles,1), ...
                zeros(nfiles,1), ...
                zeros(nfiles,1), ...
                zeros(nfiles,1), ...
                'VariableNames',{'Stitched' 'Unmixed' 'Registered' 'Finished'}, ...
                'RowNames',listing);

            app.T2 = table(zeros(nfiles,1), ...
                zeros(nfiles,1), ...
                'VariableNames',{'Unmix Settings' 'Fiducials'}, ...
                'RowNames',listing);

            app.T3 = table(zeros(nfiles,1), ...
                zeros(nfiles,1), ...
                zeros(nfiles,1), ...
                'VariableNames',{'LipoGone' 'Segmented' 'CCF Registered'}, ...
                'RowNames',listing);
            
            % creates T4
            app.create_pipeline()

            write_tables(app)
        end
        
        % takes in file name and returns index of registration channel
        % according to preset registration channel token
        function ind = id_reg_channel(app,fn)

            split_fn = split(fn,'__');
            chans = split_fn{2};
            split_chans = split(chans,'_');

            for i=1:length(split_chans)

                if contains(split_chans{i},app.reg_channel_token,'IgnoreCase',true)
                    ind = i;
                    return
                end
            end
        end

        % called when switches change
        function update_pipeline_lamps(app)

            function check_switch(pipeline_switch, lamp)
                if strcmp(pipeline_switch.Value,'On')
                    lamp.Color = [0 1 0];
                else
                    lamp.Color = [0.8 0.8 0.8];
                end
            end
            
            check_switch(app.StitchSwitch,app.StitchLamp)
            check_switch(app.RegSwitch,app.RegLamp)
            check_switch(app.UnmixSwitch,app.UnmixLamp)
            check_switch(app.FinishSwitch,app.FinishLamp)

        end

        function update_pipeline_internal(app)

            d = dictionary('On',1,'Off',0);
            new_table = app.T4(app.round_folder,:);
            new_table.stitch = d(app.StitchSwitch.Value);
            new_table.unmix = d(app.UnmixSwitch.Value);
            new_table.reg = d(app.RegSwitch.Value);
            new_table.finish = d(app.FinishSwitch.Value);
            app.T4(app.round_folder,:) = new_table;

            app.update_tables()
            app.write_tables()

        end
        
        % called on startup and when round is changed
        function load_pipeline(app)
            
            % MATLAB, in its infinite wisdom, doesn't allow dictionary keys
            % of type double
            function output = non_shitty_dict(input)
                if input == 1
                    output = 'On';
                elseif input == 0
                    output = 'Off';
                end
            end

            app.StitchSwitch.Value = non_shitty_dict(app.T4(app.round_folder,:).stitch);
            app.RegSwitch.Value = non_shitty_dict(app.T4(app.round_folder,:).reg);
            app.UnmixSwitch.Value = non_shitty_dict(app.T4(app.round_folder,:).unmix);
            app.FinishSwitch.Value = non_shitty_dict(app.T4(app.round_folder,:).finish);
            app.update_pipeline_lamps()

        end
        
        % called first time experimental directory is opened in HCRprocess
        function create_pipeline(app)

            keys = {};
            for i=1:length(app.ExperimentDirectoryNode.Children)
                keys{end+1} = app.ExperimentDirectoryNode.Children(i).Text;
            end
            
            nrounds = length(keys);
            app.T4 = table(ones(nrounds,1), ...
                ones(nrounds,1), ...
                ones(nrounds,1), ...
                ones(nrounds,1), ...
                'VariableNames',{'stitch' 'unmix' 'reg' 'finish'}, ...
                'RowNames',keys);
            
            if ~isempty(app.reg_round)
                app.T4(app.reg_round,:).reg = 0;
            end

        end
        
        % returns input directory for a given operation "op" and round
        % folder "roundf"
        % i.e. for a given round and operation, where should you look for
        % the input file
        function input_dir = calc_core_IO(app,op,roundf)

            arguments
                app
                op
                roundf = app.round_folder
            end

            round_T4 = app.T4(roundf,:);
            op_idx = find(strcmp(round_T4.Properties.VariableNames,op));

            input_idx = op_idx - 1;

            while input_idx > 0
                input_dir = round_T4.Properties.VariableNames{input_idx};
                if round_T4{:,input_dir} == 1
                    return
                else
                    input_idx = input_idx - 1;
                end
            end

        end
        
        % dialog box called when user attempts to process a file that has
        % already been processed
        function choice = verif_dialog(app,txt_prompt)
            
            fig = app.UIFigure;
            choice = uiconfirm(fig,txt_prompt, ...
                'File already processed','Icon','warning', ...
                "Options",["Yes","No"], ...
                "DefaultOption",1, ...
                "CancelOption",2);

        end
        
        % delete unnecessary files saved by registration 
        function clear_reg_trash(~)
            
            delete('result.0.nii')
            delete('result.1.nii')
            delete('TransformParameters.0.txt')
            delete('TransformParameters.1.txt')

        end

        function fixed_fn = find_fixed_im(app,sel)
            
            fixed_fn = app.build_img_f(sel, 'stitch', app.reg_round);

            if isempty(fixed_fn)
                fixed_fn = app.build_img_f(sel,'unmix',app.reg_round);
            end

        end

        
        function report_do_all_cache(app)

            if ~isempty(app.do_all_cache)
                for i=1:length(app.do_all_cache)
                    disp(app.do_all_cache{i})
                end
            else
                disp('All operations completed successfully.')
            end
            
            app.do_all_cache = {};

        end

        function do_post(app, op, mode)

            function seg_image(im)

                command1 = 'activate HCRprocess && ';

                str1 = 'python -m cellpose --verbose --image_path ';
                str2 = [' --pretrained_model Medulla_Training1_PC_020425 ' ...
                    '--diameter 45.12 ' ...
                    '--stitch_threshold 0.4 ' ...
                    '--min_size 30 ' ...
                    '--anisotropy 6 ' ...
                    '--save_tif ' ...
                    '--no_npy'];
                
                command2 = [str1, im, str2];

                system([command1 command2]);

                % fetch the uniq_id of the image being segmented
                [filepath,~,~] = fileparts(im);
                [~,name,~] = fileparts(filepath);
                uniq_id = name;
                
                % masks tif that was just generated
                [filepath,name,~] = fileparts(im);
                source = [fullfile(filepath,name) '_cp_masks.tif'];
                
                % destination in the masks folder
                [filepath,~,~] = fileparts(im);
                [filepath,~,~] = fileparts(filepath);
                [filepath,~,~] = fileparts(filepath);
                destination = fullfile(filepath,'masks',[uniq_id '_cp_masks.tif']);

                movefile(source,destination)
                
            end

            switch op
                case 'seg'
                    sel_table = app.SegTable;
                    sel = app.seg_sel;
                case 'ccf_reg'
            end

            switch mode
                case 'single'
                    
                case 'all'
            end

            input_dir = fullfile(app.exp_dir,'post','core_output',app.seg_sel);
            listing = fetch_dir(input_dir,'file');
            split_str = split(app.reg_round,'_');
            roundstr = split_str{2};
            strs_filt = filt_kw(listing,roundstr,'keep');
            strs_filt = filt_kw(strs_filt,app.reg_channel_token,'keep');
            input_f = fullfile(input_dir,strs_filt{1});
            seg_image(input_f)

        end

    end
    
    
    methods (Access = public)
        
        function finish_fiducials(app)
            
            fid_sel_idx = find(strcmp(app.FiducialsTable.RowName, app.fiducials_sel));
            app.T2(fid_sel_idx,:).Fiducials = 1;
            update_tables(app)
            write_tables(app)
            
        end

        function finish_unmix_params(app)

            unmix_param_sel_idx = find(strcmp(app.UnmixParamTable.RowName, app.unmix_param_sel));
            app.T2(unmix_param_sel_idx,:).("Unmix Settings") = 1;
            update_tables(app)
            write_tables(app)

        end

        function finish_unmix(app)

            unmix_sel_idx = find(strcmp(app.UnmixTable.RowName, app.unmix_sel));
            app.T1(unmix_sel_idx,:).Unmixed = 1;
            update_tables(app)
            write_tables(app)

        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            app.UIFigure.Name = 'HCRprocess';
            app.tables = {app.FilesTable, app.SettingsTable, app.StitchTable, ...
                app.RegTable, app.FiducialsTable, app.UnmixTable, app.UnmixParamTable, ...
                app.FinishTable};

            % set up paths
            [filepath,~,~] = fileparts(mfilename('fullpath'));
            cd(filepath)
            addpath(['./' 'utils'])
            folderName = params('fijiScriptsDir');
            addpath(folderName)
    
            % set experiment directory
            filename = fullfile(pwd,'internal','previous_exp_dir.mat');
            load(filename, 'previous_exp_dir')
            list_dir = dir(previous_exp_dir);
            if isempty(list_dir)
                app.exp_dir = uigetdir();
                previous_exp_dir = app.exp_dir;
                save(fullfile(pwd,'internal','previous_exp_dir.mat'), 'previous_exp_dir')
            else
                app.exp_dir = previous_exp_dir;
            end
            app.ExperimentDirectoryNode.Text = app.exp_dir;
        
            % populate subfolders (rounds)
            listing = fetch_dir(app.exp_dir, 'dir');
            listing_histo = filt_kw(listing,app.histo_kw,'keep');
            
            if ~isempty(listing_histo)
                app.histo_dir = listing_histo{1};
                app.uniq_ids_histo = app.get_uniq_ids(app.histo_dir,app.histo_kw);
            end

            listing = filt_kw(listing,app.HCR_kw,'keep');

            for i=1:length(listing)
                node = uitreenode(app.ExperimentDirectoryNode,'Text',listing{i}); %#ok<NASGU>
            end
            
            app.get_round_ids()

            % populate internal folders
            pop_int_folders(app, listing);

            % set registration round
            init_reg_round(app);
            
            % expand experiment directory and select first round (r0)
            expand(app.ExperimentDirectoryNode)
            app.DirTree.SelectedNodes = app.ExperimentDirectoryNode.Children(1);
            app.round_folder = app.DirTree.SelectedNodes(1).Text;
            update_round(app);
            
        end

        % Button pushed function: ExpDirButton
        function ExpDirButtonPushed(app, event)
            for i=1:length(app.ExperimentDirectoryNode.Children)
                app.ExperimentDirectoryNode.Children(1).delete
            end
            previous_exp_dir = '';
            save(fullfile(pwd,'internal','previous_exp_dir.mat'), 'previous_exp_dir')
            app.startupFcn()
        end

        % Selection changed function: DirTree
        function DirTreeSelectionChanged(app, event)
            
            selectedNodes = app.DirTree.SelectedNodes;
            
            % don't do anything if experiment directory supernode was
            % accidentally clicked
            if ~strcmp(selectedNodes(1).Text,app.exp_dir)
                app.round_folder = selectedNodes(1).Text;
                update_round(app);
            end  
            
        end

        % Button pushed function: StitchButton
        function StitchButtonPushed(app, event)
            img_f = build_img_f(app, app.stitch_sel, 'raw');
            stitch_sel_idx = find(strcmp(app.StitchTable.RowName, app.stitch_sel));
            if app.T1(stitch_sel_idx,:).Stitched == 1
                choice = app.verif_dialog('This file has already been stitched. Stitch anyway? (this will overwrite previous stitched file)');
                switch choice
                    case 'Yes'
                        % do nothing
                    case 'No'
                        return
                end
            end
            
            d = uiprogressdlg(app.UIFigure,'Title','Stitching Progress',...
                'Message','(0/1 files) Stitching...');
            d.Value = 0;
            file_frac = '(0/1 files)';
            stitch(img_f, d, file_frac)

            app.T1(stitch_sel_idx,:).Stitched = 1;
            update_tables(app)
            write_tables(app)

            d.Value = 1;
            close(d)

           
        end

        % Button pushed function: StitchAllButton
        function StitchAllButtonPushed(app, event)
            to_stitch = ~app.T1.Stitched;

            % app is not running in 'do all' mode
            if (sum(to_stitch) < 1) && (app.do_all_flag == false)
                choice = app.verif_dialog('All files have already been stitched. Stitch anyway? (this will overwrite previous stitched files)');
                switch choice
                    case 'Yes'
                        to_stitch = ~to_stitch;
                    case 'No'
                        return
                end
            end

            % app is in 'do all' mode and 'redo' mode is off
            if (sum(to_stitch) < 1) && (app.do_all_flag == true) && (app.RedoCheckBox.Value == false)
                return
            end

            stitch_sels = app.StitchTable.RowName(to_stitch);
            
            max_files = sum(to_stitch);
            file_frac = strcat('(0/',string(max_files),' files)');
            d = uiprogressdlg(app.UIFigure,'Title','Stitching Progress',...
                    'Message',strcat(file_frac,' Stitching...'));
            d.Value = 0;
            
            
            for i=1:length(stitch_sels)

                file_frac = strcat('(',string(i-1),'/',string(max_files),' files)');
                d.Message = strcat(file_frac,' Stitching...');

                img_f = build_img_f(app, stitch_sels(i), 'raw');
                stitch_sel_idx = find(strcmp(app.StitchTable.RowName, stitch_sels(i)));
                
                stitch(img_f, d, file_frac)

                app.T1(stitch_sel_idx,:).Stitched = 1;
                update_tables(app)
                write_tables(app)

                d.Value = i/max_files;
                
            end

        end

        % Button pushed function: UnmixParamButton
        function UnmixParamButtonPushed(app, event)
            
            input_dir = app.calc_core_IO('unmix',app.round_folder);

            % row index of selected cell in unmix params table
            unmix_param_sel_idx = find(strcmp(app.UnmixParamTable.RowName, app.unmix_param_sel));
            if app.T2(unmix_param_sel_idx,:).("Unmix Settings") == 1
                load_params = 1;
            else
                load_params = 0;
            end

            img_f = build_img_f(app, app.unmix_param_sel, input_dir);

            if isempty(img_f)
                msgbox(strcat("Unable to locate file: (", app.unmix_param_sel,") in folder: (", input_dir,")"))
                return
            end

            setUnmixParams(img_f, app, 'find_params', load_params)
        end

        % Button pushed function: UnmixButton
        function UnmixButtonPushed(app, event)
            unmix_sel_idx = find(strcmp(app.UnmixTable.RowName, app.unmix_sel));

            if app.T1(unmix_sel_idx,:).Unmixed == 1
                choice = app.verif_dialog('This file has already been unmixed. Unmix anyway? (this will overwrite previous unmixed file)');
                switch choice
                    case 'Yes'
                        % do nothing
                    case 'No'
                        return
                end
            end

            input_dir = app.calc_core_IO('unmix',app.round_folder);

            uparams_fn = find_fn_uniq_id(app.unmix_sel,fullfile(app.exp_dir,app.round_folder,'internal','uparams'));

            if isempty(uparams_fn)
                msgbox("No unmix parameters found for this file","Unmix parameters error","error")
                return
            end

            img_f = build_img_f(app, app.unmix_sel, input_dir);

            setUnmixParams(img_f, app, 'write',0)

            if app.DeleteIntermediatesCheckBox.Value == 1
                delete(img_f)
            end
        end

        % Button pushed function: UnmixAllButton
        function UnmixAllButtonPushed(app, event)
            to_unmix = ~app.T1.Unmixed;

            % app is not running in 'do all' mode
            if (sum(to_unmix) < 1) && (app.do_all_flag == false)
                choice = app.verif_dialog('All files have already been unmixed. Unmix anyway? (this will overwrite previous unmixed files)');
                switch choice
                    case 'Yes'
                        to_unmix = ~to_unmix;
                    case 'No'
                        return
                end
            end

            % app is in 'do all' mode and 'redo' mode is off
            if (sum(to_unmix) < 1) && (app.do_all_flag == true) && (app.RedoCheckBox.Value == false)
                return
            end

            unmix_sels = app.UnmixTable.RowName(to_unmix);
            
            max_files = sum(to_unmix);
            file_frac = strcat('(0/',string(max_files),' files)');
            d = uiprogressdlg(app.UIFigure,'Title','Unmixing Progress',...
                    'Message',strcat(file_frac,' Unmixing...'));
            d.Value = 0;
            
            for i=1:length(unmix_sels)

                app.unmix_sel = unmix_sels(i);

                file_frac = strcat('(',string(i-1),'/',string(max_files),' files)');
                d.Message = strcat(file_frac,' Unmixing...');
                
                input_dir = app.calc_core_IO('unmix');
                img_f = build_img_f(app, unmix_sels(i), input_dir);

                if isempty(img_f)
                    app.do_all_cache{end+1} = strcat("Unmix failed, unable to locate file: (", app.unmix_sel,") in folder: (",fullfile(app.round_folder,input_dir),")");
                    continue
                end

                unmix_sel_idx = find(strcmp(app.UnmixTable.RowName, unmix_sels(i)));

                uparams_fn = find_fn_uniq_id(app.unmix_sel,fullfile(app.exp_dir,app.round_folder,'internal','uparams'));

                if isempty(uparams_fn)
                    app.do_all_cache{end+1} = strcat("Unmix failed, unable to find unmix parameters for: ", app.unmix_sel, ", skipped this file.");
                    continue
                end
                
                setUnmixParams(img_f, app, 'write',0)

                if app.DeleteIntermediatesCheckBox.Value == 1
                    delete(img_f)
                end

                app.T1(unmix_sel_idx,:).Unmixed = 1;
                update_tables(app)
                write_tables(app)

                d.Value = i/max_files;
                
            end

            if app.do_all_flag == false
                app.report_do_all_cache();
            end
        end

        % Button pushed function: FiducialsButton
        function FiducialsButtonPushed(app, event)
            
            moving_input_dir = app.calc_core_IO('reg',app.round_folder);

            % row index of selected cell in fiducials table
            fid_sel_idx = find(strcmp(app.FiducialsTable.RowName, app.fiducials_sel));
            
            % if there's previous fiducials to be automatically loaded
            if app.T2(fid_sel_idx,:).Fiducials == 1
                load_fid = 1;
            else
                load_fid = 0;
            end

            moving_fn = app.build_img_f(app.fiducials_sel, moving_input_dir);

            if isempty(moving_fn)
                msgbox(strcat("Unable to locate file: (", app.fiducials_sel,") in folder: (",fullfile(app.round_folder,moving_input_dir),")"))
                return
            end

            fixed_fn = app.find_fixed_im(app.fiducials_sel);

            if isempty(fixed_fn)
                msgbox(strcat("Unable to find fixed image for: ", app.fiducials_sel, "."))
                return
            end

            [~,name,~] = fileparts(moving_fn);
            moving_reg_channel = app.id_reg_channel(name);
            [~,name,~] = fileparts(fixed_fn);
            fixed_reg_channel = app.id_reg_channel(name);

            findFiducials(fixed_fn, moving_fn, fixed_reg_channel, moving_reg_channel, app, load_fid)
            
        end

        % Button pushed function: RegButton
        function RegButtonPushed(app, event)
            
            moving_input_dir = app.calc_core_IO('reg',app.round_folder);

            reg_sel_idx = find(strcmp(app.RegTable.RowName, app.reg_sel));

            if app.T1(reg_sel_idx,:).Registered == 1
                choice = app.verif_dialog('This file has already been registered. Register anyway? (this will overwrite previous registered file)');
                switch choice
                    case 'Yes'
                        % do nothing
                    case 'No'
                        return
                end
            end

            dlg = uiprogressdlg(app.UIFigure,'Title','Registration Progress',...
                'Message','(0/1 files) Registering...');
            dlg.Value = 0;
            file_frac = '(0/1 files)';
            
            moving_fn = app.build_img_f(app.reg_sel, moving_input_dir, app.round_folder);
            fixed_fn = app.find_fixed_im(app.reg_sel);

            fids_fn = find_fn_uniq_id(app.reg_sel,fullfile(app.exp_dir,app.round_folder,'internal','fiducials'));

            if isempty(fids_fn)
                msgbox("No fiducials found for this file","Fiducials error","error")
                return
            end

            itxHandle = itxBspline2D(moving_fn, fixed_fn, dlg, file_frac);
            delete(itxHandle)

            app.clear_reg_trash()

            if app.DeleteIntermediatesCheckBox.Value == 1
                delete(moving_fn)
            end

            app.T1(reg_sel_idx,:).Registered = 1;
            update_tables(app)
            write_tables(app)

            dlg.Value = 1;
            close(dlg)


        end

        % Button pushed function: RegAllButton
        function RegAllButtonPushed(app, event)
            
            to_reg = ~app.T1.Registered;

            % app is not running in 'do all' mode
            if (sum(to_reg) < 1) && (app.do_all_flag == false)
                choice = app.verif_dialog('All files have already been registered. Register anyway? (this will overwrite previous registered files)');
                switch choice
                    case 'Yes'
                        to_reg = ~to_reg;
                    case 'No'
                        return
                end
            end

            % app is in 'do all' mode and 'redo' mode is off
            if (sum(to_reg) < 1) && (app.do_all_flag == true) && (app.RedoCheckBox.Value == false)
                return
            end

            reg_sels = app.RegTable.RowName(to_reg);
            
            dlg = uiprogressdlg(app.UIFigure,'Title','Registration Progress',...
                    'Message','(0/1 files) Registering...');
            dlg.Value = 0;
            file_frac = '(0/1 files)';

            for i=1:length(reg_sels)

                app.reg_sel = reg_sels(i);

                moving_input_dir = app.calc_core_IO('reg',app.round_folder);

                reg_sel_idx = find(strcmp(app.RegTable.RowName, app.reg_sel));

                moving_fn = app.build_img_f(app.reg_sel, moving_input_dir, app.round_folder);
                fixed_fn = app.find_fixed_im(app.reg_sel);

                fids_fn = find_fn_uniq_id(app.reg_sel,fullfile(app.exp_dir,app.round_folder,'internal','fiducials'));

                if isempty(fids_fn)
                    app.do_all_cache{end+1} = strcat("Registration failed, unable to find fiducials for: ", app.reg_sel, ", skipped this file.");
                    continue
                end

                if isempty(moving_fn)
                    app.do_all_cache{end+1} = strcat("Unable to find moving image for: ", app.reg_sel, ", skipped this file.");
                    continue
                end

                if isempty(fixed_fn)
                    app.do_all_cache{end+1} = strcat("Unable to find fixed image for: ", app.reg_sel, ", skipped this file.");
                    continue
                end

                itxHandle = itxBspline2D(moving_fn, fixed_fn, dlg, file_frac);
                delete(itxHandle)

                app.clear_reg_trash()

                if app.DeleteIntermediatesCheckBox.Value == 1
                    delete(moving_fn)
                end
    
                app.T1(reg_sel_idx,:).Registered = 1;
                update_tables(app)
                write_tables(app)

            end

            if app.do_all_flag == false
                app.report_do_all_cache();
            end


        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            
            input_dir = app.calc_core_IO('finish',app.round_folder);

            process_sel_idx = find(strcmp(app.FinishTable.RowName, app.process_sel));

            if app.T1(process_sel_idx,:).Finished == 1
                choice = app.verif_dialog('This file has already been finished. Finish anyway? (this will overwrite previous finished files)');
                switch choice
                    case 'Yes'
                        % do nothing
                    case 'No'
                        return
                end
            end
            
            img_f = build_img_f(app, app.process_sel, input_dir);

            if isempty(img_f)
                msgbox(strcat("Unable to locate file: (", app.process_sel,") in folder: (",fullfile(app.round_folder,input_dir),")"))
                return
            end

            process_final(img_f, app.SubtractbackgroundCheckBox.Value, app.RadiusEditField.Value)

            if app.DeleteIntermediatesCheckBox.Value == 1
                delete(img_f)
            end

            app.T1(process_sel_idx,:).Finished = 1;
            update_tables(app)
            write_tables(app)


        end

        % Button pushed function: ProcessAllButton
        function ProcessAllButtonPushed(app, event)
            
            to_process = ~app.T1.Finished;
            
            % app is not running in 'do all' mode
            if (sum(to_process) < 1) && (app.do_all_flag == false)
                choice = app.verif_dialog('All files have already been finished. Finish anyway? (this will overwrite previous finished files)');
                switch choice
                    case 'Yes'
                        to_process = ~to_process;
                    case 'No'
                        return
                end
            end
            
            % app is in 'do all' mode and 'redo' mode is off
            if (sum(to_process) < 1) && (app.do_all_flag == true) && (app.RedoCheckBox.Value == false)
                return
            end

            process_sels = app.FinishTable.RowName(to_process);
            
            for i=1:length(process_sels)

                app.process_sel = process_sels(i);
                process_sel_idx = find(strcmp(app.FinishTable.RowName, app.process_sel));
                
                input_dir = app.calc_core_IO('finish',app.round_folder);
                img_f = build_img_f(app, app.process_sel, input_dir);
    
                if isempty(img_f)
                    app.do_all_cache{end+1} = strcat("Finishing error, unable to locate file: (", app.process_sel,") in folder: (",fullfile(app.round_folder,input_dir),"), skipped this file.");
                    continue
                end
    
                process_final(img_f, app.SubtractbackgroundCheckBox.Value, app.RadiusEditField.Value)

                if app.DeleteIntermediatesCheckBox.Value == 1
                    delete(img_f)
                end
    
                app.T1(process_sel_idx,:).Finished = 1;
                update_tables(app)
                write_tables(app)

            end

            if app.do_all_flag == false
                app.report_do_all_cache();
            end

        end

        % Button pushed function: HistoProcessButton
        function HistoProcessButtonPushed(app, event)
            
            if isempty(app.histo_dir)
                msgbox("No histology directory and/or files found. Make sure to include the appropriate tokens in file and directory names.")
                return
            end

            histo_sel_idx = find(strcmp(app.HistoTable.RowName, app.histo_sel));

            if app.T5(histo_sel_idx,:).Processed == 1
                choice = app.verif_dialog('This file has already been processed. Process anyway? (this will overwrite previous processed files)');
                switch choice
                    case 'Yes'
                        % do nothing
                    case 'No'
                        return
                end
            end
            
            img_f = build_img_f(app, app.histo_sel, 'raw', app.histo_dir, 'histo');

            if isempty(img_f)
                msgbox(strcat("Unable to locate file: (", app.histo_sel,") in folder: (",fullfile(app.histo_dir,'raw'),")"))
                return
            end

            process_histo(img_f)

            app.T5(histo_sel_idx,:).Processed = 1;
            update_tables(app)
            write_tables(app)
        end

        % Button pushed function: HistoProcessAllButton
        function HistoProcessAllButtonPushed(app, event)
            
            if isempty(app.histo_dir)
                app.do_all_cache{end+1} = "No histology directory and/or files found. Make sure to include the appropriate tokens in file and directory names.";
                if app.do_all_flag == false
                    app.report_do_all_cache();
                end
                return
            end
            
            to_histo = ~app.T5.Processed;
            
            % app is not running in 'do all' mode
            if (sum(to_histo) < 1) && (app.do_all_flag == false)
                choice = app.verif_dialog('All files have already been processed. Process anyway? (this will overwrite previous processed files)');
                switch choice
                    case 'Yes'
                        to_histo = ~to_histo;
                    case 'No'
                        return
                end
            end
            
            % app is in 'do all' mode and 'redo' mode is off
            if (sum(to_histo) < 1) && (app.do_all_flag == true) && (app.RedoCheckBox.Value == false)
                return
            end

            histo_sels = app.HistoTable.RowName(to_histo);
            
            for i=1:length(histo_sels)

                app.histo_sel = histo_sels(i);
                histo_sel_idx = find(strcmp(app.HistoTable.RowName, app.histo_sel));
                
                img_f = build_img_f(app, app.histo_sel, 'raw', app.histo_dir, 'histo');
    
                if isempty(img_f)
                    app.do_all_cache{end+1} = strcat("Unable to locate file: (", app.histo_sel,") in folder: (",fullfile(app.histo_dir,'raw'),")");
                    continue
                end
    
                process_histo(img_f)
    
                app.T5(histo_sel_idx,:).Processed = 1;
                update_tables(app)
                write_tables(app)

            end

        end

        % Button pushed function: DoAllButton
        function DoAllButtonPushed(app, event)
            
            app.do_all_flag = true;
            
            if app.T4(app.round_folder,:).stitch == 1
                app.StitchAllButtonPushed();
            end

            if app.T4(app.round_folder,:).unmix == 1
                app.UnmixAllButtonPushed();
            end

            if app.T4(app.round_folder,:).reg == 1
                app.RegAllButtonPushed();
            end

            if app.T4(app.round_folder,:).finish == 1
                app.ProcessAllButtonPushed();
            end
            
            app.report_do_all_cache();

            app.do_all_flag = false;

        end

        % Cell selection callback: StitchTable
        function StitchTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                row_idx = indices(1);
                app.stitch_sel = app.StitchTable.RowName{row_idx};
            else
                return
            end
            
        end

        % Cell selection callback: FiducialsTable
        function FiducialsTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                row_idx = indices(1);
                app.fiducials_sel = app.FiducialsTable.RowName{row_idx};
            else
                return
            end
            
        end

        % Cell selection callback: UnmixParamTable
        function UnmixParamTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                row_idx = indices(1);
                app.unmix_param_sel = app.UnmixParamTable.RowName{row_idx};
            else
                return
            end
            
        end

        % Cell selection callback: RegTable
        function RegTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                row_idx = indices(1);
                app.reg_sel = app.RegTable.RowName{row_idx};
            else
                return
            end
            
        end

        % Cell selection callback: UnmixTable
        function UnmixTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                row_idx = indices(1);
                app.unmix_sel = app.UnmixTable.RowName{row_idx};
            else
                return
            end
            
        end

        % Cell selection callback: FinishTable
        function FinishTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                row_idx = indices(1);
                app.process_sel = app.FinishTable.RowName{row_idx};
            else
                return
            end
        end

        % Cell selection callback: HistoTable
        function HistoTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                row_idx = indices(1);
                app.histo_sel = app.HistoTable.RowName{row_idx};
            else
                return
            end
        end

        % Cell selection callback: SegTable
        function SegTableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                row_idx = indices(1);
                app.seg_sel = app.SegTable.RowName{row_idx};
            else
                return
            end
        end

        % Button pushed function: memMonitorButton
        function memMonitorButtonPushed(app, event)
            memMonitor()
        end

        % Value changed function: FinishSwitch, LipoSwitch, RegSwitch, 
        % ...and 3 other components
        function ProcessingSwitchValueChanged(app, event)
            app.update_pipeline_lamps()
            app.update_pipeline_internal()
        end

        % Value changed function: TableFileSwitch
        function TableFileSwitchValueChanged(app, event)
            app.update_tables()
        end

        % Value changed function: RegRoundDropDown
        function RegRoundDropDownValueChanged(app, event)
            
            value = app.RegRoundDropDown.Value;
            app.reg_round = value;
            app.meta("reg_round") = app.reg_round;
            app.save_meta()

            app.T4(value,:).reg = 0;
            app.load_pipeline()
            app.update_pipeline_internal()

        end

        % Button pushed function: SegButton
        function SegButtonPushed(app, event)
            app.do_post('seg','single')
        end

        % Button pushed function: SegAllButton
        function SegAllButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'docs', 'doc_assets', 'logo', 'HCRprocess logo.png');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.FilesTable = uitable(app.MainTab);
            app.FilesTable.ColumnName = {'Stitched'; 'Unmixed'; 'Registered'; 'Finished'};
            app.FilesTable.RowName = {};
            app.SettingsTable = uitable(app.MainTab);
            app.SettingsTable.ColumnName = {'Unmix Settings'; 'Fiducials'};
            app.SettingsTable.RowName = {};
            app.PostTable = uitable(app.MainTab);
            app.PostTable.ColumnName = {'LipoGone'; 'Segmented'; 'CCF Registered'};
            app.PostTable.RowName = {};
            app.PostprocessingroundindependentLabel = uilabel(app.MainTab);
            app.PostprocessingroundindependentLabel.Text = 'Post-processing (round-independent)';
            app.ManualsettingsroundspecificLabel = uilabel(app.MainTab);
            app.ManualsettingsroundspecificLabel.Text = 'Manual settings (round-specific)';
            app.CoreprocessingpipelineroundspecificLabel = uilabel(app.MainTab);
            app.CoreprocessingpipelineroundspecificLabel.Text = 'Core processing pipeline (round-specific)';
            app.StitchingTab = uitab(app.TabGroup);
            app.StitchingTab.Title = 'Stitching';
            app.StitchTable = uitable(app.StitchingTab);
            app.StitchTable.ColumnName = {'Stitched'};
            app.StitchTable.RowName = {};
            app.StitchTable.CellSelectionCallback = createCallbackFcn(app, @StitchTableCellSelection, true);
            app.StitchingFunctionsPanel = uipanel(app.StitchingTab);
            app.StitchingFunctionsPanel.Title = 'Stitching Functions';
            app.StitchButton = uibutton(app.StitchingFunctionsPanel, 'push');
            app.StitchButton.ButtonPushedFcn = createCallbackFcn(app, @StitchButtonPushed, true);
            app.StitchButton.Text = 'Stitch this file';
            app.StitchAllButton = uibutton(app.StitchingFunctionsPanel, 'push');
            app.StitchAllButton.ButtonPushedFcn = createCallbackFcn(app, @StitchAllButtonPushed, true);
            app.StitchAllButton.Text = 'Stitch all files';
            app.SpectralUnmixingTab = uitab(app.TabGroup);
            app.SpectralUnmixingTab.Title = 'Spectral Unmixing';
            app.UnmixParamTable = uitable(app.SpectralUnmixingTab);
            app.UnmixParamTable.ColumnName = {'Unmix Parameters'};
            app.UnmixParamTable.RowName = {};
            app.UnmixParamTable.CellSelectionCallback = createCallbackFcn(app, @UnmixParamTableCellSelection, true);
            app.UnmixTable = uitable(app.SpectralUnmixingTab);
            app.UnmixTable.ColumnName = {'Unmixed'};
            app.UnmixTable.RowName = {};
            app.UnmixTable.CellSelectionCallback = createCallbackFcn(app, @UnmixTableCellSelection, true);
            app.UnmixParametersFunctionsPanel = uipanel(app.SpectralUnmixingTab);
            app.UnmixParametersFunctionsPanel.Title = 'Unmix Parameters Functions';
            app.UnmixParamButton = uibutton(app.UnmixParametersFunctionsPanel, 'push');
            app.UnmixParamButton.ButtonPushedFcn = createCallbackFcn(app, @UnmixParamButtonPushed, true);
            app.UnmixParamButton.Text = {'Set/edit unmixing'; 'parameters for this file'};
            app.UnmixFunctionsPanel = uipanel(app.SpectralUnmixingTab);
            app.UnmixFunctionsPanel.Title = 'Unmix Functions';
            app.UnmixButton = uibutton(app.UnmixFunctionsPanel, 'push');
            app.UnmixButton.ButtonPushedFcn = createCallbackFcn(app, @UnmixButtonPushed, true);
            app.UnmixButton.Text = 'Unmix this file';
            app.UnmixAllButton = uibutton(app.UnmixFunctionsPanel, 'push');
            app.UnmixAllButton.ButtonPushedFcn = createCallbackFcn(app, @UnmixAllButtonPushed, true);
            app.UnmixAllButton.Text = 'Unmix all files';
            app.RegTab = uitab(app.TabGroup);
            app.RegTab.Title = 'Registration';
            app.FiducialsTable = uitable(app.RegTab);
            app.FiducialsTable.ColumnName = {'Fiducials'};
            app.FiducialsTable.RowName = {};
            app.FiducialsTable.CellSelectionCallback = createCallbackFcn(app, @FiducialsTableCellSelection, true);
            app.RegTable = uitable(app.RegTab);
            app.RegTable.ColumnName = {'Registered'};
            app.RegTable.RowName = {};
            app.RegTable.CellSelectionCallback = createCallbackFcn(app, @RegTableCellSelection, true);
            app.FiducialsFunctionsPanel = uipanel(app.RegTab);
            app.FiducialsFunctionsPanel.Title = 'Fiducials Functions';
            app.FiducialsButton = uibutton(app.FiducialsFunctionsPanel, 'push');
            app.FiducialsButton.ButtonPushedFcn = createCallbackFcn(app, @FiducialsButtonPushed, true);
            app.FiducialsButton.Text = 'Find/edit fiducials for this file';
            app.RegistrationFunctionsPanel = uipanel(app.RegTab);
            app.RegistrationFunctionsPanel.Title = 'Registration Functions';
            app.RegButton = uibutton(app.RegistrationFunctionsPanel, 'push');
            app.RegButton.ButtonPushedFcn = createCallbackFcn(app, @RegButtonPushed, true);
            app.RegButton.Text = 'Register this file';
            app.RegAllButton = uibutton(app.RegistrationFunctionsPanel, 'push');
            app.RegAllButton.ButtonPushedFcn = createCallbackFcn(app, @RegAllButtonPushed, true);
            app.RegAllButton.Text = 'Register all files';
            app.FinalTab = uitab(app.TabGroup);
            app.FinalTab.Title = 'Finishing';
            app.FinishTable = uitable(app.FinalTab);
            app.FinishTable.ColumnName = {'Finished'};
            app.FinishTable.RowName = {};
            app.FinishTable.CellSelectionCallback = createCallbackFcn(app, @FinishTableCellSelection, true);
            app.FinishingFunctionsPanel = uipanel(app.FinalTab);
            app.FinishingFunctionsPanel.Title = 'Finishing Functions';
            app.RollingballradiusEditFieldLabel = uilabel(app.FinishingFunctionsPanel);
            app.RollingballradiusEditFieldLabel.Text = 'Rolling ball radius:';
            app.RadiusEditField = uieditfield(app.FinishingFunctionsPanel, 'text');
            app.RadiusEditField.Value = '100';
            app.ProcessButton = uibutton(app.FinishingFunctionsPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Finish this file';
            app.ProcessAllButton = uibutton(app.FinishingFunctionsPanel, 'push');
            app.ProcessAllButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessAllButtonPushed, true);
            app.ProcessAllButton.Text = 'Finish all files';
            app.SubtractbackgroundCheckBox = uicheckbox(app.FinishingFunctionsPanel);
            app.SubtractbackgroundCheckBox.Text = 'Subtract background?';
            app.Histology = uitab(app.TabGroup);
            app.Histology.Title = 'Histology';
            app.HistoTable = uitable(app.Histology);
            app.HistoTable.ColumnName = {'Processed'};
            app.HistoTable.RowName = {};
            app.HistoTable.CellSelectionCallback = createCallbackFcn(app, @HistoTableCellSelection, true);
            app.HistologyFunctionsPanel = uipanel(app.Histology);
            app.HistologyFunctionsPanel.Title = 'Histology Functions';
            app.HistoProcessButton = uibutton(app.HistologyFunctionsPanel, 'push');
            app.HistoProcessButton.ButtonPushedFcn = createCallbackFcn(app, @HistoProcessButtonPushed, true);
            app.HistoProcessButton.Text = 'Process this file';
            app.HistoProcessAllButton = uibutton(app.HistologyFunctionsPanel, 'push');
            app.HistoProcessAllButton.ButtonPushedFcn = createCallbackFcn(app, @HistoProcessAllButtonPushed, true);
            app.HistoProcessAllButton.Text = 'Process all files';
            app.SegmentationTab = uitab(app.TabGroup);
            app.SegmentationTab.Title = 'Segmentation';
            app.SegTable = uitable(app.SegmentationTab);
            app.SegTable.ColumnName = {'Segmented'};
            app.SegTable.RowName = {};
            app.SegTable.CellSelectionCallback = createCallbackFcn(app, @SegTableCellSelection, true);
            app.SegAllButton = uibutton(app.SegmentationTab, 'push');
            app.SegAllButton.ButtonPushedFcn = createCallbackFcn(app, @SegAllButtonPushed, true);
            app.SegAllButton.Text = 'Segment all images';
            app.SegButton = uibutton(app.SegmentationTab, 'push');
            app.SegButton.ButtonPushedFcn = createCallbackFcn(app, @SegButtonPushed, true);
            app.SegButton.Text = 'Segment this image';
            app.LipofuscinRemovalTab = uitab(app.TabGroup);
            app.LipofuscinRemovalTab.Title = 'Lipofuscin Removal';
            app.ExpDirButton = uibutton(app.UIFigure, 'push');
            app.ExpDirButton.ButtonPushedFcn = createCallbackFcn(app, @ExpDirButtonPushed, true);
            app.ExpDirButton.Text = 'Change Experiment Directory';
            app.DirTree = uitree(app.UIFigure);
            app.DirTree.SelectionChangedFcn = createCallbackFcn(app, @DirTreeSelectionChanged, true);
            app.ExperimentDirectoryNode = uitreenode(app.DirTree);
            app.ExperimentDirectoryNode.Text = 'Experiment Directory';
            app.CoreProcessingPipelinePanel = uipanel(app.UIFigure);
            app.CoreProcessingPipelinePanel.Title = 'Core Processing Pipeline';
            app.StitchLamp = uilamp(app.CoreProcessingPipelinePanel);
            app.RegLamp = uilamp(app.CoreProcessingPipelinePanel);
            app.UnmixLamp = uilamp(app.CoreProcessingPipelinePanel);
            app.Image = uiimage(app.CoreProcessingPipelinePanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'arrow-pointing-right.svg');
            app.StitchingLabel = uilabel(app.CoreProcessingPipelinePanel);
            app.StitchingLabel.Text = 'Stitching';
            app.RegistrationLabel = uilabel(app.CoreProcessingPipelinePanel);
            app.RegistrationLabel.Text = 'Registration';
            app.StitchSwitch = uiswitch(app.CoreProcessingPipelinePanel, 'toggle');
            app.StitchSwitch.ValueChangedFcn = createCallbackFcn(app, @ProcessingSwitchValueChanged, true);
            app.StitchSwitch.Enable = 'off';
            app.StitchSwitch.Value = 'On';
            app.UnmixingLabel = uilabel(app.CoreProcessingPipelinePanel);
            app.UnmixingLabel.Text = 'Unmixing';
            app.Image_2 = uiimage(app.CoreProcessingPipelinePanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'assets', 'arrow-pointing-right.svg');
            app.RegSwitch = uiswitch(app.CoreProcessingPipelinePanel, 'toggle');
            app.RegSwitch.ValueChangedFcn = createCallbackFcn(app, @ProcessingSwitchValueChanged, true);
            app.RegSwitch.Value = 'On';
            app.UnmixSwitch = uiswitch(app.CoreProcessingPipelinePanel, 'toggle');
            app.UnmixSwitch.ValueChangedFcn = createCallbackFcn(app, @ProcessingSwitchValueChanged, true);
            app.UnmixSwitch.Value = 'On';
            app.Image_4 = uiimage(app.CoreProcessingPipelinePanel);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'assets', 'arrow-pointing-right.svg');
            app.FinishLamp = uilamp(app.CoreProcessingPipelinePanel);
            app.FinishingLabel = uilabel(app.CoreProcessingPipelinePanel);
            app.FinishingLabel.Text = 'Finishing';
            app.FinishSwitch = uiswitch(app.CoreProcessingPipelinePanel, 'toggle');
            app.FinishSwitch.ValueChangedFcn = createCallbackFcn(app, @ProcessingSwitchValueChanged, true);
            app.FinishSwitch.Value = 'On';
            app.PostprocessingPanel = uipanel(app.UIFigure);
            app.PostprocessingPanel.Title = 'Post-processing';
            app.LipoLamp = uilamp(app.PostprocessingPanel);
            app.LipoGoneLabel = uilabel(app.PostprocessingPanel);
            app.LipoGoneLabel.Text = 'LipoGone';
            app.LipoSwitch = uiswitch(app.PostprocessingPanel, 'toggle');
            app.LipoSwitch.ValueChangedFcn = createCallbackFcn(app, @ProcessingSwitchValueChanged, true);
            app.LipoSwitch.Value = 'On';
            app.SegmentationLabel = uilabel(app.PostprocessingPanel);
            app.SegmentationLabel.Text = 'Segmentation';
            app.SegLamp = uilamp(app.PostprocessingPanel);
            app.SegSwitch = uiswitch(app.PostprocessingPanel, 'toggle');
            app.SegSwitch.ValueChangedFcn = createCallbackFcn(app, @ProcessingSwitchValueChanged, true);
            app.SegSwitch.Value = 'On';
            app.CCFSwitch = uiswitch(app.PostprocessingPanel, 'toggle');
            app.CCFSwitch.Value = 'On';
            app.CCFLamp = uilamp(app.PostprocessingPanel);
            app.CCFRegistrationLabel = uilabel(app.PostprocessingPanel);
            app.CCFRegistrationLabel.Text = 'CCF Registration';
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.DeleteIntermediatesCheckBox = uicheckbox(app.SettingsPanel);
            app.DeleteIntermediatesCheckBox.Text = 'Delete Intermediates';
            app.FunctionsPanel = uipanel(app.UIFigure);
            app.FunctionsPanel.Title = 'Functions';
            app.RegistrationRoundLabel = uilabel(app.FunctionsPanel);
            app.RegistrationRoundLabel.Text = 'Registration Round';
            app.RegRoundDropDown = uidropdown(app.FunctionsPanel);
            app.RegRoundDropDown.Items = {};
            app.RegRoundDropDown.ValueChangedFcn = createCallbackFcn(app, @RegRoundDropDownValueChanged, true);
            app.RegRoundDropDown.Value = {};
            app.TableFileSwitch = uiswitch(app.FunctionsPanel, 'slider');
            app.TableFileSwitch.Items = {'Status Tables', 'Files'};
            app.TableFileSwitch.ValueChangedFcn = createCallbackFcn(app, @TableFileSwitchValueChanged, true);
            app.TableFileSwitch.Value = 'Status Tables';
            app.DoAllButton = uibutton(app.FunctionsPanel, 'push');
            app.DoAllButton.ButtonPushedFcn = createCallbackFcn(app, @DoAllButtonPushed, true);
            app.DoAllButton.Text = 'DO ALL CORE';
            app.RedoCheckBox = uicheckbox(app.FunctionsPanel);
            app.RedoCheckBox.Text = 'Redo all too?';
            app.memMonitorButton = uibutton(app.FunctionsPanel, 'push');
            app.memMonitorButton.ButtonPushedFcn = createCallbackFcn(app, @memMonitorButtonPushed, true);
            app.memMonitorButton.Text = 'Launch Memory Monitor';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HCRprocess

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end