classdef LipoGone < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ThresholdSlider            matlab.ui.control.Slider
        ThresholdSliderLabel       matlab.ui.control.Label
        ZplaneLabel                matlab.ui.control.Label
        ZplaneSlider               matlab.ui.control.Slider
        ZplaneSliderLabel          matlab.ui.control.Label
        ImageSelectionButtonGroup  matlab.ui.container.ButtonGroup
        ThresholdedButton          matlab.ui.control.RadioButton
        MultichannelButton         matlab.ui.control.RadioButton
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        inputf
        stack % raw image stack
        im % current im to be plotted
        info % image info
        imsc % scaled color image
        n_zplanes % number of zplanes
        n_chans % number of channels
        weights % weights per channel
        threshold
    end
    
    methods (Access = private)
        
        function update_ZplaneLabel(app)

            str = sprintf('(%u/%u)',app.ZplaneSlider.Value,app.n_zplanes);
            app.ZplaneLabel.Text = str;
            
        end

        function average_image(app)
            multi_chan = app.stack(:,:,:,app.ZplaneSlider.Value);
            multi_chan = cast(multi_chan,"double");

            for i=1:app.n_chans
                multi_chan(:,:,i) = rescale(multi_chan(:,:,i));
            end

            avg_im = zeros(size(multi_chan,[1 2]),'double');

            for i=1:app.n_chans
                avg_im = avg_im + app.weights(i).*multi_chan(:,:,i);
            end

            app.im = avg_im;
        end

        function update_image(app)
            imagesc(app.UIAxes,app.im);
            colormap(app.UIAxes,"gray")
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, inputf)
            app.inputf = inputf;

            [app.stack, app.info] = read_tiff(inputf);

            sz = size(app.stack);
            app.n_chans = sz(3);
            app.n_zplanes = sz(4);

            app.weights = repelem(1/app.n_chans,app.n_chans);
            
            app.ZplaneSlider.Limits(1) = 1;
            app.ZplaneSlider.Limits(2) = app.n_zplanes;
            app.update_ZplaneLabel();

            app.average_image()
            app.update_image()

            
        end

        % Selection changed function: ImageSelectionButtonGroup
        function ImageSelectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.ImageSelectionButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Thresholded Image')
                app.im = app.im > app.threshold;
                app.update_image()
            end
        end

        % Value changed function: ZplaneSlider
        function ZplaneSliderValueChanged(app, event)
            value = app.ZplaneSlider.Value;
            app.ZplaneSlider.Value = round(value);
            app.update_ZplaneLabel()
            app.average_image()
            app.update_image()
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            value = app.ThresholdSlider.Value;
            app.threshold = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ImageSelectionButtonGroup = uibuttongroup(app.UIFigure);
            app.ImageSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImageSelectionButtonGroupSelectionChanged, true);
            app.ImageSelectionButtonGroup.Title = 'Image Selection';
            app.MultichannelButton = uiradiobutton(app.ImageSelectionButtonGroup);
            app.MultichannelButton.Text = 'Multichannel Image';
            app.MultichannelButton.Value = true;
            app.ThresholdedButton = uiradiobutton(app.ImageSelectionButtonGroup);
            app.ThresholdedButton.Text = 'Thresholded Image';
            app.ZplaneSliderLabel = uilabel(app.UIFigure);
            app.ZplaneSliderLabel.Text = 'Z-plane';
            app.ZplaneSlider = uislider(app.UIFigure);
            app.ZplaneSlider.MajorTicks = [];
            app.ZplaneSlider.ValueChangedFcn = createCallbackFcn(app, @ZplaneSliderValueChanged, true);
            app.ZplaneSlider.MinorTicks = [];
            app.ZplaneLabel = uilabel(app.UIFigure);
            app.ZplaneLabel.Text = 'Zplane';
            app.ThresholdSliderLabel = uilabel(app.UIFigure);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.UIFigure);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.MajorTickLabels = {''};
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [];
            app.ThresholdSlider.Value = 0.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LipoGone(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end