classdef block_cut < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LoadSampleImageButton         matlab.ui.control.Button
        HistoPanel                    matlab.ui.container.Panel
        Histoaxes                     matlab.ui.control.UIAxes
        YawImage                      matlab.ui.control.Image
        PitchImage                    matlab.ui.control.Image
        YawSpinner                    matlab.ui.control.Spinner
        BlockingcutyawLabel           matlab.ui.control.Label
        UpdateCCFVolumeButton         matlab.ui.control.Button
        PitchSpinner                  matlab.ui.control.Spinner
        BlockingcutpitchSpinnerLabel  matlab.ui.control.Label
        CCFtt                         matlab.ui.control.TextArea
        SelectCCFVolumeButtonGroup    matlab.ui.container.ButtonGroup
        AverageTemplateButton         matlab.ui.control.RadioButton
        AnnotationBoundaryButton      matlab.ui.control.RadioButton
        AnnotationButton              matlab.ui.control.RadioButton
        ColorCCFbyButtonGroup         matlab.ui.container.ButtonGroup
        SubstructureButton            matlab.ui.control.RadioButton
        StructureButton               matlab.ui.control.RadioButton
        DivisionButton                matlab.ui.control.RadioButton
        CategoryButton                matlab.ui.control.RadioButton
        OrganButton                   matlab.ui.control.RadioButton
        AllenCCFVolumePanel           matlab.ui.container.Panel
        incPButtonFast                matlab.ui.control.Button
        incAButtonFast                matlab.ui.control.Button
        incAButton                    matlab.ui.control.Button
        incPButton                    matlab.ui.control.Button
        zSlider                       matlab.ui.control.Slider
        CCFaxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        abcDir % Description
        imgDir %
        colorDir %

        ccfNII %
        ccfColors %
        ccfTerms
        
        anno_orig
        anno_bound_orig
        avg_temp_orig

        anno % Allen CCF volume, axes correspond to the following anatomical directions:
             % Default: X (DV), Y (AP), Z (ML)
             % Post-rotations: X (ML), Y (DV), Z (AP)
        anno_bound
        avg_temp

        Tcolors % Table that stores hexadecimal color codes of every parcellation index
                % across all parcellation hierarchy levels
        Tterms

        colors
        
        numZ    % total number of z (AP) planes in CCF volume
        zCCF    % current z (AP) plane in CCF volume

        ttOrgan
        ttCategory
        ttDivision
        ttStructure
        ttSubstructure

        CCFmarker

    end
    
    methods (Access = private)

        function loadCCFvol(app)

            function vol = rotateVol(vol)
                vol = imrotate3(vol,-90,[0 0 1]);
                vol = imrotate3(vol,-90,[0 1 0]);
            end
            
            app.anno = niftiread(fullfile(app.imgDir,'annotation_10.nii'));
            app.anno_bound = niftiread(fullfile(app.imgDir,'annotation_boundary_10.nii'));
            app.avg_temp = niftiread(fullfile(app.imgDir,'average_template_10.nii'));

            app.anno = rotateVol(app.anno);
            app.anno_bound = rotateVol(app.anno_bound);
            app.avg_temp = rotateVol(app.avg_temp);

            app.anno_orig = app.anno;
            app.anno_bound_orig = app.anno_bound;
            app.avg_temp_orig = app.avg_temp;
            
        end
        
        function setCCFcolormap(app)
            
            if strcmp(app.SelectCCFVolumeButtonGroup.SelectedObject.Text,'Annotation')

                switch app.ColorCCFbyButtonGroup.SelectedObject.Text
                    case 'Organ'
                        app.colors = hex2rgb(app.Tcolors{:,'organ_color'});
                    case 'Category'
                        app.colors = hex2rgb(app.Tcolors{:,'category_color'});
                    case 'Division'
                        app.colors = hex2rgb(app.Tcolors{:,'division_color'});
                    case 'Structure'
                        app.colors = hex2rgb(app.Tcolors{:,'structure_color'});
                    case 'Substructure'
                        app.colors = hex2rgb(app.Tcolors{:,'substructure_color'});
                end

            else

                app.colors = gray(517);

            end

        end

        function updateCCFvol(app)

            switch app.SelectCCFVolumeButtonGroup.SelectedObject.Text
                case 'Annotation'
                    X = app.anno(:,:,app.zCCF);
                    C = unique(X);
                    cmap = app.colors(ismember(app.Tcolors.parcellation_index,C),:);
                    d = dictionary(C',1:length(C));
                    X = d(X);
                    RGB = ind2rgb(X,cmap);
                    h = imshow(RGB,Parent=app.CCFaxes);
                    hold(app.CCFaxes,"on")
                    i = imshow(app.anno_bound(:,:,app.zCCF),Parent=app.CCFaxes,Colormap=flipud(gray(517)));
                    i.AlphaData = imbinarize(app.anno_bound(:,:,app.zCCF));
                    hold(app.CCFaxes,"off")
                    set(i,'ButtonDownFcn',@app.clickCCFCallback)
                case 'Annotation Boundary'
                    h = imshow(app.anno_bound(:,:,app.zCCF),Parent=app.CCFaxes,Colormap=app.colors);
                    set(h,'ButtonDownFcn',@app.clickCCFCallback)
                case 'Average Template'
                    h = imshow(app.avg_temp(:,:,app.zCCF),Parent=app.CCFaxes,Colormap=app.colors,DisplayRange=[0,516]);
                    set(h,'ButtonDownFcn',@app.clickCCFCallback)
            end


        end

        function clickCCFCallback(app,~,hit)
            x = uint16(hit.IntersectionPoint(2));
            y = uint16(hit.IntersectionPoint(1));
            hold(app.CCFaxes,"on")
            delete(app.CCFmarker)
            app.CCFmarker = scatter(app.CCFaxes,y,x,40,"r+");
            hold(app.CCFaxes,"off")
            parc_ind = app.anno(x,y,app.zCCF);
            rows = (app.Tterms.parcellation_index == parc_ind);
            row = app.Tterms(rows,:);
            app.ttOrgan = row.organ{1};
            app.ttCategory = row.category{1};
            app.ttDivision = row.division{1};
            app.ttStructure = row.structure{1};
            app.ttSubstructure = row.substructure{1};
            app.updateCCFtt()
        end
        
        function updateCCFtt(app)
            formatSpec = "Organ: %s | Category: %s | Division: %s | Structure: %s | Substructure: %s";
            str = sprintf(formatSpec,app.ttOrgan,app.ttCategory, ...
                app.ttDivision,app.ttStructure,app.ttSubstructure);
            app.CCFtt.Value = str;
        end

        function calibrateZSlider(app, NameValueArgs)

            arguments
                app
                NameValueArgs.init
            end
            
            if NameValueArgs.init == false
                oldNumZ = app.numZ;
            end

            app.numZ = uint16(size(app.anno,3));
            app.zSlider.Limits = [double(1) double(app.numZ)];
            app.zSlider.MajorTicks = [1 app.numZ];
            app.zSlider.MajorTickLabels = {'A' 'P'};
            
            if NameValueArgs.init == true
                app.zCCF = uint16(median(1:app.numZ));
            else
                oldZNorm = double(app.zCCF) / double(oldNumZ);
                app.zCCF = uint16(oldZNorm * app.numZ);
            end

            app.zSlider.Value = double(app.zCCF);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            movegui(app.UIFigure,'north')

            app.abcDir = params('abcDir');
            app.imgDir = fullfile(app.abcDir,'image_volumes', ...
                'Allen-CCF-2020','20230630');
            app.colorDir = fullfile(app.abcDir,'metadata', ...
                'Allen-CCF-2020','20230630','views');

            app.ccfColors = fullfile(app.colorDir, ...
                'parcellation_to_parcellation_term_membership_color.csv');
            app.ccfTerms = fullfile(app.colorDir, ...
                'parcellation_to_parcellation_term_membership_acronym.csv');

            app.Tcolors = readtable(app.ccfColors);
            app.Tterms = readtable(app.ccfTerms);

            app.setCCFcolormap()
            axis(app.CCFaxes,"off")
            axis(app.Histoaxes,"off")

            app.loadCCFvol()
            app.calibrateZSlider(init=true)

            app.CCFmarker = [];

            app.updateCCFvol()


        end

        % Selection changed function: ColorCCFbyButtonGroup
        function ColorCCFbyButtonGroupSelectionChanged(app, event)
           app.setCCFcolormap()     
           app.updateCCFvol()
        end

        % Selection changed function: SelectCCFVolumeButtonGroup
        function SelectCCFVolumeButtonGroupSelectionChanged(app, event)
            app.setCCFcolormap()
            app.updateCCFvol()
        end

        % Value changed function: zSlider
        function zSliderValueChanged(app, event)
            app.zCCF = uint16(app.zSlider.Value);
            app.updateCCFvol()
        end

        % Button pushed function: incPButton
        function incPButtonPushed(app, event)
            if app.zCCF < app.numZ
                app.zCCF = app.zCCF + 1;
                app.zSlider.Value = double(app.zCCF);
                app.updateCCFvol();
            end
            
        end

        % Button pushed function: incAButton
        function incAButtonPushed(app, event)
            if app.zCCF > 1
                app.zCCF = app.zCCF - 1;
                app.zSlider.Value = double(app.zCCF);
                app.updateCCFvol();
            end
            
        end

        % Button pushed function: incPButtonFast
        function incPButtonFastPushed(app, event)
            if app.zCCF < (app.numZ - 10)
                app.zCCF = app.zCCF + 10;
                app.zSlider.Value = double(app.zCCF);
                app.updateCCFvol();
            else
                app.zCCF = app.numZ;
                app.zSlider.Value = double(app.zCCF);
                app.updateCCFvol();
            end
            
        end

        % Button pushed function: incAButtonFast
        function incAButtonFastPushed(app, event)
            if app.zCCF >= 10
                app.zCCF = app.zCCF - 10;
                app.zSlider.Value = double(app.zCCF);
                app.updateCCFvol();
            else
                app.zCCF = 1;
                app.zSlider.Value = double(app.zCCF);
                app.updateCCFvol();
            end    
        end

        % Button pushed function: UpdateCCFVolumeButton
        function UpdateCCFVolumeButtonPushed(app, event)
            
            app.anno = app.anno_orig;
            app.anno_bound = app.anno_bound_orig;
            app.avg_temp = app.avg_temp_orig;

            if app.PitchSpinner.Value ~= 0
                app.anno = imrotate3(app.anno,app.PitchSpinner.Value,[1 0 0],"nearest");
                app.anno_bound = imrotate3(app.anno_bound,app.PitchSpinner.Value,[1 0 0],"nearest");
                app.avg_temp = imrotate3(app.avg_temp,app.PitchSpinner.Value,[1 0 0],"nearest");
            end
            
            if app.YawSpinner.Value ~= 0
                app.anno = imrotate3(app.anno,-app.YawSpinner.Value,[0 1 0],"nearest");
                app.anno_bound = imrotate3(app.anno_bound,-app.YawSpinner.Value,[0 1 0],"nearest");
                app.avg_temp = imrotate3(app.avg_temp,-app.YawSpinner.Value,[0 1 0],"nearest");
            end
            
            app.calibrateZSlider(init=false)
            app.updateCCFvol();
        end

        % Button pushed function: LoadSampleImageButton
        function LoadSampleImageButtonPushed(app, event)
            file = uigetfile('','Select tiff file');
            disp(file)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AllenCCFVolumePanel = uipanel(app.UIFigure);
            app.AllenCCFVolumePanel.Title = 'Allen CCF Volume';
            app.CCFaxes = uiaxes(app.AllenCCFVolumePanel);
            app.CCFaxes.XTick = [];
            app.CCFaxes.YTick = [];
            app.CCFaxes.ZTick = [];
            app.zSlider = uislider(app.AllenCCFVolumePanel);
            app.zSlider.MajorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.zSlider.MajorTickLabels = {'0', '4', '8', '12', '16', '20', '24', '28', '32', '36', '40', '44', '48', '52', '56', '60', '64', '68', '72', '76', '80', '84', '88', '92', '96', '100'};
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @zSliderValueChanged, true);
            app.incPButton = uibutton(app.AllenCCFVolumePanel, 'push');
            app.incPButton.ButtonPushedFcn = createCallbackFcn(app, @incPButtonPushed, true);
            app.incPButton.Icon = fullfile(pathToMLAPP, 'assets', 'right_arrow.png');
            app.incPButton.Text = '';
            app.incAButton = uibutton(app.AllenCCFVolumePanel, 'push');
            app.incAButton.ButtonPushedFcn = createCallbackFcn(app, @incAButtonPushed, true);
            app.incAButton.Icon = fullfile(pathToMLAPP, 'assets', 'left_arrow.png');
            app.incAButton.Text = '';
            app.incAButtonFast = uibutton(app.AllenCCFVolumePanel, 'push');
            app.incAButtonFast.ButtonPushedFcn = createCallbackFcn(app, @incAButtonFastPushed, true);
            app.incAButtonFast.Icon = fullfile(pathToMLAPP, 'assets', 'double_left_arrow.png');
            app.incAButtonFast.Text = '';
            app.incPButtonFast = uibutton(app.AllenCCFVolumePanel, 'push');
            app.incPButtonFast.ButtonPushedFcn = createCallbackFcn(app, @incPButtonFastPushed, true);
            app.incPButtonFast.Icon = fullfile(pathToMLAPP, 'assets', 'double_right_arrow.png');
            app.incPButtonFast.Text = '';
            app.ColorCCFbyButtonGroup = uibuttongroup(app.UIFigure);
            app.ColorCCFbyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorCCFbyButtonGroupSelectionChanged, true);
            app.ColorCCFbyButtonGroup.Title = 'Color CCF by:';
            app.OrganButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.OrganButton.Text = 'Organ';
            app.CategoryButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.CategoryButton.Text = 'Category';
            app.DivisionButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.DivisionButton.Text = 'Division';
            app.StructureButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.StructureButton.Text = 'Structure';
            app.StructureButton.Value = true;
            app.SubstructureButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.SubstructureButton.Text = 'Substructure';
            app.SelectCCFVolumeButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectCCFVolumeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectCCFVolumeButtonGroupSelectionChanged, true);
            app.SelectCCFVolumeButtonGroup.Title = 'Select CCF Volume:';
            app.AnnotationButton = uiradiobutton(app.SelectCCFVolumeButtonGroup);
            app.AnnotationButton.Text = 'Annotation';
            app.AnnotationButton.Value = true;
            app.AnnotationBoundaryButton = uiradiobutton(app.SelectCCFVolumeButtonGroup);
            app.AnnotationBoundaryButton.Text = 'Annotation Boundary';
            app.AverageTemplateButton = uiradiobutton(app.SelectCCFVolumeButtonGroup);
            app.AverageTemplateButton.Text = 'Average Template';
            app.CCFtt = uitextarea(app.UIFigure);
            app.CCFtt.Value = {'Click anywhere in the CCF to view metadata'};
            app.BlockingcutpitchSpinnerLabel = uilabel(app.UIFigure);
            app.BlockingcutpitchSpinnerLabel.Text = 'Blocking cut pitch (°):';
            app.PitchSpinner = uispinner(app.UIFigure);
            app.UpdateCCFVolumeButton = uibutton(app.UIFigure, 'push');
            app.UpdateCCFVolumeButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateCCFVolumeButtonPushed, true);
            app.UpdateCCFVolumeButton.Text = 'Update CCF Volume';
            app.BlockingcutyawLabel = uilabel(app.UIFigure);
            app.BlockingcutyawLabel.Text = 'Blocking cut yaw (°):';
            app.YawSpinner = uispinner(app.UIFigure);
            app.PitchImage = uiimage(app.UIFigure);
            app.PitchImage.ImageSource = fullfile(pathToMLAPP, 'assets', 'pitch rotation graphic.png');
            app.YawImage = uiimage(app.UIFigure);
            app.YawImage.ImageSource = fullfile(pathToMLAPP, 'assets', 'yaw rotation graphic.png');
            app.HistoPanel = uipanel(app.UIFigure);
            app.HistoPanel.Title = 'Sample Image';
            app.Histoaxes = uiaxes(app.HistoPanel);
            app.Histoaxes.XTick = [];
            app.Histoaxes.YTick = [];
            app.Histoaxes.ZTick = [];
            app.LoadSampleImageButton = uibutton(app.UIFigure, 'push');
            app.LoadSampleImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSampleImageButtonPushed, true);
            app.LoadSampleImageButton.Text = 'Load Sample Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = block_cut

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end