classdef injection_checker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PreloadButton               matlab.ui.control.Button
        MinMaxSlider                matlab.ui.control.RangeSlider
        MinMaxSliderLabel           matlab.ui.control.Label
        SampleImagesListBox         matlab.ui.control.ListBox
        SampleImagesListBoxLabel    matlab.ui.control.Label
        SettingsPanel               matlab.ui.container.Panel
        OverlayAnnoCheckBox         matlab.ui.control.CheckBox
        SampleImageParamsPanel      matlab.ui.container.Panel
        SampleImageChannelsListBox  matlab.ui.control.ListBox
        SampleImageChannelsListBoxLabel  matlab.ui.control.Label
        LoadSampleDirectoryButton   matlab.ui.control.Button
        HistoPanel                  matlab.ui.container.Panel
        HistoAxes                   matlab.ui.control.UIAxes
        CCFtt                       matlab.ui.control.TextArea
        SelectCCFVolumeButtonGroup  matlab.ui.container.ButtonGroup
        AverageTemplateButton       matlab.ui.control.RadioButton
        AnnotationBoundaryButton    matlab.ui.control.RadioButton
        AnnotationButton            matlab.ui.control.RadioButton
        ColorCCFbyButtonGroup       matlab.ui.container.ButtonGroup
        SubstructureButton          matlab.ui.control.RadioButton
        StructureButton             matlab.ui.control.RadioButton
        DivisionButton              matlab.ui.control.RadioButton
        CategoryButton              matlab.ui.control.RadioButton
        OrganButton                 matlab.ui.control.RadioButton
        AllenCCFVolumePanel         matlab.ui.container.Panel
        incPButtonFast              matlab.ui.control.Button
        incAButtonFast              matlab.ui.control.Button
        incAButton                  matlab.ui.control.Button
        incPButton                  matlab.ui.control.Button
        zSlider                     matlab.ui.control.Slider
        CCFaxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        abcDir      % directory of abc atlas
        imgDir      % subdirectory of abc atlas for CCF stuff
        colorDir    % subdirectory of abc atlas for color stuff
        expDir      % experiment directory, app input argument

        parent_app  % parent app (HCRprocess)

        ccfNII %
        ccfColors %
        ccfTerms
        
        anno_orig
        anno_bound_orig
        avg_temp_orig

        anno % Allen CCF volume, axes correspond to the following anatomical directions:
             % Default: X (DV), Y (AP), Z (ML)
             % Post-rotations: X (ML), Y (DV), Z (AP)
        anno_bound
        avg_temp

        Tcolors % Table that stores hexadecimal color codes of every parcellation index
                % across all parcellation hierarchy levels
        Tterms

        colors
        
        numZ    % total number of z (AP) planes in CCF volume
        zCCF    % current z (AP) plane in CCF volume

        ttOrgan
        ttCategory
        ttDivision
        ttStructure
        ttSubstructure

        CCFmarker
        CCF_im
        CCF_im_overlay

        im % sample image
        ims % store sample images (if preloaded)
        im_info % sample image metadata
        im_num_chans % number of sample image channels
        im_chan % currently selected sample image channel

        HistoIm % histology image object

        IsPanning logical = false
        PanAnchor double = [0 0]
        HistoCross
        HistoCrossPos

        preloaded logical = false

    end
    
    methods (Access = private)

        function loadCCFvol(app)

            function vol = rotateVol(vol)
                vol = imrotate3(vol,-90,[0 0 1]);
                vol = imrotate3(vol,-90,[0 1 0]);
            end
            
            app.anno = niftiread(fullfile(app.imgDir,'annotation_10.nii'));
            app.anno_bound = niftiread(fullfile(app.imgDir,'annotation_boundary_10.nii'));
            app.avg_temp = niftiread(fullfile(app.imgDir,'average_template_10.nii'));

            app.anno = rotateVol(app.anno);
            app.anno_bound = rotateVol(app.anno_bound);
            app.avg_temp = rotateVol(app.avg_temp);

            app.anno_orig = app.anno;
            app.anno_bound_orig = app.anno_bound;
            app.avg_temp_orig = app.avg_temp;
            
        end
        
        function setCCFcolormap(app)
            
            if strcmp(app.SelectCCFVolumeButtonGroup.SelectedObject.Text,'Annotation')

                switch app.ColorCCFbyButtonGroup.SelectedObject.Text
                    case 'Organ'
                        app.colors = hex2rgb(app.Tcolors{:,'organ_color'});
                    case 'Category'
                        app.colors = hex2rgb(app.Tcolors{:,'category_color'});
                    case 'Division'
                        app.colors = hex2rgb(app.Tcolors{:,'division_color'});
                    case 'Structure'
                        app.colors = hex2rgb(app.Tcolors{:,'structure_color'});
                    case 'Substructure'
                        app.colors = hex2rgb(app.Tcolors{:,'substructure_color'});
                end

            else

                app.colors = gray(517);

            end

        end

        function updateCCFvol(app)

            X = app.anno(:,:,app.zCCF);
            AlphaData = zeros(size(X));
            AlphaData(X ~= 0) = 1;

            switch app.SelectCCFVolumeButtonGroup.SelectedObject.Text

                case 'Annotation'

                    C = unique(X);
                    cmap = app.colors(ismember(app.Tcolors.parcellation_index,C),:);
                    d = dictionary(C',1:length(C));
                    X = d(X);
                    RGB = ind2rgb(X,cmap);

                    if app.OverlayAnnoCheckBox.Value == true
                        BW = imbinarize(app.anno_bound(:,:,app.zCCF),0);
                        RGB = imoverlay(RGB,BW,"black");
                    end
                    
                    app.CCF_im = imshow(RGB,Parent=app.CCFaxes);
                    set(app.CCF_im,'ButtonDownFcn',@app.clickCCFCallback)

                case 'Annotation Boundary'

                    app.CCF_im = imshow(app.anno_bound(:,:,app.zCCF),Parent=app.CCFaxes,Colormap=app.colors);
                    set(app.CCF_im,'ButtonDownFcn',@app.clickCCFCallback)

                case 'Average Template'

                    app.CCF_im = imshow(app.avg_temp(:,:,app.zCCF),Parent=app.CCFaxes,Colormap=app.colors,DisplayRange=[0,516]);
                    set(app.CCF_im,'ButtonDownFcn',@app.clickCCFCallback)

            end

            app.CCF_im.AlphaData = AlphaData;

        end

        function clickCCFCallback(app,~,hit)
            x = uint16(hit.IntersectionPoint(2));
            y = uint16(hit.IntersectionPoint(1));
            hold(app.CCFaxes,"on")
            delete(app.CCFmarker)
            app.CCFmarker = scatter(app.CCFaxes,y,x,40,"r+");
            hold(app.CCFaxes,"off")
            parc_ind = app.anno(x,y,app.zCCF);
            rows = (app.Tterms.parcellation_index == parc_ind);
            row = app.Tterms(rows,:);
            app.ttOrgan = row.organ{1};
            app.ttCategory = row.category{1};
            app.ttDivision = row.division{1};
            app.ttStructure = row.structure{1};
            app.ttSubstructure = row.substructure{1};
            app.updateCCFtt()
        end
        
        function updateCCFtt(app)
            formatSpec = "Organ: %s | Category: %s | Division: %s | Structure: %s | Substructure: %s";
            str = sprintf(formatSpec,app.ttOrgan,app.ttCategory, ...
                app.ttDivision,app.ttStructure,app.ttSubstructure);
            app.CCFtt.Value = str;
        end

        function loadSampleDirectory(app)

            title = 'Select directory containing only tifs to be assessed';
            app.expDir = uigetdir(pwd,title);

            listing = fetch_dir(app.expDir,'file');
            listing = filt_kw(listing,{'.tif','.tiff'},'keep');

            app.SampleImagesListBox.Items = listing;
            app.SampleImagesListBox.Value = app.SampleImagesListBox.Items{1};
            app.loadSampleImage(fullfile(app.expDir,app.SampleImagesListBox.Value))

        end

        function loadSampleImage(app, filepath)

            [im_local, im_info_local] = read_tiff(filepath);
            
            % evil, long if-else tree for taking in any kind of input image
            if ismatrix(im_local) % 2D image, 1 chan, 1 plane, easy

                im_num_chans_local = 1;

            elseif ~isempty(im_info_local)

                [chans, ~, ~, data_found_dict] = parse_im_info(filepath);

                if data_found_dict('chans_found') == true
                    im_num_chans_local = chans; 
                else
                    im_num_chans_local = 1;
                end
                
                if im_num_chans_local == 1 % third dimension is planes, not channels
                    im_local = im_local(:,:,round(size(im_local,3)/2));
                end

            end
            
            Items = {};
            for i = 1:app.im_num_chans
                Items{end+1} = append('Channel',' ',string(i)); 
            end
            Items = cellstr(Items);
            app.SampleImageChannelsListBox.Items = Items;
            app.SampleImageChannelsListBox.Value = 'Channel 1';
            app.im_chan = 1;

            app.updateSampleImage()

        end

        function calibrateZSlider(app, NameValueArgs)

            arguments
                app
                NameValueArgs.init
            end
            
            if NameValueArgs.init == false
                oldNumZ = app.numZ;
            end

            app.numZ = uint16(size(app.anno,3));
            app.zSlider.Limits = [double(1) double(app.numZ)];
            app.zSlider.MajorTicks = [1 app.numZ];
            app.zSlider.MajorTickLabels = {'A' 'P'};
            
            if NameValueArgs.init == true
                app.zCCF = uint16(median(1:app.numZ));
            else
                oldZNorm = double(app.zCCF) / double(oldNumZ);
                app.zCCF = uint16(oldZNorm * app.numZ);
            end

            app.zSlider.Value = double(app.zCCF);

        end

        function calibrateCCFOverlayOpacitySlider(app)

            app.CCFOverlayOpacitySlider.Limits = [0 1];
            app.CCFOverlayOpacitySlider.MajorTicks = [0 0.25 0.5 0.75 1];
            app.CCFOverlayOpacitySlider.MajorTickLabels = {};
            app.CCFOverlayOpacitySlider.MinorTicks = [];
            app.CCFOverlayOpacitySlider.Value = 1;

        end

        function updateSampleImage(app)

            if ~isempty(app.HistoCross) && isvalid(app.HistoCross)
                app.HistoIm = imshow(app.im(:,:,app.im_chan),Parent=app.HistoAxes,InitialMagnification="fit");
                app.redrawCross()
            else
                app.HistoIm = imshow(app.im(:,:,app.im_chan),Parent=app.HistoAxes,InitialMagnification="fit");
            end
            
            minVal = min(app.im(:,:,app.im_chan),[],"all");
            maxVal = max(app.im(:,:,app.im_chan),[],"all");
            app.MinMaxSlider.Limits = [double(minVal) double(maxVal)];
            app.autoSetLims()

        end
    
        function createCCFOverlay(app)

            app.CCF_im_overlay = imshow(app.CCF_im.CData,Parent=app.CCFOverlayAxes);
            app.CCF_im_overlay.CDataMapping = 'direct';
            app.CCF_im_overlay.Clipping = 'off';

        end

        function updateCCFOverlay(app)

            old_xlim = app.CCFOverlayAxes.XLim;
            old_ylim = app.CCFOverlayAxes.YLim;
            
            app.CCF_im_overlay.CData = imrotate(app.CCF_im.CData,app.RotationSpinner.Value);

            % make colormap yellow for annotation boundary overlay and gray
            % otherwise
            switch app.SelectCCFVolumeButtonGroup.SelectedObject.Text
                case 'Annotation'
                    app.CCFOverlayAxes.Colormap = app.colors;
                case 'Annotation Boundary'
                    c = gray(256);
                    c(:,3) = 0;
                    app.CCFOverlayAxes.Colormap = c;
                case 'Average Template'
                    app.CCFOverlayAxes.Colormap = gray(256);
            end

            AlphaData = imrotate(app.CCF_im.AlphaData,app.RotationSpinner.Value);
            AlphaData(AlphaData ~= 0) = app.CCFOverlayOpacitySlider.Value;
            if strcmp(app.SelectCCFVolumeButtonGroup.SelectedObject.Text,'Annotation Boundary')
                AlphaData(app.CCF_im_overlay.CData == 0) = 0;
            end
            app.CCF_im_overlay.AlphaData = AlphaData;
            
            xPix = size(AlphaData,2);
            yPix = size(AlphaData,1);

            % xlim(app.CCFOverlayAxes,'manual')
            % ylim(app.CCFOverlayAxes,'manual')

            % app.CCFOverlayAxes.XLim = [0, xPix];
            % app.CCFOverlayAxes.YLim = [0, yPix];
            
            xAdj = (xPix * app.XZoomSpinner.Value) - xPix;
            xAdj = round(xAdj / 2);
            yAdj = (yPix * app.YZoomSpinner.Value) - yPix;
            yAdj = round(yAdj / 2);
            app.CCF_im_overlay.XData = [-xAdj, xPix + xAdj];
            app.CCF_im_overlay.YData = [-yAdj, yPix + yAdj];

            app.CCFOverlayAxes.XLim = old_xlim;
            app.CCFOverlayAxes.YLim = old_ylim;

            pan(app.CCFOverlayAxes,'on')

        end

        function autoSetLims(app)

            P = prctile(app.im(:,:,app.im_chan),[0.1, 99.9],"all");
            app.MinMaxSlider.Value = double(P');
            app.HistoAxes.CLim = P;

        end

        function inAxes = isMouseOverAxes(app, ax)

            mousePos = app.UIFigure.CurrentPoint;     % [x y] in pixels, from bottom-left
        
            axPos = getpixelposition(ax, true);       % true = isrecursive (relative to figure) [left bottom width height]
            inAxes = mousePos(1) >= axPos(1) && ...
                     mousePos(1) <= axPos(1) + axPos(3) && ...  % inside x
                     mousePos(2) >= axPos(2) && ...
                     mousePos(2) <= axPos(2) + axPos(4);  % inside y
        
        end

        function drawCross(app, x, y)

            ax = app.HistoAxes;

            % Remove previous cross if any
            if ~isempty(app.HistoCross) && all(isvalid(app.HistoCross))
                delete(app.HistoCross);
            end
        
            hold(ax, 'on');
            app.HistoCrossPos = [x, y];
            app.HistoCross = plot(ax, x, y, 'r+', ...
                'MarkerSize', 12, 'LineWidth', 1.5, 'HitTest', 'off');
            hold(ax, 'off');
            
        end

        function redrawCross(app)

            ax = app.HistoAxes;

            hold(ax, 'on');
            x = app.HistoCrossPos(1);
            y = app.HistoCrossPos(2);
            app.HistoCross = plot(ax, x, y, 'r+', ...
                'MarkerSize', 12, 'LineWidth', 1.5, 'HitTest', 'off');
            hold(ax, 'off');

        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            
            [filepath,~,~] = fileparts(mfilename('fullpath'));
            cd(filepath)
            addpath(['./' 'utils'])

            movegui(app.UIFigure,'north')

            app.abcDir = params('abcDir');
            app.imgDir = fullfile(app.abcDir,'image_volumes', ...
                'Allen-CCF-2020','20230630');
            app.colorDir = fullfile(app.abcDir,'metadata', ...
                'Allen-CCF-2020','20230630','views');

            app.ccfColors = fullfile(app.colorDir, ...
                'parcellation_to_parcellation_term_membership_color.csv');
            app.ccfTerms = fullfile(app.colorDir, ...
                'parcellation_to_parcellation_term_membership_acronym.csv');

            app.Tcolors = readtable(app.ccfColors);
            app.Tterms = readtable(app.ccfTerms);

            app.setCCFcolormap()

            axis(app.CCFaxes,"off")
            axis(app.HistoAxes,"off")
            axis(app.CCFaxes,"image")
            axis(app.HistoAxes,"image")
            disableDefaultInteractivity(app.CCFaxes)
            disableDefaultInteractivity(app.HistoAxes)

            app.loadCCFvol()
            app.calibrateZSlider(init=true)

            app.CCFmarker = [];

            app.updateCCFvol()

        end

        % Selection changed function: ColorCCFbyButtonGroup
        function ColorCCFbyButtonGroupSelectionChanged(app, event)
           app.setCCFcolormap()     
           app.updateCCFvol()
        end

        % Selection changed function: SelectCCFVolumeButtonGroup
        function SelectCCFVolumeButtonGroupSelectionChanged(app, event)
            app.setCCFcolormap()
            app.updateCCFvol()
        end

        % Value changed function: zSlider
        function zSliderValueChanged(app, event)
            app.zCCF = uint16(app.zSlider.Value);
            app.updateCCFvol()
        end

        % Button pushed function: incPButton
        function incPButtonPushed(app, event)
            if app.zCCF < app.numZ
                app.zCCF = app.zCCF + 1;
                app.zSlider.Value = double(app.zCCF);
                app.updateCCFvol();
            end
            
        end

        % Button pushed function: incAButton
        function incAButtonPushed(app, event)
            if app.zCCF > 1
                app.zCCF = app.zCCF - 1;
                app.zSlider.Value = double(app.zCCF);
                app.updateCCFvol();
            end
            
        end

        % Button pushed function: incPButtonFast
        function incPButtonFastPushed(app, event)
            if app.zCCF < (app.numZ - 10)
                app.zCCF = app.zCCF + 10;
                app.zSlider.Value = double(app.zCCF);
                app.updateCCFvol();
            else
                app.zCCF = app.numZ;
                app.zSlider.Value = double(app.zCCF);
                app.updateCCFvol();
            end
            
        end

        % Button pushed function: incAButtonFast
        function incAButtonFastPushed(app, event)
            if app.zCCF >= 10
                app.zCCF = app.zCCF - 10;
                app.zSlider.Value = double(app.zCCF);
                app.updateCCFvol();
            else
                app.zCCF = 1;
                app.zSlider.Value = double(app.zCCF);
                app.updateCCFvol();
            end    
        end

        % Callback function
        function UpdateCCFVolumeButtonPushed(app, event)
            
            app.anno = app.anno_orig;
            app.anno_bound = app.anno_bound_orig;
            app.avg_temp = app.avg_temp_orig;

            if app.PitchSpinner.Value ~= 0
                app.anno = imrotate3(app.anno,-app.PitchSpinner.Value,[1 0 0],"nearest");
                app.anno_bound = imrotate3(app.anno_bound,-app.PitchSpinner.Value,[1 0 0],"nearest");
                app.avg_temp = imrotate3(app.avg_temp,-app.PitchSpinner.Value,[1 0 0],"nearest");
            end
            
            if app.YawSpinner.Value ~= 0
                app.anno = imrotate3(app.anno,-app.YawSpinner.Value,[0 1 0],"nearest");
                app.anno_bound = imrotate3(app.anno_bound,-app.YawSpinner.Value,[0 1 0],"nearest");
                app.avg_temp = imrotate3(app.avg_temp,-app.YawSpinner.Value,[0 1 0],"nearest");
            end
            
            app.calibrateZSlider(init=false)
            app.updateCCFvol();
        end

        % Button pushed function: LoadSampleDirectoryButton
        function LoadSampleDirectoryButtonPushed(app, event)
            app.loadSampleDirectory()
        end

        % Value changed function: SampleImageChannelsListBox
        function SampleImageChannelsListBoxValueChanged(app, event)
           
            value = app.SampleImageChannelsListBox.Value;

            app.im_chan = str2num(value(end));
            app.updateSampleImage()

        end

        % Callback function: not associated with a component
        function AutosetlimitsCheckBoxValueChanged(app, event)
            value = app.AutosetlimitsCheckBox.Value;
            app.autoSetLims(value)
        end

        % Value changed function: OverlayAnnoCheckBox
        function OverlayAnnoCheckBoxValueChanged(app, event)
            app.updateCCFvol()
        end

        % Value changed function: SampleImagesListBox
        function SampleImagesListBoxValueChanged(app, event)
            switch app.preloaded
                case 0
                    app.loadSampleImage(fullfile(app.expDir,app.SampleImagesListBox.Value))
                case 1
            end
            
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            
            ax = app.HistoAxes;
            inAxes = app.isMouseOverAxes(ax);

            if ~inAxes
                return;   % mouse is not over this axes — do nothing
            end
        
            % --- Zoom logic ---
            xlim   = get(ax, 'XLim');
            ylim   = get(ax, 'YLim');
            cp     = ax.CurrentPoint;
            mouseX = cp(1,1);
            mouseY = cp(1,2);
        
            zoomFactor = 1.15;
            if event.VerticalScrollCount < 0
                scale = 1 / zoomFactor;   % zoom in
            else
                scale = zoomFactor;        % zoom out
            end

            newXLim = mouseX + (xlim - mouseX) * scale;
            newYLim = mouseY + (ylim - mouseY) * scale;

            imgSize = size(app.HistoIm.CData); % [h w]
            newXLim = max(0.5, min(imgSize(2)+0.5, newXLim));
            newYLim = max(0.5, min(imgSize(1)+0.5, newYLim));

            set(ax, 'XLim', newXLim, ...
                    'YLim', newYLim);
        
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            
            inAxes = app.isMouseOverAxes(app.HistoAxes);
            if ~inAxes
                return;   % mouse is not over this axes — do nothing
            end

            cp = app.HistoAxes.CurrentPoint;
            x = cp(1,1);
            y = cp(1,2);

            switch app.UIFigure.SelectionType
                case 'normal'
                    app.PanAnchor  = [x, y];
                    app.IsPanning  = true;
                case 'alt'
                    app.drawCross(x, y);
            end

        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            app.IsPanning = false;
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            
            if ~app.IsPanning
                return; 
            end

            cp = app.HistoAxes.CurrentPoint;
            dx = app.PanAnchor(1) - cp(1,1);
            dy = app.PanAnchor(2) - cp(1,2);
            app.HistoAxes.XLim = app.HistoAxes.XLim + dx;
            app.HistoAxes.YLim = app.HistoAxes.YLim + dy;

        end

        % Value changed function: MinMaxSlider
        function MinMaxSliderValueChanged(app, event)
            app.HistoAxes.CLim = app.MinMaxSlider.Value;
        end

        % Button pushed function: PreloadButton
        function PreloadButtonPushed(app, event)
            
            if isempty(app.expDir)
                return
            end

            for i=1:length(app.SampleImagesListBox.Values)

            end
            
            app.preloaded = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.AllenCCFVolumePanel = uipanel(app.UIFigure);
            app.AllenCCFVolumePanel.Title = 'Allen CCF Volume';
            app.CCFaxes = uiaxes(app.AllenCCFVolumePanel);
            app.CCFaxes.XTick = [];
            app.CCFaxes.YTick = [];
            app.CCFaxes.ZTick = [];
            app.zSlider = uislider(app.AllenCCFVolumePanel);
            app.zSlider.MajorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            app.zSlider.MajorTickLabels = {'0', '4', '8', '12', '16', '20', '24', '28', '32', '36', '40', '44', '48', '52', '56', '60', '64', '68', '72', '76', '80', '84', '88', '92', '96', '100'};
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @zSliderValueChanged, true);
            app.incPButton = uibutton(app.AllenCCFVolumePanel, 'push');
            app.incPButton.ButtonPushedFcn = createCallbackFcn(app, @incPButtonPushed, true);
            app.incPButton.Icon = fullfile(pathToMLAPP, 'assets', 'right_arrow.png');
            app.incPButton.Text = '';
            app.incAButton = uibutton(app.AllenCCFVolumePanel, 'push');
            app.incAButton.ButtonPushedFcn = createCallbackFcn(app, @incAButtonPushed, true);
            app.incAButton.Icon = fullfile(pathToMLAPP, 'assets', 'left_arrow.png');
            app.incAButton.Text = '';
            app.incAButtonFast = uibutton(app.AllenCCFVolumePanel, 'push');
            app.incAButtonFast.ButtonPushedFcn = createCallbackFcn(app, @incAButtonFastPushed, true);
            app.incAButtonFast.Icon = fullfile(pathToMLAPP, 'assets', 'double_left_arrow.png');
            app.incAButtonFast.Text = '';
            app.incPButtonFast = uibutton(app.AllenCCFVolumePanel, 'push');
            app.incPButtonFast.ButtonPushedFcn = createCallbackFcn(app, @incPButtonFastPushed, true);
            app.incPButtonFast.Icon = fullfile(pathToMLAPP, 'assets', 'double_right_arrow.png');
            app.incPButtonFast.Text = '';
            app.ColorCCFbyButtonGroup = uibuttongroup(app.UIFigure);
            app.ColorCCFbyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorCCFbyButtonGroupSelectionChanged, true);
            app.ColorCCFbyButtonGroup.Title = 'Color CCF by:';
            app.OrganButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.OrganButton.Text = 'Organ';
            app.CategoryButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.CategoryButton.Text = 'Category';
            app.DivisionButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.DivisionButton.Text = 'Division';
            app.StructureButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.StructureButton.Text = 'Structure';
            app.StructureButton.Value = true;
            app.SubstructureButton = uiradiobutton(app.ColorCCFbyButtonGroup);
            app.SubstructureButton.Text = 'Substructure';
            app.SelectCCFVolumeButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectCCFVolumeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectCCFVolumeButtonGroupSelectionChanged, true);
            app.SelectCCFVolumeButtonGroup.Title = 'Select CCF Volume:';
            app.AnnotationButton = uiradiobutton(app.SelectCCFVolumeButtonGroup);
            app.AnnotationButton.Text = 'Annotation';
            app.AnnotationButton.Value = true;
            app.AnnotationBoundaryButton = uiradiobutton(app.SelectCCFVolumeButtonGroup);
            app.AnnotationBoundaryButton.Text = 'Annotation Boundary';
            app.AverageTemplateButton = uiradiobutton(app.SelectCCFVolumeButtonGroup);
            app.AverageTemplateButton.Text = 'Average Template';
            app.CCFtt = uitextarea(app.UIFigure);
            app.CCFtt.Value = {'Click anywhere in the CCF to view metadata'};
            app.HistoPanel = uipanel(app.UIFigure);
            app.HistoPanel.Title = 'Sample Image';
            app.HistoAxes = uiaxes(app.HistoPanel);
            app.HistoAxes.XTick = [];
            app.HistoAxes.YTick = [];
            app.HistoAxes.ZTick = [];
            app.LoadSampleDirectoryButton = uibutton(app.UIFigure, 'push');
            app.LoadSampleDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSampleDirectoryButtonPushed, true);
            app.LoadSampleDirectoryButton.Text = 'Load Sample Directory';
            app.SampleImageChannelsListBoxLabel = uilabel(app.UIFigure);
            app.SampleImageChannelsListBoxLabel.Text = {'Sample Image'; 'Channels'};
            app.SampleImageChannelsListBox = uilistbox(app.UIFigure);
            app.SampleImageChannelsListBox.Items = {};
            app.SampleImageChannelsListBox.ValueChangedFcn = createCallbackFcn(app, @SampleImageChannelsListBoxValueChanged, true);
            app.SampleImageChannelsListBox.Value = {};
            app.SampleImageParamsPanel = uipanel(app.UIFigure);
            app.SampleImageParamsPanel.Title = 'Sample Image Display Parameters';
            app.SettingsPanel = uipanel(app.UIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.OverlayAnnoCheckBox = uicheckbox(app.SettingsPanel);
            app.OverlayAnnoCheckBox.ValueChangedFcn = createCallbackFcn(app, @OverlayAnnoCheckBoxValueChanged, true);
            app.OverlayAnnoCheckBox.Text = {'Overlay annotation boundaries'; 'on annotation volume?'};
            app.OverlayAnnoCheckBox.Value = true;
            app.SampleImagesListBoxLabel = uilabel(app.UIFigure);
            app.SampleImagesListBoxLabel.Text = 'Sample Images';
            app.SampleImagesListBox = uilistbox(app.UIFigure);
            app.SampleImagesListBox.Items = {};
            app.SampleImagesListBox.ValueChangedFcn = createCallbackFcn(app, @SampleImagesListBoxValueChanged, true);
            app.SampleImagesListBox.Value = {};
            app.MinMaxSliderLabel = uilabel(app.UIFigure);
            app.MinMaxSliderLabel.Text = 'Min/Max';
            app.MinMaxSlider = uislider(app.UIFigure, 'range');
            app.MinMaxSlider.ValueChangedFcn = createCallbackFcn(app, @MinMaxSliderValueChanged, true);
            app.PreloadButton = uibutton(app.UIFigure, 'push');
            app.PreloadButton.ButtonPushedFcn = createCallbackFcn(app, @PreloadButtonPushed, true);
            app.PreloadButton.Text = {'Preload all'; 'sample images'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = injection_checker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end