classdef manual_masks < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        OverlaysPanel                  matlab.ui.container.Panel
        GridLineThicknessSpinner       matlab.ui.control.Spinner
        GridLineThicknessSpinnerLabel  matlab.ui.control.Label
        UpdateImageButton              matlab.ui.control.Button
        GridButton                     matlab.ui.control.StateButton
        GridLamp                       matlab.ui.control.Lamp
        CCFButton                      matlab.ui.control.StateButton
        CCFLamp                        matlab.ui.control.Lamp
        MasksButton                    matlab.ui.control.StateButton
        MasksLamp                      matlab.ui.control.Lamp
        ControlsPanel                  matlab.ui.container.Panel
        ShowGridButton                 matlab.ui.control.Button
        GridRectanglesEditField        matlab.ui.control.NumericEditField
        GridRectanglesEditFieldLabel   matlab.ui.control.Label
        SegmentationIterationsEditField  matlab.ui.control.NumericEditField
        SegmentationiterationsEditFieldLabel  matlab.ui.control.Label
        DecrementZButton               matlab.ui.control.Button
        IncrementZButton               matlab.ui.control.Button
        ShowMasksButton                matlab.ui.control.Button
        DisplayMasksCheckBox           matlab.ui.control.CheckBox
        MasksTable                     matlab.ui.control.Table
        PreloadRGBZstackButton         matlab.ui.control.Button
        zSlider                        matlab.ui.control.Slider
        zPlaneLabel                    matlab.ui.control.Label
        zSliderLabel                   matlab.ui.control.Label
        ChansPanel                     matlab.ui.container.Panel
        LoadImButton                   matlab.ui.control.Button
        ImPanel                        matlab.ui.container.Panel
        ImAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        ims_f
        masksIm_f
        labelIms_f
        ccfOverlay_f

        masksIm
        labelIms = {}
        ccfOverlayIm
        ccfOverlayImAlpha
        fullIm
        preloadedRGBIm
        zPlaneIm
        masksVol
        masksVolAlpha
        gridIm

        masksImRGB
        labelImsRGB = {}

        imObj
        masksVolImObj
        gridImObj
        ccfOverlayImObj
        
        numChans
        numLabelIms
        chanStr = {}

        chansGrid
        chansLabelEdit = {}
        chansColorButton = {}
        chansMinMaxLabel = {}
        chansMinMaxSlider = {}
        chansEnableCheckbox = {}
        chansAutoLim = {}

        componentsColor = [0.9 0.9 0.9]
        zPlane = 1

        num_rows
        num_cols
        num_planes

        PanAnchor
        IsPanning = false
        PanAxes
        Preloaded = false
        ROIMode = false
        masksCMap

    end
    
    methods (Access = private)
        
        function [im_local, im_num_chans_local] = loadIm(~, filepath)

            [im_local, im_info_local] = read_tiff(filepath);
            
            % evil, long if-else tree for taking in any kind of input image
            if ismatrix(im_local) % 2D image, 1 chan, 1 plane, easy

                im_num_chans_local = 1;

            elseif ~isempty(im_info_local)

                [chans, ~, ~, data_found_dict] = parse_im_info(filepath);

                if data_found_dict('chans_found') == true
                    im_num_chans_local = chans; 
                else
                    im_num_chans_local = 1;
                end

                assert(im_num_chans_local == 1, ...
                    'Number of channels in each image must be 1, got %g.', ...
                    im_num_chans_local)

            end
    
        end

        function createChannelControls(app)
        
            % Outer grid: one column per channel, all equal width, spans full panel
            app.chansGrid = uigridlayout(app.ChansPanel, [1, app.numChans]);
            app.chansGrid.ColumnWidth = repmat({'1x'}, 1, app.numChans);
            app.chansGrid.RowHeight = {'1x'};
            app.chansGrid.Padding = [5 5 5 5];
            app.chansGrid.ColumnSpacing = 2;
        
            for i = 1:app.numChans
                % Nested grid for one channel's stacked controls
                % Rows: label | color button | min row | max row | auto/enable row
                chGrid = uigridlayout(app.chansGrid, [5, 1]);
                chGrid.RowHeight = {'fit', '2x', '1x', '1x', '1x'};
                chGrid.RowSpacing = 3;
                chGrid.Padding = [2 2 2 2];
                chGrid.Layout.Row = 1;
                chGrid.Layout.Column = i;
        
                % Channel label
                app.chansLabelEdit{i} = uieditfield(chGrid, 'text', ...
                    'Value', app.chanStr{i}, ...
                    'BackgroundColor', [1 1 1], ...
                    'HorizontalAlignment', 'center', ...
                    'FontSize', 18, ...
                    'FontWeight', 'bold');
                app.chansLabelEdit{i}.Layout.Row = 1;
        
                % Color swatch button
                app.chansColorButton{i} = uibutton(chGrid, 'push', ...
                    'Text', '', ...
                    'BackgroundColor', rand(1,3), ...
                    'UserData', 'colorButton', ...
                    'ButtonPushedFcn', @(src,evt) app.colorButtonFcn(src, evt, i));
                app.chansColorButton{i}.Layout.Row = 2;
        
                 % Numeric readout of current [min max] (slider ticks alone are coarse)
                lbl = uilabel(chGrid, 'Text', '0 / 255', ...
                    'BackgroundColor', app.componentsColor, ...
                    'HorizontalAlignment', 'center', 'FontSize', 18);
                lbl.Layout.Row = 3;
                app.chansMinMaxLabel{i} = lbl;
        
                % Range slider replaces CminEdit/CmaxEdit
                sld = uislider(chGrid, 'range');
                sld.Limits = [0 255];
                sld.Value = [0 255];
                sld.Enable = 'off';   % matches old 'Enable','Off' until Auto is unchecked
                sld.ValueChangedFcn = @(src,evt) app.minMaxSliderChanged(src, evt, i);
                sld.Layout.Row = 4;
                app.chansMinMaxSlider{i} = sld;
        
                % Bottom row: On checkbox + Auto toggle side by side
                bottomGrid = uigridlayout(chGrid, [1, 2]);
                bottomGrid.ColumnWidth = {'1x', '1x'};
                bottomGrid.Padding = [0 0 0 0];
                bottomGrid.ColumnSpacing = 2;
                bottomGrid.Layout.Row = 5;
        
                app.chansEnableCheckbox{i} = uicheckbox(bottomGrid, ...
                    'Text', 'On', ...
                    'FontSize', 18, ...
                    'Value', true, ...
                    'UserData', 'enableCheckbox', ...
                    'ValueChangedFcn', @(src,evt) app.updateImage(src, evt));

                autoChk = uicheckbox(bottomGrid, 'Text', 'Auto', 'FontSize', 18, ...
                    'Value', true, ...
                    'ValueChangedFcn', @(src,evt) app.enableLims(src, evt, i));
                autoChk.Layout.Column = 2;
                app.chansAutoLim{i} = autoChk;
        
            end

        end

        function colorButtonFcn(app, ~, ~, chan)

            newclr = uisetcolor(app.chansColorButton{chan}.BackgroundColor);
            
            % uisetcolor returns 0 if the user cancels the dialog
            if isequal(newclr, 0)
                return
            end
            
            app.chansColorButton{chan}.BackgroundColor = newclr;
            
            if chan == 1
                app.masksImRGB = app.makeRGBImage(app.masksIm,1);
            else
                app.labelImsRGB{chan-1} = app.makeRGBImage(app.labelIms{chan-1},chan);
            end

            app.updateImage()

        end
        

        function updateZPlaneIm(app)

            app.zPlaneIm = squeeze(app.fullIm(:,:,app.zPlane,:));
  
            for i=1:app.numChans
                Limits = [min(app.zPlaneIm(:,:,i), [], 'all');
                    max(app.zPlaneIm(:,:,i), [], 'all')];
                app.chansMinMaxSlider{i}.Limits = Limits;
            end

        end

        function RGBIm = makeRGBImage(app, im, chan)

            RGBIm = zeros(app.num_rows, app.num_cols, app.num_planes, 3, 'uint8');
            clr = app.chansColorButton{chan}.BackgroundColor;
            clr_uint8 = uint8(round(clr * 255));
            clr_uint8 = reshape(clr_uint8, 1, 1, 3);

            for i=1:app.num_planes

                P = qprctile(im(:,:,i), [0.1 99.995]);
                cmin = P(1);
                cmax = P(2);
                
                if cmax <= cmin
                    cmax = cmin + 1;
                end

                im_scaled = (single(im(:,:,i)) - cmin) ./ (cmax-cmin);
                im_scaled = clip(im_scaled, 0, 1);
                im_rgb = uint8(im_scaled .* single(clr_uint8));
                RGBIm(:,:,i,:) = im_rgb;

            end

        end

        function scaledIm = getScaledImage(app, chan, getLims)
            
            scaledIm = app.zPlaneIm(:,:,chan);

            if getLims
                lims = app.getChanLims(chan, scaledIm);
            else
                lims = app.chansMinMaxSlider{chan}.Value;
            end
            
            scaledIm = (scaledIm - lims(1)) ./ (lims(2)-lims(1));
            scaledIm = clip(scaledIm, 0, 1);

        end

        function lims = getChanLims(app, chan, im)
            
            if get(app.chansAutoLim{chan}, 'Value') == false

                cmin = app.chansMinMaxSlider{chan}.Value(1);
                cmax = app.chansMinMaxSlider{chan}.Value(2);

            else

                P = qprctile(im(:), [0.1 99.995]);
                cmin = P(1);
                cmax = P(2);
                
                if cmax <= cmin
                    cmax = cmin + 1;
                end
                
                app.chansMinMaxSlider{chan}.Value(1) = cmin;
                app.chansMinMaxSlider{chan}.Value(2) = cmax;

            end
            
            lims = [cmin cmax];

        end

        % function updateImage(app, varargin)
        % 
        %     getLims = repelem(true,app.numChans);
        %     if ~isempty(varargin)
        %         if strcmp(varargin{1}.UserData,'enableCheckbox') 
        %             getLims = repelem(false,app.numChans);
        %         elseif strcmp(varargin{1}.UserData,'colorButton') 
        %             getLims = repelem(false,app.numChans);
        %         end
        %     end
        % 
        %     im = zeros(app.num_rows, app.num_cols, 3);
        % 
        %     for i = 1:app.numChans
        % 
        %         if app.chansEnableCheckbox{i}.Value == false
        %             continue;
        %         end
        % 
        %         clr = app.chansColorButton{i}.BackgroundColor;
        %         scim = app.getScaledImage(i, getLims(i));
        % 
        %         for j = 1:3
        %             im(:,:,j) = im(:,:,j) + scim.*clr(j);
        %         end
        % 
        %     end
        % 
        %     app.imObj.CData = im;
        % 
        % end

        function updateImage(app, varargin)
            
            im = zeros(app.num_rows, app.num_cols, 1, 3, 'uint8');

            if app.chansEnableCheckbox{1}.Value
                im = imadd(im, app.masksImRGB(:,:,app.zPlane,:));
            end
            
            for i=1:app.numLabelIms
                if app.chansEnableCheckbox{i+1}.Value
                    im = imadd(im, app.labelImsRGB{i}(:,:,app.zPlane,:));
                end
            end

            if app.MasksButton.Value
                test = 1;
            end

            app.imObj.CData = squeeze(im);

        end

        function updateMasksVol(app)

            set(app.masksVolImObj, 'CData', app.masksVol(:,:,app.zPlane), ...
                            'AlphaData', app.masksVolAlpha(:,:,app.zPlane));

        end

        function updateCCFOverlay(app)

            set(app.ccfOverlayImObj, 'CData', app.ccfOverlayIm(:,:,app.zPlane), ...
                              'AlphaData', app.ccfOverlayImAlpha(:,:,app.zPlane));

        end

        function inAxes = isMouseOverAxes(app, ax)

            mousePos = app.UIFigure.CurrentPoint;     % [x y] in pixels, from bottom-left
        
            axPos = getpixelposition(ax, true);       % true = isrecursive (relative to figure) [left bottom width height]
            inAxes = mousePos(1) >= axPos(1) && ...
                     mousePos(1) <= axPos(1) + axPos(3) && ...  % inside x
                     mousePos(2) >= axPos(2) && ...
                     mousePos(2) <= axPos(2) + axPos(4);  % inside y
        
        end

        function makeMasksColormap(app, targetMask)

            numMasks = height(app.MasksTable.Data);
            highlightColor = [1 0 0];   % red
            otherColor    = [0 0 1];    % blue
            bgColor       = [0 0 0];    % black for label 0 / background

            app.masksCMap = repmat(otherColor, numMasks+1, 1);
            app.masksCMap(1,:) = bgColor; 
            app.masksCMap(targetMask+1,:) = highlightColor; 

        end

        function makeGridIm(app)

            app.gridIm = makeGrid(app.masksVol(:,:,1), ...
                app.GridRectanglesEditField.Value, ...
                app.GridLineThicknessSpinner.Value);
            app.gridImObj.CData = app.gridIm;
            app.gridImObj.AlphaData = (app.gridIm > 0);

        end

        function makeCCFOverlay(app)

            parc_inds = unique(app.ccfOverlayIm);
            parc_inds(parc_inds == 0) = [];
            ccfOverlayBound = zeros(size(app.ccfOverlayIm),'logical');

            for i=1:app.num_planes

                BW = zeros(app.num_rows,app.num_cols,'logical');

                for j=1:length(parc_inds)
                    parc_ind = parc_inds(j);
                    parc_ind_perim = bwperim(app.ccfOverlayIm(:,:,i) == parc_ind);
                    BW = BW | parc_ind_perim;
                end

                BW = bwmorph(BW,"thicken",2);
                BW = bwmorph(BW,"bridge");
                BW = bwmorph(BW,"majority",2);
                ccfOverlayBound(:,:,i) = BW;

            end

            app.ccfOverlayIm = ccfOverlayBound;

        end

        function updateLamp(~, button, lamp)
            switch button.Value
                case true
                    lamp.Color = [0 1 0];
                case false
                    lamp.Color = [1 1 1];
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, test)
            
            [filepath,~,~] = fileparts(mfilename('fullpath'));
            cd(filepath)
            addpath(['./' 'utils'])

            app.UIFigure.WindowState = 'maximized';
       
            axis(app.ImAxes,"off")
            axis(app.ImAxes,"image")
            disableDefaultInteractivity(app.ImAxes)
            app.ImAxes.Toolbar = [];
            hold(app.ImAxes,"on")
            app.imObj = image(app.ImAxes,[]);
            app.masksVolImObj = image(app.ImAxes,[]);
            app.gridImObj = image(app.ImAxes,[]);
            app.ccfOverlayImObj = image(app.ImAxes,[]);
            app.gridImObj.CDataMapping = 'scaled';
            app.ImAxes.YDir = 'reverse';
            
            app.ims_f = {'D:\2026-01-16_MC_SC_17\post\core_output\s01L\r0_NT647.tif';
                'D:\2026-01-16_MC_SC_17\post\core_output\s01L\r0_EGFP488.tif';
                'D:\2026-01-16_MC_SC_17\post\core_output\s01L\r0_dTom561.tif'};
            app.ccfOverlay_f = 'D:\2026-01-16_MC_SC_17\post\CCF_vols\s01L\s01Lannotation.tif';
            app.masksIm_f = app.ims_f{1};
            app.labelIms_f = app.ims_f(2:end);
            

            app.numLabelIms = length(app.labelIms_f);
            app.numChans = 1 + app.numLabelIms;
            for i=1:app.numChans
                [~,name,~] = fileparts(app.ims_f{i});
                app.chanStr{i} = name(4:end);
            end

            for i=2:app.numChans
                app.MasksTable.ColumnName{end+1} = app.chanStr{i};
            end
            T = cell2table(cell(0, numel(app.MasksTable.ColumnName)), ...
                'VariableNames', app.MasksTable.ColumnName);
            app.MasksTable.Data = T;

            app.createChannelControls()
            

        end

        % Button pushed function: LoadImButton
        function LoadImButtonPushed(app, event)
            
            Message = 'Loading masks image...';
            d = uiprogressdlg(app.UIFigure,'Message',Message,'Indeterminate','on');

            [app.masksIm, ~] =  app.loadIm(app.masksIm_f);

            Message = 'Loading label images...';
            d.Message = Message;

            for i=1:length(app.labelIms_f)
                app.labelIms{i} = app.loadIm(app.labelIms_f{i});
            end

            [app.num_rows, app.num_cols, app.num_planes] = size(app.masksIm);
            app.zPlane = round(app.num_planes / 2);
            % app.fullIm = zeros(app.num_rows, app.num_cols, app.num_planes, app.numChans, "double");

            % app.fullIm(:,:,:,1) = double(app.masksIm);
            % for i=1:app.numLabelIms
            %     app.fullIm(:,:,:,i+1) = double(app.labelIms{i});
            % end

            app.zSlider.Limits = [1, app.num_planes];
            app.zSlider.MinorTicks = 1:app.num_planes;
            app.zSlider.Value = app.zPlane;
            app.zPlaneLabel.Text = num2str(app.zPlane);

            app.masksImRGB = app.makeRGBImage(app.masksIm,1);
            for i=1:app.numLabelIms
                app.labelImsRGB{i} = app.makeRGBImage(app.labelIms{i},i+1);
            end

            Message = 'Loading CCF overlay...';
            d.Message = Message;

            app.ccfOverlayIm = app.loadIm(app.ccfOverlay_f);
            app.makeCCFOverlay()
            app.ccfOverlayImAlpha = (app.ccfOverlayIm > 0);
            
            app.masksVol = zeros(app.num_rows, app.num_cols, app.num_planes, "uint16");
            app.masksVolAlpha = zeros(app.num_rows, app.num_cols, app.num_planes, "logical");

            app.makeGridIm()

            % app.initZPlaneIm()
            app.updateImage()
            app.updateMasksVol()
            app.updateCCFOverlay()
            
            delete(d)

        end

        % Value changed function: zSlider
        function zSliderValueChanged(app, event)
            
            app.zPlane = uint8(app.zSlider.Value);
            app.zPlaneLabel.Text = num2str(app.zPlane);

            if app.Preloaded
                app.imObj.CData = squeeze(app.preloadedRGBIm(:,:,app.zPlane,:));
            else
                % app.updateZPlaneIm()
                app.updateImage()
            end

            app.updateMasksVol()
            app.updateCCFOverlay()

        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            
            ax = app.ImAxes;
            inAxes = app.isMouseOverAxes(ax);
            imgSize = size(app.imObj.CData);

            if ~inAxes
                return
            end

            % --- Zoom logic ---
            xlim   = get(ax, 'XLim');
            ylim   = get(ax, 'YLim');
            cp     = ax.CurrentPoint;
            mouseX = cp(1,1);
            mouseY = cp(1,2);
        
            zoomFactor = 1.15;
            if event.VerticalScrollCount < 0
                scale = 1 / zoomFactor;   % zoom in
            else
                scale = zoomFactor;        % zoom out
            end

            newXLim = mouseX + (xlim - mouseX) * scale;
            newYLim = mouseY + (ylim - mouseY) * scale;
            
            newXLim = max(0.5, min(imgSize(2)+0.5, newXLim));
            newYLim = max(0.5, min(imgSize(1)+0.5, newYLim));

            set(ax, 'XLim', newXLim, ...
                    'YLim', newYLim);

        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            
            if app.ROIMode
                return
            end

            switch app.UIFigure.SelectionType

                case 'normal'

                    ax = app.ImAxes;
                    inAxes = app.isMouseOverAxes(ax);
        
                    if ~inAxes
                        return
                    end
        
                    cp = ax.CurrentPoint;
                    x = cp(1,1);
                    y = cp(1,2);

                    app.PanAnchor  = [x, y];
                    app.IsPanning  = true;

                case 'alt'

                    app.ROIMode = true;
                    roi = drawpolygon(app.ImAxes);
                    app.UIFigure.Pointer = 'watch';
                    drawnow

                    mask = createMask(roi,app.imObj);
                    BW = activecontour(app.masksIm(:,:,app.zPlane), ...
                        mask, ...
                        app.SegmentationIterationsEditField.Value, ...
                        'edge', ...
                        'ContractionBias',0);
                    masksVolPlane = app.masksVol(:,:,app.zPlane);
                    newMask = height(app.MasksTable.Data) + 1;
                    masksVolPlane(BW) = newMask;
                    app.masksVol(:,:,app.zPlane) = masksVolPlane;
                    app.MasksTable.Data(end+1,:) = [{newMask}, num2cell(zeros(1, width(app.MasksTable.Data)-1))];
                     
                    delete(roi)
                    app.ROIMode = false;
                    app.UIFigure.Pointer = 'arrow';
                    app.updateMasksVol()

            end

        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            app.IsPanning = false;
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            
            if ~app.IsPanning
                return; 
            end

            cp = app.ImAxes.CurrentPoint;
            dx = app.PanAnchor(1) - cp(1,1);
            dy = app.PanAnchor(2) - cp(1,2);
            app.ImAxes.XLim = app.ImAxes.XLim + dx;
            app.ImAxes.YLim = app.ImAxes.YLim + dy;

        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            switch event.Key
                case 'z'
                    app.DecrementZButtonPushed([])
                case 'x'
                    app.ShowMasksButtonPushed([])
                case 'c'
                    app.IncrementZButtonPushed([])
            end
        end

        % Window key release function: UIFigure
        function UIFigureWindowKeyRelease(app, event)

        end

        % Button pushed function: PreloadRGBZstackButton
        function PreloadRGBZstackButtonPushed(app, event)
            
            Message = 'Preloading RGB Image...';
            d = uiprogressdlg(app.UIFigure,'Message',Message,'Indeterminate','off');

            app.preloadedRGBIm = zeros(app.num_rows, app.num_cols, app.num_planes, 3, 'double');
            
            % switch all channels to auto limits
            for i=1:app.numChans
                app.chansAutoLim{i}.Value = true;
            end

            for i = 1:app.num_planes

                d.Value = i / app.num_planes;
                d.Message = sprintf('Preloading RGB Image... (%d / %d)', i, app.num_planes);
                app.zPlane = i;
                app.updateZPlaneIm()

                for j = 1:app.numChans
                
                    clr = app.chansColorButton{j}.BackgroundColor;
                    scim = app.getScaledImage(j, true);
                    
                    for k = 1:3
                        app.preloadedRGBIm(:,:,i,k) = app.preloadedRGBIm(:,:,i,k) + scim.*clr(k);
                    end

                end
   
            end

            app.Preloaded = true;

        end

        % Button pushed function: DecrementZButton
        function DecrementZButtonPushed(app, event)
            if app.zPlane > 1
                app.zSlider.Value = app.zSlider.Value - 1;
                app.zSliderValueChanged([])
            else
                return
            end
        end

        % Button pushed function: IncrementZButton
        function IncrementZButtonPushed(app, event)
            if app.zPlane < app.num_planes
                app.zSlider.Value = app.zSlider.Value + 1;
                app.zSliderValueChanged([])
            else
                return
            end
        end

        % Button pushed function: ShowMasksButton
        function ShowMasksButtonPushed(app, event)
            app.masksVolImObj.Visible = ~app.masksVolImObj.Visible;
        end

        % Clicked callback: MasksTable
        function MasksTableClicked(app, event)
            
            displayRow = event.InteractionInformation.DisplayRow;
            displayColumn = event.InteractionInformation.DisplayColumn;

            if displayColumn == 1

                app.UIFigure.Pointer = 'watch';
                drawnow
                
                targetMask = app.MasksTable.Data{app.MasksTable.Selection, 'Mask ID'};
                app.makeMasksColormap(targetMask)
                app.ImAxes.Colormap = app.masksCMap;
    
                app.UIFigure.Pointer = 'arrow';

            else

                app.MasksTable.Data{displayRow,displayColumn} = ~app.MasksTable.Data{displayRow,displayColumn};
                
            end

        end

        % Button pushed function: ShowGridButton
        function ShowGridButtonPushed(app, event)
            app.gridImObj.Visible = ~app.gridImObj.Visible;
        end

        % Value changed function: GridLineThicknessSpinner
        function GridLineThicknessSpinnerValueChanged(app, event)
            app.makeGridIm()
        end

        % Value changed function: MasksButton
        function MasksButtonValueChanged(app, event)
            app.updateLamp(app.MasksButton, app.MasksLamp)
        end

        % Value changed function: CCFButton
        function CCFButtonValueChanged(app, event)
            app.updateLamp(app.CCFButton, app.CCFLamp)
        end

        % Value changed function: GridButton
        function GridButtonValueChanged(app, event)
            app.updateLamp(app.GridButton, app.GridLamp)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIFigure.WindowKeyReleaseFcn = createCallbackFcn(app, @UIFigureWindowKeyRelease, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImPanel = uipanel(app.GridLayout);
            app.ImPanel.Title = 'Image';
            app.ImAxes = uiaxes(app.ImPanel);
            app.ImAxes.XTick = [];
            app.ImAxes.YTick = [];
            app.LoadImButton = uibutton(app.GridLayout, 'push');
            app.LoadImButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImButtonPushed, true);
            app.LoadImButton.Text = 'Load Image';
            app.ChansPanel = uipanel(app.GridLayout);
            app.ChansPanel.Title = 'Channels';
            app.zSliderLabel = uilabel(app.GridLayout);
            app.zSliderLabel.Text = 'Z-plane:';
            app.zPlaneLabel = uilabel(app.GridLayout);
            app.zPlaneLabel.Text = '1';
            app.zSlider = uislider(app.GridLayout);
            app.zSlider.Limits = [1 100];
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @zSliderValueChanged, true);
            app.zSlider.Value = 75.2322384673338;
            app.PreloadRGBZstackButton = uibutton(app.GridLayout, 'push');
            app.PreloadRGBZstackButton.ButtonPushedFcn = createCallbackFcn(app, @PreloadRGBZstackButtonPushed, true);
            app.PreloadRGBZstackButton.Text = 'Preload RGB z-stack';
            app.MasksTable = uitable(app.GridLayout);
            app.MasksTable.ColumnName = {'Mask ID'};
            app.MasksTable.RowName = {};
            app.MasksTable.SelectionType = 'row';
            app.MasksTable.ClickedFcn = createCallbackFcn(app, @MasksTableClicked, true);
            app.ControlsPanel = uipanel(app.GridLayout);
            app.ControlsPanel.Title = 'Controls';
            app.DisplayMasksCheckBox = uicheckbox(app.ControlsPanel);
            app.DisplayMasksCheckBox.Text = 'Display Masks';
            app.ShowMasksButton = uibutton(app.ControlsPanel, 'push');
            app.ShowMasksButton.ButtonPushedFcn = createCallbackFcn(app, @ShowMasksButtonPushed, true);
            app.ShowMasksButton.Text = 'Show masks [x]';
            app.IncrementZButton = uibutton(app.ControlsPanel, 'push');
            app.IncrementZButton.ButtonPushedFcn = createCallbackFcn(app, @IncrementZButtonPushed, true);
            app.IncrementZButton.Text = 'Increment z [c]';
            app.DecrementZButton = uibutton(app.ControlsPanel, 'push');
            app.DecrementZButton.ButtonPushedFcn = createCallbackFcn(app, @DecrementZButtonPushed, true);
            app.DecrementZButton.Text = 'Decrement z [z]';
            app.SegmentationiterationsEditFieldLabel = uilabel(app.ControlsPanel);
            app.SegmentationiterationsEditFieldLabel.Text = 'Segmentation iterations';
            app.SegmentationIterationsEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.SegmentationIterationsEditField.Limits = [1 Inf];
            app.SegmentationIterationsEditField.Value = 5;
            app.GridRectanglesEditFieldLabel = uilabel(app.ControlsPanel);
            app.GridRectanglesEditFieldLabel.Text = 'Grid Rectangles';
            app.GridRectanglesEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.GridRectanglesEditField.Value = 10;
            app.ShowGridButton = uibutton(app.ControlsPanel, 'push');
            app.ShowGridButton.ButtonPushedFcn = createCallbackFcn(app, @ShowGridButtonPushed, true);
            app.ShowGridButton.Text = 'Show grid';
            app.OverlaysPanel = uipanel(app.GridLayout);
            app.OverlaysPanel.Title = 'Overlays';
            app.MasksLamp = uilamp(app.OverlaysPanel);
            app.MasksButton = uibutton(app.OverlaysPanel, 'state');
            app.MasksButton.ValueChangedFcn = createCallbackFcn(app, @MasksButtonValueChanged, true);
            app.MasksButton.Text = 'Masks';
            app.MasksButton.Value = true;
            app.CCFLamp = uilamp(app.OverlaysPanel);
            app.CCFButton = uibutton(app.OverlaysPanel, 'state');
            app.CCFButton.ValueChangedFcn = createCallbackFcn(app, @CCFButtonValueChanged, true);
            app.CCFButton.Text = 'CCF';
            app.CCFButton.Value = true;
            app.GridLamp = uilamp(app.OverlaysPanel);
            app.GridButton = uibutton(app.OverlaysPanel, 'state');
            app.GridButton.ValueChangedFcn = createCallbackFcn(app, @GridButtonValueChanged, true);
            app.GridButton.Text = 'Grid';
            app.GridButton.Value = true;
            app.UpdateImageButton = uibutton(app.OverlaysPanel, 'push');
            app.UpdateImageButton.Text = 'Update Image';
            app.GridLineThicknessSpinnerLabel = uilabel(app.OverlaysPanel);
            app.GridLineThicknessSpinnerLabel.Text = 'Grid Line Thickness';
            app.GridLineThicknessSpinner = uispinner(app.OverlaysPanel);
            app.GridLineThicknessSpinner.Limits = [1 Inf];
            app.GridLineThicknessSpinner.ValueChangedFcn = createCallbackFcn(app, @GridLineThicknessSpinnerValueChanged, true);
            app.GridLineThicknessSpinner.Value = 5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manual_masks(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end