classdef quality_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        BinaryGenesPanel           matlab.ui.container.Panel
        UITable                    matlab.ui.control.Table
        BinGeneAxes                matlab.ui.control.UIAxes
        MasksTable                 matlab.ui.control.Table
        ACCEPTCELLCButton          matlab.ui.control.Button
        REJECTCELLZButton          matlab.ui.control.Button
        MASKSONXButton             matlab.ui.control.Button
        RegistrationChannelsPanel  matlab.ui.container.Panel
        RegChannelTable            matlab.ui.control.Table
        RegChannelAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        
        masks_tags = {'masks'};
        reg_channels_tags = {'neur','nt'}
        primary_reg_channel_tag = 'r1';
        
        im_dir 
        f_masks_im
        f_reg_channels_ims
        f_primary_reg_channel_im
        f_secondary_reg_channel_ims
        f_bin_channels_ims

        masks_im
        primary_reg_channel_im
        secondary_reg_channel_ims

        masks
        num_masks
        masks_stats
        current_mask

        % matlab image objects
        reg_channels_im
        reg_channels_mask_im

        im_size
        num_reg_channel_ims
        g_box
        y_box
        previous_masks
        
        xypad = 10
        mask_opacity = 0.5
        show_masks = true

    end
    
    methods (Access = private)
        
        function [ROI, ROI_mask] = extract_ROI(app,im,mask,plane)

            arguments
                app
                im
                mask
                plane = []
            end
            
            bbox = num2cell(app.masks_stats{string(mask),"BoundingBox"});
            [ulf_x, ulf_y, ulf_z, width_x, width_y, width_z] = bbox{:};

            min1 = ceil(ulf_y) - app.xypad;
            min2 = ceil(ulf_x) - app.xypad;
            min3 = ceil(ulf_z);

            max1 = floor(ulf_y + width_y) + app.xypad;
            max2 = floor(ulf_x + width_x) + app.xypad;
            max3 = floor(ulf_z + width_z);
            
            % determine if any of the indices are out of bounds
            out_of_bounds = false;
            if any([min1 < 1, min2 < 1, min3 < 1])
                out_of_bounds = true;
            end
            if any([max1 > app.im_size(1), max2 > app.im_size(2), max3 > app.im_size(3)])
                out_of_bounds = true;
            end

            switch out_of_bounds

                case false

                    ind1 = min1:max1;
                    ind2 = min2:max2;
                    ind3 = min3:max3;
                    ROI = im(ind1, ind2, ind3);
                    ROI_mask = app.masks_im(ind1, ind2, ind3);

                case true

                    prepad1 = max(1-min1,0);
                    postpad1 = max(max1-app.im_size(1),0);
                    prepad2 = max(1-min2,0);
                    postpad2 = max(max2-app.im_size(2),0);
                    prepad3 = max(1-min3,0);
                    postpad3 = max(max3-app.im_size(3),0);

                    min1 = min1 + prepad1;
                    max1 = max1 - postpad1;
                    min2 = min2 + prepad2;
                    max2 = max2 - postpad2;
                    min3 = min3 + prepad3;
                    max3 = max3 - postpad3;

                    ind1 = min1:max1;
                    ind2 = min2:max2;
                    ind3 = min3:max3;
                    
                    ROI = im(ind1, ind2, ind3);
                    ROI = padarray(ROI,[prepad1 prepad2 prepad3],"pre");
                    ROI = padarray(ROI,[postpad1 postpad2 postpad3],"post");

                    ROI_mask = app.masks_im(ind1, ind2, ind3);
                    ROI_mask = padarray(ROI_mask,[prepad1 prepad2 prepad3],"pre");
                    ROI_mask = padarray(ROI_mask,[postpad1 postpad2 postpad3],"post");

            end

            if ~isempty(plane)
                ROI = ROI(:,:,plane);
                ROI_mask = ROI_mask(:,:,plane);
            end
         
        end

        function principal_plane = find_principal_plane(~,ROI,ROI_mask,mask)

            num_planes = size(ROI,3);

            if num_planes == 1
                principal_plane = 1;
                return
            end

            binary_mask = (ROI_mask == mask);
            plane_means = zeros(num_planes,1);
            plane_sizes = zeros(num_planes,1);

            for i=1:num_planes
                ROI_plane = ROI(:,:,i);
                binary_mask_plane = binary_mask(:,:,i);
                plane_means(i) = mean(ROI_plane(binary_mask_plane));
                plane_sizes(i) = sum(binary_mask_plane,"all");
            end

            plane_means = normalize(plane_means,"zscore");
            plane_sizes = normalize(plane_sizes,"zscore");
            scores = mean(horzcat(plane_means,plane_sizes),2);
            [~,I] = max(scores);

            principal_plane = I;

        end

        function [tiles, ssimval] = make_reg_channels_tiles(app,mask)

            principal_plane = app.masks_stats{string(mask),"PrincipalPlane"};

            [primary_reg_im, ~] = app.extract_ROI(app.primary_reg_channel_im,mask,principal_plane);
            primary_reg_im = rescale(primary_reg_im,0,65535);
            
            vol = zeros(size(primary_reg_im,1),size(primary_reg_im,2),app.num_reg_channel_ims);
            ssimval = zeros(app.num_reg_channel_ims,1);
            vol(:,:,1) = primary_reg_im;
            ssimval(1) = 1;


            for i=1:app.num_reg_channel_ims-1
                [secondary_reg_im, ~] = app.extract_ROI(app.secondary_reg_channel_ims{i},mask,principal_plane);
                secondary_reg_im = rescale(secondary_reg_im,0,65535);
                ssimval(i+1) = ssim(secondary_reg_im,primary_reg_im,'Exponents',[0 1 1]);
                vol(:,:,i+1) = secondary_reg_im;
            end

            tiles = imtile(vol,BorderSize=[1 1]);


        end

        function [mask_tiles, mask_tiles_alpha] = make_reg_channels_tiles_mask(app,mask)

            principal_plane = app.masks_stats{string(mask),"PrincipalPlane"};

            [~, mask_im] = app.extract_ROI(app.primary_reg_channel_im,mask,principal_plane);
            vol_mask = repmat(mask_im,1,1,app.num_reg_channel_ims);

            local_masks = unique(mask_im);
            map = repmat([0 0 1],max(local_masks)+1,1);
            map(app.current_mask+1,:) = [1 0 0];
            map(1,:) = [0 0 0];
            mask_tiles = imtile(vol_mask,map,BackgroundColor="black",BorderSize=[1 1]);
            grayTile = rgb2gray(mask_tiles);
            mask_tiles_alpha = (grayTile > 0);

        end

        function initializeRegTiles(app)

            [tiles, ssimval] = app.make_reg_channels_tiles(app.current_mask);
            [mask_tiles, mask_tiles_alpha] = app.make_reg_channels_tiles_mask(app.current_mask);

            app.reg_channels_im = imshow(tiles,[0 65535],Parent=app.RegChannelAxes);
            app.reg_channels_mask_im = imshow(mask_tiles,[0 65535],Parent=app.RegChannelAxes);
            app.reg_channels_mask_im.AlphaData = mask_tiles_alpha * app.mask_opacity;

            app.RegChannelTable.Data.("SSIM") = ssimval;
            ssim = app.RegChannelTable.Data.("SSIM")(2:end);
            ssim_thresh = cell2mat(app.RegChannelTable.Data.("SSIM Threshold")(2:end));
            app.RegChannelTable.Data.("Pass?")(2:end) = num2cell(ssim >= ssim_thresh);
            
            if all(cell2mat(app.RegChannelTable.Data.("Pass?")(2:end)))
                app.RegistrationChannelsPanel.BorderColor = [0.39,0.83,0.07];
            else
                app.RegistrationChannelsPanel.BorderColor = [1.00,0.75,0];
            end

            for i=1:app.num_reg_channel_ims-1
                switch app.RegChannelTable.Data.("Pass?"){i+1}
                    case 0
                        addStyle(app.RegChannelTable,app.y_box,'cell',[i+1,4])
                    case 1
                        addStyle(app.RegChannelTable,app.g_box,'cell',[i+1,4])
                end
                app.RegChannelTable.Data.("Pass?"){i+1} = [];
            end
        
        end

        function updateRegTiles(app)

            [tiles, ssimval] = app.make_reg_channels_tiles(app.current_mask);
            [mask_tiles, mask_tiles_alpha] = app.make_reg_channels_tiles_mask(app.current_mask);

            app.reg_channels_im.CData = tiles;
            app.reg_channels_mask_im.CData = mask_tiles;
            app.reg_channels_mask_im.AlphaData = mask_tiles_alpha * app.mask_opacity;

            app.RegChannelTable.Data.("SSIM") = ssimval;
            ssim = app.RegChannelTable.Data.("SSIM")(2:end);
            ssim_thresh = cell2mat(app.RegChannelTable.Data.("SSIM Threshold")(2:end));
            app.RegChannelTable.Data.("Pass?")(2:end) = num2cell(ssim >= ssim_thresh);
            
            if all(cell2mat(app.RegChannelTable.Data.("Pass?")(2:end)))
                app.RegistrationChannelsPanel.BorderColor = [0.39,0.83,0.07];
            else
                app.RegistrationChannelsPanel.BorderColor = [1.00,0.75,0];
            end

            for i=1:app.num_reg_channel_ims-1
                switch app.RegChannelTable.Data.("Pass?"){i+1}
                    case 0
                        addStyle(app.RegChannelTable,app.y_box,'cell',[i+1,4])
                    case 1
                        addStyle(app.RegChannelTable,app.g_box,'cell',[i+1,4])
                end
                app.RegChannelTable.Data.("Pass?"){i+1} = [];
            end
        
        end

        % set current mask to next mask in the table that has not been
        % assessed
        function set_next_mask(app)
            
            nextRow = find(~app.MasksTable.Data.("Assessed"),1,"first");

            if ~isempty(nextRow)
                app.MasksTable.Selection = nextRow;
                app.current_mask = app.MasksTable.Data.("Mask ID")(app.MasksTable.Selection);
                app.updateRegTiles()
            end
            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % add subdirectories with needed functions
            [filepath,~,~] = fileparts(mfilename('fullpath'));
            cd(filepath)
            addpath(['./' 'utils'])
            addpath(['./' 'assets'])

            movegui(app.UIFigure,'north')
            axis(app.RegChannelAxes,"off")
            axis(app.RegChannelAxes,"image")
            hold(app.RegChannelAxes,"on")

            app.im_dir = 'C:\Users\jpv88\Desktop\s04';
            
            % get image file names
            listing = dir(app.im_dir);
            f_names = {listing.name};
            TF_im = contains(f_names,{'.tif','.tiff'},'IgnoreCase',true);
            im_files = f_names(TF_im);
            
            % get masks image file name
            TF_mask = contains(im_files,app.masks_tags);
            app.f_masks_im = im_files(TF_mask);
            app.f_masks_im = app.f_masks_im{1};
            
            % get registration channel image file names
            TF_reg_channels = contains(im_files,app.reg_channels_tags);
            app.f_reg_channels_ims = im_files(TF_reg_channels);
            app.num_reg_channel_ims = length(app.f_reg_channels_ims);
            
            % get primary registration channel image name
            TF_primary_reg_channel = contains(app.f_reg_channels_ims,app.primary_reg_channel_tag);
            app.f_primary_reg_channel_im = app.f_reg_channels_ims(TF_primary_reg_channel);
            app.f_primary_reg_channel_im = app.f_primary_reg_channel_im{1};

            % get secondary registration channel image names
            app.f_secondary_reg_channel_ims = app.f_reg_channels_ims(~TF_primary_reg_channel);

            % get binarization image file names
            % TF_bin = and(~TF_mask,~TF_reg_channels);
            % app.f_bin_channels_ims = im_files(TF_bin);
            % [indx,~] = listdlg('ListString',app.f_bin_channels_ims, ...
            %     'SelectionMode','multiple', ...
            %     'PromptString',{'Select genes to be binarized','(Ctrl+Click to select multiple)'});
            % app.f_bin_channels_ims = app.f_bin_channels_ims(indx);
            app.f_bin_channels_ims = {'r0_wga555561.tif','r1_slc17a6488.tif','r2_Rph3a488.tif','r3_slc6a5488.tif','r4_slc5a7488.tif'};

            % load all images
            fig = uifigure;
            d = uiprogressdlg(fig,'Title','Loading...','Message',"Loading Masks...",'Value',1/4);
            app.masks_im = read_tiff(fullfile(app.im_dir,app.f_masks_im));
            d.Message = 'Loading primary registration channel image...';
            d.Value = 1/2;
            app.primary_reg_channel_im = read_tiff(fullfile(app.im_dir,app.f_primary_reg_channel_im));
            d.Message = 'Loading secondary registration channel images...';
            d.Value = 3/4;
            app.secondary_reg_channel_ims = cell(app.num_reg_channel_ims-1,1);
            for i=1:app.num_reg_channel_ims-1
                app.secondary_reg_channel_ims{i} = read_tiff(fullfile(app.im_dir,app.f_secondary_reg_channel_ims{i}));
            end
            close(fig)

            % prepare masks stuff
            app.masks = unique(app.masks_im);
            app.masks(1) = [];
            app.num_masks = length(app.masks);
            app.masks_stats = regionprops3(app.masks_im,{"BoundingBox","Centroid"});
            A = table2array(app.masks_stats);
            TF = isnan(A);
            TF = any(TF,2);
            TF = ~TF;
            app.masks_stats = app.masks_stats(TF,:);
            app.masks_stats.Properties.RowNames = string(app.masks);

            app.im_size = size(app.primary_reg_channel_im);
            
            % find principal planes of each ROI
            principal_planes = zeros(app.num_masks,1);
            for i=1:app.num_masks
                mask = app.masks(i);
                [ROI, ROI_mask] = app.extract_ROI(app.primary_reg_channel_im,mask);
                principal_planes(i) = app.find_principal_plane(ROI,ROI_mask,mask);
            end
            app.masks_stats = addvars(app.masks_stats,principal_planes,'After','Centroid','NewVariableNames',{'PrincipalPlane'});
            
            % prepare registration channels table
            app.RegChannelTable.Data = {app.f_primary_reg_channel_im,'N/A','N/A','N/A'};
            for i=1:app.num_reg_channel_ims-1
                app.RegChannelTable.Data = [app.RegChannelTable.Data;{app.f_secondary_reg_channel_ims{i},0,0.1,0}];
            end
            app.RegChannelTable.Data = cell2table(app.RegChannelTable.Data);
            app.RegChannelTable.Data = renamevars(app.RegChannelTable.Data, ...
                ["Var1","Var2","Var3","Var4"], ...
                app.RegChannelTable.ColumnName);
            
            % prepare icons
            app.g_box = uistyle('BackgroundColor','green', ...
                'Icon','success', ...
                'IconAlignment','left', ...
                'HorizontalAlignment','center');
            app.y_box = uistyle('BackgroundColor','yellow', ...
                'Icon',fullfile('./','assets','exclamation-warning-round-black-yellow-icon.svg'), ...
                'IconAlignment','left', ...
                'HorizontalAlignment','center');
            
            masks_table = table;
            assessed = zeros(app.num_masks,1,'logical');
            results = repelem({'null'},app.num_masks,1);
            masks_table = addvars(masks_table,app.masks,assessed,results,'NewVariableNames',{'Mask ID','Assessed','Result'});
            masks_table = addvars(masks_table,ceil(app.masks_stats.BoundingBox(:,3)),'Before','Assessed','NewVariableNames',{'Z-plane'});
            masks_table = addvars(masks_table,app.masks_stats.BoundingBox(:,6),'Before','Assessed','NewVariableNames',{'Z-span'});
            app.MasksTable.Data = masks_table;

            app.current_mask = app.masks(1);
            app.previous_masks = [app.masks(1)];
            app.MasksTable.Selection = 1;
            app.initializeRegTiles()


        end

        % Selection changed function: MasksTable
        function MasksTableSelectionChanged(app, event)
            selection = app.MasksTable.Selection;
            app.current_mask = app.MasksTable.Data.("Mask ID")(selection);
            app.updateRegTiles()
        end

        % Cell edit callback: RegChannelTable
        function RegChannelTableCellEdit(app, event)
            app.updateRegTiles()
        end

        % Button pushed function: REJECTCELLZButton
        function REJECTCELLZButtonPushed(app, event)
            app.MasksTable.Data.("Assessed")(app.MasksTable.Selection) = true;
            app.MasksTable.Data.("Result"){app.MasksTable.Selection} = 'bad';
            app.previous_masks = [app.previous_masks app.current_mask];
            app.set_next_mask()
            scroll(app.MasksTable,"row",app.MasksTable.Selection)
        end

        % Button pushed function: MASKSONXButton
        function MASKSONXButtonPushed(app, event)
            app.show_masks = ~app.show_masks;
            app.reg_channels_mask_im.Visible = app.show_masks;
        end

        % Button pushed function: ACCEPTCELLCButton
        function ACCEPTCELLCButtonPushed(app, event)
            app.MasksTable.Data.("Assessed")(app.MasksTable.Selection) = true;
            app.MasksTable.Data.("Result"){app.MasksTable.Selection} = 'good';
            app.previous_masks = [app.previous_masks app.current_mask];
            app.set_next_mask()
            scroll(app.MasksTable,"row",app.MasksTable.Selection)
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            
            key = event.Key;
            
            switch key
                case 'z'
                    app.REJECTCELLZButtonPushed()
                case 'x'
                    app.MASKSONXButtonPushed()
                case 'c'
                    app.ACCEPTCELLCButtonPushed()
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.RegistrationChannelsPanel = uipanel(app.GridLayout);
            app.RegistrationChannelsPanel.Title = 'Registration Channels';
            app.RegChannelAxes = uiaxes(app.RegistrationChannelsPanel);
            app.RegChannelAxes.XTick = [];
            app.RegChannelAxes.YTick = [];
            app.RegChannelTable = uitable(app.RegistrationChannelsPanel);
            app.RegChannelTable.ColumnName = {'Image'; 'SSIM'; 'SSIM Threshold'; 'Pass?'};
            app.RegChannelTable.RowName = {};
            app.RegChannelTable.ColumnEditable = [false false true false];
            app.RegChannelTable.CellEditCallback = createCallbackFcn(app, @RegChannelTableCellEdit, true);
            app.MASKSONXButton = uibutton(app.GridLayout, 'push');
            app.MASKSONXButton.ButtonPushedFcn = createCallbackFcn(app, @MASKSONXButtonPushed, true);
            app.MASKSONXButton.Text = 'MASKS ON [X]';
            app.REJECTCELLZButton = uibutton(app.GridLayout, 'push');
            app.REJECTCELLZButton.ButtonPushedFcn = createCallbackFcn(app, @REJECTCELLZButtonPushed, true);
            app.REJECTCELLZButton.Text = 'REJECT CELL [Z]';
            app.ACCEPTCELLCButton = uibutton(app.GridLayout, 'push');
            app.ACCEPTCELLCButton.ButtonPushedFcn = createCallbackFcn(app, @ACCEPTCELLCButtonPushed, true);
            app.ACCEPTCELLCButton.Text = 'ACCEPT CELL [C]';
            app.MasksTable = uitable(app.GridLayout);
            app.MasksTable.ColumnName = {'Mask ID'; 'Z-plane'; 'Z-span'; 'Assessed'; 'Result'};
            app.MasksTable.RowName = {};
            app.MasksTable.SelectionType = 'row';
            app.MasksTable.SelectionChangedFcn = createCallbackFcn(app, @MasksTableSelectionChanged, true);
            app.BinaryGenesPanel = uipanel(app.GridLayout);
            app.BinaryGenesPanel.Title = 'Binary Genes';
            app.BinGeneAxes = uiaxes(app.BinaryGenesPanel);
            app.BinGeneAxes.XTick = [];
            app.BinGeneAxes.YTick = [];
            app.UITable = uitable(app.BinaryGenesPanel);
            app.UITable.ColumnName = {'Gene'; 'Binary Expression Readout'; 'Photobleached?'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = quality_control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end